classdef PWA_Tool_LW < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Step5PerformPWALabel            matlab.ui.control.Label
        Step4ComputeWaveformsLabel      matlab.ui.control.Label
        Step1LoadDataLabel              matlab.ui.control.Label
        Step3Label                      matlab.ui.control.Label
        Step2Label                      matlab.ui.control.Label
        TotaldistanceincmEditField      matlab.ui.control.NumericEditField
        TotaldistanceincmEditFieldLabel  matlab.ui.control.Label
        unifycardiaccyclelengthviaDropDown  matlab.ui.control.DropDown
        unifycardiaccyclelengthviaDropDownLabel  matlab.ui.control.Label
        NormalizationmethodDropDown     matlab.ui.control.DropDown
        NormalizationmethodDropDownLabel  matlab.ui.control.Label
        NumberofneighborsforaveragingwaveformsDropDown  matlab.ui.control.DropDown
        NumberofneighborsforaveragingwaveformsDropDownLabel  matlab.ui.control.Label
        NumberofinvolvedpointsEditField  matlab.ui.control.NumericEditField
        NumberofinvolvedpointsforsplittingLabel  matlab.ui.control.Label
        segmentationthresholdSlider     matlab.ui.control.Slider
        segmentationthresholdLabel      matlab.ui.control.Label
        computewaveformsButton          matlab.ui.control.Button
        recButton                       matlab.ui.control.Button
        PWVforsplittingEditFieldLabel   matlab.ui.control.Label
        PWVforsplittingEditField        matlab.ui.control.NumericEditField
        CompareregularizationparametersButton  matlab.ui.control.Button
        PerformSplittingonlyButton      matlab.ui.control.Button
        OptionsPanel                    matlab.ui.container.Panel
        infoButton                      matlab.ui.control.Button
        SmoothingparameterEditField     matlab.ui.control.NumericEditField
        SmoothingparameterEditFieldLabel  matlab.ui.control.Label
        RegularizationparameterEditField  matlab.ui.control.NumericEditField
        RegularizationparameterEditFieldLabel  matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        PWVType                         matlab.ui.control.DropDown
        CropvelocityButton              matlab.ui.control.Button
        ScanInfoTable                   matlab.ui.control.Table
        PerformPulsewaveAnalysisButton  matlab.ui.control.Button
        RestartButton                   matlab.ui.control.Button
        CenterlineextractionandBranchidentificationButton  matlab.ui.control.Button
        SelectdatapointsforPWVestimationButton  matlab.ui.control.Button
        PWAToolLabel                    matlab.ui.control.Label
        LoadangioCheckBox               matlab.ui.control.CheckBox
        dicomButton                     matlab.ui.control.Button
        LoadvelocitydataPanel           matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        CropangioButton                 matlab.ui.control.Button
        segmentationthresholdSlider_2   matlab.ui.control.Slider
        segmentationthresholdSlider_2Label  matlab.ui.control.Label
        LoadangiodataButton             matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        DButton                         matlab.ui.control.StateButton
        SaveresultsButton               matlab.ui.control.Button
        XYButton                        matlab.ui.control.StateButton
        XZButton                        matlab.ui.control.StateButton
        YZButton                        matlab.ui.control.StateButton
        ViewLabel                       matlab.ui.control.Label
        AdjustAlignmentofangioandvelocitydataLabel  matlab.ui.control.Label
        RotateLabel                     matlab.ui.control.Label
        TranslateLabel                  matlab.ui.control.Label
        translateZminus                 matlab.ui.control.Button
        translateZplus                  matlab.ui.control.Button
        translateXminus                 matlab.ui.control.Button
        translateXplus                  matlab.ui.control.Button
        translateYminus                 matlab.ui.control.Button
        translateYplus                  matlab.ui.control.Button
        ZButton_2                       matlab.ui.control.Button
        ZButton                         matlab.ui.control.Button
        XButton                         matlab.ui.control.Button
        XButton_2                       matlab.ui.control.Button
        YButton_2                       matlab.ui.control.Button
        YButton                         matlab.ui.control.Button
        PWVinmsLabel                    matlab.ui.control.Label
        ShowsumCheckBox                 matlab.ui.control.CheckBox
        showpointNrDropDown             matlab.ui.control.DropDown
        showpointNrDropDownLabel        matlab.ui.control.Label
        PWVDisplay                      matlab.ui.control.NumericEditField
        Display3                        matlab.ui.control.UIAxes
        Display2                        matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        PWVCalcDisplay                  matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        centerline_performed=0
    end
    
    properties (Access = public)
        publicangio    % Public angios to pass and take from crop app 
        PWVpointList
        flip1
        flip2
        flip3
        flip4
        branches
        %Public from here on only to pass to subapp for visualization of
        %waveforms
        directory % Description
        cardiacFrames
        pixdim
        delays
        timeres
        v
        directionFlagVelocity
        MAG
        res
        vangio3d
        vsegmentation
        angiodirectory
        angiores
        angiopixdim
        angio3d
        maxangio3d
        directionFlagAngio
        angiosegmentation
        branchList
        angioOrientation
        vOrientation
        angioPosition
        vPosition
        v_shifted
        waveforms
        rho
        reconstructed_waveforms
        distances
        numberofpoints
        angiovis
        angiochangedflag
        param
        PWV
    end
    
    methods (Access = private)
        
        function Update3DSegmentation(app)
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            app.vsegmentation = zeros(size(app.vangio3d));
            w=app.segmentationthresholdSlider.Value*max(app.vangio3d,[],"all");
            app.vsegmentation(app.vangio3d>w) = 1;
            app.vsegmentation = bwareaopen(app.vsegmentation,round(sum(app.vsegmentation(:)).*0.0002),26); %The value at the end of the commnad in the minimum area of each segment to keep
            app.vsegmentation = imfill(app.vsegmentation,'holes'); % Fill in holes created by slow flow on the inside of vessels
            app.vsegmentation = single(app.vsegmentation);  
            app.UIAxes.Visible='on';
            %----------
            cla(app.UIAxes)
            ss = smooth3(app.vsegmentation);
            hpatch1 = patch(app.UIAxes,isosurface(ss,.05),'FaceColor','red','EdgeColor', 'red','FaceAlpha',0.15);
            hpatch2 = patch(app.UIAxes,isocaps(ss,.05),'FaceColor','red','EdgeColor', 'none','FaceAlpha',0.25);
            reducepatch(hpatch1 ,0.15);
            % Make it all look good
            camlight(app.UIAxes);
            lighting(app.UIAxes,'gouraud');
            lightangle(app.UIAxes,0,0);
            view(app.UIAxes, [0 90]);
            daspect(app.UIAxes,[1/app.pixdim(1) 1/app.pixdim(2) 1/app.pixdim(3)])
            index=find(app.vsegmentation);
            [x,y,z]=ind2sub(size(app.vsegmentation),index);
            m_xstart = min(x); m_ystart = min(y); m_zstart = min(z);
            m_xstop = max(x); m_ystop = max(y); m_zstop = max(z);
            if ~isempty(m_ystart)
                xlim(app.UIAxes,[m_ystart-5 m_ystop+5]);
            end
            if ~isempty(m_xstart)
                ylim(app.UIAxes,[m_xstart-5 m_xstop+5]);
            end
            set(app.UIFigure,'Pointer','arrow');
        end

        function Update3DAngioSegmentation(app)
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            app.angiochangedflag=1;
            app.angiosegmentation = zeros(size(app.angio3d));
            w=app.segmentationthresholdSlider_2.Value*app.maxangio3d;
            app.angiosegmentation(app.angio3d>w) = 1;
            app.angiosegmentation = bwareaopen(app.angiosegmentation,round(sum(app.angiosegmentation(:)).*0.0002),26); %The value at the end of the commnad in the minimum area of each segment to keep
            app.angiosegmentation = imfill(app.angiosegmentation,'holes'); % Fill in holes created by slow flow on the inside of vessels
            app.angiosegmentation = single(app.angiosegmentation); 
            app.UIAxes2.Visible='on';
            app.XYButton.Visible='on';
            app.XZButton.Visible='on';
            app.YZButton.Visible='on';
            app.DButton.Visible='on';
            app.ViewLabel.Visible='on';
            %----------
            cla(app.UIAxes2)
            ss = smooth3(app.angiosegmentation);
            is=isosurface(ss,.05);
            reducepatch(is ,0.15);
            hpatch1 = patch(app.UIAxes2,is,'FaceColor','red','EdgeColor', 'none','FaceAlpha',0.25);
            % Make it all look good
            camlight(app.UIAxes2);
            lighting(app.UIAxes2,'gouraud');
            lightangle(app.UIAxes2,0,0);
            if app.XYButton.Value
                view(app.UIAxes2, [0 90]);
            elseif app.XZButton.Value
                view(app.UIAxes2, [0  0]);
            elseif app.YZButton.Value
                view(app.UIAxes2, [90 0]);
            end
            daspect(app.UIAxes2,[1/app.angiopixdim(1) 1/app.angiopixdim(2) 1/app.angiopixdim(3)])
            index=find(app.angiosegmentation);
            [x,y,z]=ind2sub(size(app.angiosegmentation),index);
            m_xstart = min(x); m_ystart = min(y); m_zstart = min(z); 
            m_xstop = max(x); m_ystop = max(y); m_zstop = max(z);
            
            if ~isempty(m_xstart)
                ylim(app.UIAxes2,[m_xstart-5 m_xstop+5]);
            end
            if ~isempty(m_ystart)
                xlim(app.UIAxes2,[m_ystart-5 m_ystop+5]);
            end
            if ~isempty(m_zstart)
                zlim(app.UIAxes2,[m_zstart-5 m_zstop+5]);
            end
            set(app.UIFigure,'Pointer','arrow');
        end
        function reset3DSegmentationAndCenterline(app)
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            % Initialize figure
            colorbar(app.UIAxes2,'off')
            cla(app.UIAxes2);
            app.XYButton.Visible='on';
            app.XZButton.Visible='on';
            app.YZButton.Visible='on';
            app.DButton.Visible='on';
            app.ViewLabel.Visible='on';
            if app.LoadangioCheckBox.Value
                if app.angiochangedflag
                    ss = smooth3(app.angiosegmentation);
                    app.angiovis=isosurface(ss,0.5);
                    app.angiochangedflag=0;
                end
                hpatch = patch(app.UIAxes2,app.angiovis,'FaceAlpha',0.20);
                reducepatch(hpatch,0.3);
                set(hpatch,'FaceColor',[0.7 0.7 0.7],'EdgeColor', 'none','PickableParts','none');
                [app.v_shifted,~]=shift_position_affine(app.vsegmentation,app.vPosition,app.vOrientation,app.pixdim, ...
                size(app.angiosegmentation), app.angioPosition,app.angioOrientation,app.angiopixdim,app.directionFlagVelocity,app.directionFlagAngio,"interpolation","cutoff");
                ss = smooth3(app.v_shifted);
                
                hpatch1 = patch(app.UIAxes2,isosurface(ss,.05),'FaceColor','red','EdgeColor', 'none','FaceAlpha',0.25);
                reducepatch(hpatch1 ,0.3);
                index=find(app.v_shifted);
                [x,y,z]=ind2sub(size(app.angiosegmentation),index);
                m_xstart = min(x); m_ystart = min(y); m_zstart = min(z);
                m_xstop = max(x); m_ystop = max(y); m_zstop = max(z);
                
                if ~isempty(m_xstart)
                    ylim(app.UIAxes2,[m_xstart-5 m_xstop+5]);
                end
                if ~isempty(m_ystart)
                    xlim(app.UIAxes2,[m_ystart-5 m_ystop+5]);
                end
                if ~isempty(m_zstart)
                    zlim(app.UIAxes2,[m_zstart-5 m_zstop+5]);
                end
                if app.LoadangioCheckBox.Value
                    app.translateXplus.Visible='on';
                    app.translateYplus.Visible='on';
                    app.translateZplus.Visible='on';
                    app.translateXminus.Visible='on';
                    app.translateYminus.Visible='on';
                    app.translateZminus.Visible='on';
                    app.TranslateLabel.Visible='on';
                end
                app.AdjustAlignmentofangioandvelocitydataLabel.Visible='on';
                daspect(app.UIAxes2,[(1/app.angiopixdim(1))/(1/app.angiopixdim(1)) (1/app.angiopixdim(2))/(1/app.angiopixdim(1)) (1/app.angiopixdim(3))/(1/app.angiopixdim(1))])
                
                % make it look good
            else
                ss = smooth3(app.vsegmentation);
                hpatch = patch(app.UIAxes2,isosurface(ss,0.5),'FaceAlpha',0.20);
                reducepatch(hpatch,0.3);
                set(hpatch,'FaceColor',[0.7 0.7 0.7],'EdgeColor', 'none','PickableParts','none');
                index=find(app.vsegmentation);
                [x,y,z]=ind2sub(size(app.vsegmentation),index);
                m_xstart = min(x); m_ystart = min(y); m_zstart = min(z);
                m_xstop = max(x); m_ystop = max(y); m_zstop = max(z);
                if ~isempty(m_xstart)
                    ylim(app.UIAxes2,[m_xstart-5 m_xstop+5]);
                end
                if ~isempty(m_ystart)
                    xlim(app.UIAxes2,[m_ystart-5 m_ystop+5]);
                end
                if ~isempty(m_zstart)
                    zlim(app.UIAxes2,[m_zstart-5 m_zstop+5]);
                end
                daspect(app.UIAxes2,[1/app.pixdim(1) 1/app.pixdim(2) 1/app.pixdim(3)])
                
            end
            axis(app.UIAxes2, 'vis3d')
            axis(app.UIAxes2, 'on')
            colormap(app.UIAxes2,'colorcube')
            camlight(app.UIAxes2);
            lighting(app.UIAxes2,'gouraud');
            if app.XYButton.Value
                view(app.UIAxes2, [0 90]);
            elseif app.XZButton.Value
                view(app.UIAxes2, [0 0]);
            elseif app.YZButton.Value
                view(app.UIAxes2, [90 0]);
            end
            if isempty(app.branchList)
                errordlg("No artery branches were found in the data");
            else
                unqBranches = unique(app.branchList(:,4));
                c = lines(length(unqBranches));
                for b = 1:length(unqBranches)
                    % extract coordinates for branch and plot
                    currBranch = find(app.branchList(:,4) == b);
                    hline(b) = line(app.UIAxes2, ...
                        app.branchList(currBranch,2),app.branchList(currBranch,1),app.branchList(currBranch,3),...
                        'Color',c(b,:),'Marker','.','MarkerSize',12,'LineStyle','none');
                end
                
                c_dark=c.*0.8;
                tmp=c_dark<0;
                c_dark(tmp)=0;
                % Put the number labels on the CenterlinePlot
                for i = 1:length(unqBranches)
                    %find rows where textint = branchList(:,4) and take the mid point
                    temp = app.branchList(app.branchList(:,4) == i,1:3);
                    textLoc(i,1:3) = temp(round(size(temp,1)/2),:);
                    Ntxt = text(app.UIAxes2,textLoc(unqBranches(i),2)+2,textLoc(unqBranches(i),1)+2,textLoc(unqBranches(i),3)-2,...
                    num2str(unqBranches(i)),'Color',c_dark(i,:),'FontSize',14,'FontWeight', 'bold','Margin', 1,...
                    'HitTest','off','PickableParts','none');
                end
            end
            set(app.UIFigure,'Pointer','arrow');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LeftPanel.AutoResizeChildren = 'off';
            app.RightPanel.AutoResizeChildren = 'off';
            drawnow;
            pause(2);
            app.LeftPanel.AutoResizeChildren = 'on';
            app.RightPanel.AutoResizeChildren = 'on';
        end

        % Button pushed function: dicomButton
        function dicomButtonPushed(app, event)
            % when load data is pushed, make sure to add the utilities
            % folder (and subfolders) so all functions are in the current
            % path
            [appDirectory,~,~] = fileparts(mfilename("fullpath"));
            addpath(genpath([appDirectory '/utilities']));

            clc;
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            % from load data
            [app.directory, app.cardiacFrames, app.res, app.pixdim, app.delays, app.v, app.MAG, ...
                app.vangio3d,app.vOrientation,app.vPosition,app.directionFlagVelocity] = read_dicoms();
            if app.pixdim~=0
                % initialize the mask
                %app.vmask = ones(size(app.vangio3d));
                app.timeres=mean(diff(app.delays,1,2),'all');
                % add to info table
                app.ScanInfoTable.Data = cat(2,cellstr([num2str(app.res(1)) ' x ' num2str(app.res(2)) ' x ' num2str(app.res(3))]),...
                    cellstr([num2str(round(app.pixdim(1),1)) ' x ' num2str(round(app.pixdim(2),1)) ' x ' num2str(round(app.pixdim(3),1))]),...
                    cellstr(num2str(app.timeres)),cellstr(num2str(app.cardiacFrames)));
                
                         
    %             figure
    %             [ampmip_z,~]=max(app.vsegmentation,[],3); % compute z-indizes
    %             imagesc(ampmip_z')
    %             axis xy
    %             colormap('gray')
                %------------
                Update3DSegmentation(app);
    
                % set intial parameters
                %app.rotAngles = [0 0];
                %app.isRawDataCropped = 0;
                app.CropvelocityButton.Visible='on';
                app.centerline_performed=0;
            end
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: recButton
        function recButtonPushed(app, event)
            % when load data is pushed, make sure to add the utilities
            % folder (and subfolders) so all functions are in the current
            % path
            [appDirectory,~,~] = fileparts(mfilename("fullpath"));
            addpath(genpath([appDirectory '/utilities']));

            clc;
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            % from load data
            [app.directory, app.cardiacFrames, app.res, app.pixdim, delay_temp, app.v, app.MAG, ...
                app.vangio3d,app.vOrientation,app.vPosition,app.directionFlagVelocity] = loadPARREC();
            
            % initialize the mask
            %app.vmask = ones(size(app.vangio3d));
            delay_temp=reshape(delay_temp,[],app.cardiacFrames,2);
            app.delays=delay_temp(:,:,1);
            app.timeres=max(app.delays(:))/app.cardiacFrames;
            % add to info table
            app.ScanInfoTable.Data = cat(2,cellstr([num2str(app.res(1)) ' x ' num2str(app.res(2)) ' x ' num2str(app.res(3))]),...
                cellstr([num2str(round(app.pixdim(1),1)) ' x ' num2str(round(app.pixdim(2),1)) ' x ' num2str(round(app.pixdim(3),1))]),...
                cellstr(num2str(app.timeres)),cellstr(num2str(app.cardiacFrames)));
            
                     
%             figure
%             [ampmip_z,~]=max(app.vsegmentation,[],3); % compute z-indizes
%             imagesc(ampmip_z')
%             axis xy
%             colormap('gray')
            %------------
            Update3DSegmentation(app);

            % set intial parameters
            %app.rotAngles = [0 0];
            %app.isRawDataCropped = 0;
            app.CropvelocityButton.Visible='on';
            app.centerline_performed=0;
            set(app.UIFigure,'Pointer','arrow');
        end

        % Value changed function: LoadangioCheckBox
        function LoadangioCheckBoxValueChanged(app, event)
            value = app.LoadangioCheckBox.Value;
            if value==1 
                app.LoadangiodataButton.Visible='on';
                app.segmentationthresholdSlider_2.Visible='on';
                app.segmentationthresholdSlider_2Label.Visible='on';
            else 
                app.LoadangiodataButton.Visible='off';
                app.segmentationthresholdSlider_2.Visible='off';
                app.segmentationthresholdSlider_2Label.Visible='off';
                app.CropangioButton.Visible='off';
            end
        end

        % Value changed function: segmentationthresholdSlider
        function segmentationthresholdSliderValueChanged(app, event)
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            Update3DSegmentation(app);
            app.centerline_performed=0;
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: LoadangiodataButton
        function LoadangiodataButtonPushed(app, event)
            % from load data
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            [app.angiodirectory, app.angiores, app.angiopixdim,...
                app.angio3d,app.angioOrientation,app.angioPosition,app.directionFlagAngio] = read_angio_dicoms();
            app.segmentationthresholdSlider_2.Visible='on';
%             figure
%             [ampmip_z,~]=max(app.angio3d,[],3); % compute z-indizes
%             surf(ampmip_z')
%             view([0 0 90])
%             shading interp
%             colormap('gray') 
            app.maxangio3d=max(app.angio3d,[],"all");
            app.segmentationthresholdSlider_2.Value=0.9;
            Update3DAngioSegmentation(app);
            app.CropangioButton.Visible='on';
%             figure
%             [ampmip_z,~]=max(app.angiosegmentation,[],3); % compute z-indizes
%             surf(ampmip_z')
%             view([0 0 90])
%             shading interp
%             colormap('gray') 
            set(app.UIFigure,'Pointer','arrow');
        end

        % Value changed function: segmentationthresholdSlider_2
        function segmentationthresholdSlider_2ValueChanged(app, event)
            set(app.UIFigure,'Pointer','watch');  
            drawnow()
            Update3DAngioSegmentation(app);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: CropvelocityButton
        function CropvelocityButtonPushed(app, event)
            Crop=crop_app(app,app.vangio3d);
            while isvalid(Crop);pause(0.1);end
            app.vangio3d=app.publicangio;
            Update3DSegmentation(app);
        end

        % Button pushed function: CropangioButton
        function CropangioButtonPushed(app, event)
            Crop=crop_app(app,app.angio3d);
            while isvalid(Crop);pause(0.1);end
            app.angio3d=app.publicangio;
            Update3DAngioSegmentation(app);
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            delete(app);
            PWA_Tool_LW;
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: 
        % CenterlineextractionandBranchidentificationButton
        function CenterlineextractionandBranchidentificationButtonPushed(app, event)
            % these are hard-coded for now
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            sortingCriteria = 3;
            se = strel('sphere',1);
            if app.LoadangioCheckBox.Value
                se = strel('sphere',1);
                ss = imerode(app.angiosegmentation,se);
                spurLength = 16;
            else
                ss = app.vsegmentation;   
                spurLength = 8;
            end
            h=waitbar(0,"Centerline & branch computation");
            wbch = allchild(h);
            jp = wbch(1).JavaPeer;
            jp.setIndeterminate(1)
            Y=thinning(ss);
            [~,~, app.branchList, ~] = centerline(Y, spurLength, sortingCriteria);
            reset3DSegmentationAndCenterline(app);
            close(h);
            app.centerline_performed=1;
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: SelectdatapointsforPWVestimationButton
        function SelectdatapointsforPWVestimationButtonPushed(app, event)
%             app.vsegmentation=zeros(10,10,10);
%             app.vsegmentation(:,1,1)=1;
%             app.branchList=zeros(10,5);
%             app.branchList(1,:)=[1 1 1 1 1];
%             app.branchList(2,:)=[2 1 1 1 2];
%             app.branchList(3,:)=[3 1 1 1 3];
%             app.branchList(4,:)=[4 1 1 1 4];
%             app.branchList(5,:)=[5 1 1 1 5];
%             app.branchList(6,:)=[6 1 1 1 6];
%             app.branchList(7,:)=[7 1 1 1 7];
%             app.branchList(8,:)=[8 1 1 1 8];
%             app.branchList(9,:)=[9 1 1 1 9];
%             app.branchList(10,:)=[10 1 1 1 10];
%             app.pixdim=[1 1 1];
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            if app.centerline_performed
                if app.LoadangioCheckBox.Value
                    select=select_datapoints_app(app,app.angiosegmentation,app.branchList,app.v_shifted,app.angiopixdim,app.pixdim,app.v);
                else
                    select=select_datapoints_app(app,app.angiosegmentation,app.branchList,app.vsegmentation,app.angiopixdim,app.pixdim,app.v);
                end
                while isvalid(select);pause(0.1);end
                idx = [];
                %  extract branches, if flip checkbox on, flip the centerline
                for b = 1:numel(app.branches)
                    tmpIdx = find(app.branchList(:,4)==app.branches(b));
                    switch b
                        case 1
                            if app.flip1
                                tmpIdx = flip(tmpIdx,1);
                            end
                        case 2
                            if app.flip2
                                tmpIdx = flip(tmpIdx,1);
                            end
                        case 3
                            if app.flip3
                                tmpIdx = flip(tmpIdx,1);
                            end
                        case 4
                            if app.flip4
                                tmpIdx = flip(tmpIdx,1);
                            end
                    end
                    idx = cat(1,idx,tmpIdx);
                end
                branchActual = app.branchList(idx,1:3);
                %distance computation
                if app.LoadangioCheckBox.Value
                    [all_distances,~]=curvedistance(app,app.angiopixdim'.*branchActual,1,5);% in mm
                else
                    [all_distances,~]=curvedistance(app,app.pixdim'.*branchActual,1,5);% in mm
                end
                [~,k]=ismember(app.PWVpointList(:,1:3),branchActual,'rows');
                [~,I]=sort(k);
                app.PWVpointList=app.PWVpointList(I,:);
                app.numberofpoints=size(app.PWVpointList,1);
                app.distances=all_distances(sort(k));
                app.distances=app.distances-app.distances(1);
                app.TotaldistanceincmEditField.Value=round(app.distances(end)/10,1);
                %transform PWVPoints in shifted image back to original
                %muss punkt für punkt passieren damit die reihenfolge erhalten
                %bleibt. ohne interpolation! 
                if app.LoadangioCheckBox.Value
                    [~,v_ind]=shift_position_affine(app.PWVpointList(:,1:3),app.angioPosition,app.angioOrientation,app.angiopixdim, ...
                        size(app.vsegmentation), app.vPosition,app.vOrientation,app.pixdim,app.directionFlagAngio,app.directionFlagVelocity,"nointerpolation","nocutoff");
                    [~,branch_ind]=shift_position_affine(branchActual,app.angioPosition,app.angioOrientation,app.angiopixdim, ...
                        size(app.vsegmentation), app.vPosition,app.vOrientation,app.pixdim,app.directionFlagAngio,app.directionFlagVelocity,"nointerpolation","nocutoff");
                else
                    v_ind=app.PWVpointList(:,1:3);
                    branch_ind=branchActual;
                end
                Update3DSegmentation(app);
                hline1 = line(app.UIAxes,branch_ind(:,2),branch_ind(:,1),branch_ind(:,3),...
                        'Marker','none','LineStyle','-','LineWidth', 1.5);
                c = lines(app.numberofpoints);
                for i = 1:app.numberofpoints
                    hline2 = line(app.UIAxes,v_ind(i,2),v_ind(i,1),v_ind(i,3),...
                    'Marker','.','MarkerSize',30,'Color',c(i,:),'LineStyle','none');
                    if app.numberofpoints<15
                    Ntxt = text(app.UIAxes,v_ind(i,2)-2,v_ind(i,1)+2,v_ind(i,3)-2,...
                    num2str(i),'FontSize',14,'FontWeight', 'bold','Margin', 1,...
                    'HitTest','off','PickableParts','none');
                    end
                end
                app.NumberofinvolvedpointsEditField.Value=app.numberofpoints;
                app.computewaveformsButton.Visible='on';
                app.unifycardiaccyclelengthviaDropDown.Visible='on';
                app.unifycardiaccyclelengthviaDropDownLabel.Visible='on';
                app.NormalizationmethodDropDown.Visible='on';
                app.NormalizationmethodDropDownLabel.Visible='on';
                app.NumberofneighborsforaveragingwaveformsDropDown.Visible='on';
                app.NumberofneighborsforaveragingwaveformsDropDownLabel.Visible='on';
            else
                errordlg("Perform Step 2 first!")
            end
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: computewaveformsButton
        function computewaveformsButtonPushed(app, event)
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            if app.LoadangioCheckBox.Value
                [~,v_ind]=shift_position_affine(app.PWVpointList(:,1:3),app.angioPosition,app.angioOrientation,app.angiopixdim, ...
                    size(app.vsegmentation), app.vPosition,app.vOrientation,app.pixdim,app.directionFlagAngio,app.directionFlagVelocity,"nointerpolation","nocutoff");
            else
                v_ind=app.PWVpointList(:,1:3);
            end
            [x_seg,y_seg,z_seg]=ind2sub(size(app.vsegmentation),find(app.vsegmentation));
            avn=str2num(app.NumberofneighborsforaveragingwaveformsDropDown.Value);
            neighbor_idx=knnsearch([x_seg y_seg z_seg],v_ind,'K',avn);
            %use v_segmentation to find neighbors
            app.waveforms=zeros(app.numberofpoints,size(app.v,4));
            cutoffstr="cutoff";
            averagestr="average";
            if strcmp(app.unifycardiaccyclelengthviaDropDown.Value,averagestr)
                card_time = [0:app.cardiacFrames-1]*app.timeres;
            end
            for idx=1:app.numberofpoints
                %figure 
                %hold on
                for i_neighbors=1:avn
                    i_temp=neighbor_idx(idx,i_neighbors);
                    if strcmp(app.unifycardiaccyclelengthviaDropDown.Value,cutoffstr)
                        v_cutoff=squeeze(app.v(x_seg(i_temp),y_seg(i_temp),z_seg(i_temp),:))';
                        if i_neighbors==1 && idx==1
                            [~,min_ind]=min(v_cutoff);
                            [~,shortest_interval]=min(app.delays(:,end));
                            %card_time as shortest inverval from app.delays
                            card_time = app.delays(shortest_interval,:);
                            app.timeres = card_time(end)/app.cardiacFrames;
                            t_shift=app.delays(z_seg(1),min_ind);
                            pixelshift=t_shift*app.cardiacFrames./app.delays(:,end);   
                        end
                        shift_temp=pixelshift(z_seg(i_temp));
                        %figure
                        %plot(app.delays(z_seg(i_temp),:),v_cutoff)
                        %hold on
                        % interpolation of waves on shortest interval
                        v_cutoff=FourierShift(v_cutoff,-shift_temp);
                        v_cutoff=interp1(app.delays(z_seg(i_temp),:),v_cutoff,app.delays(shortest_interval,:));
                        %plot(card_time,v_cutoff)
                        app.waveforms(idx,:)=app.waveforms(idx,:)+v_cutoff/avn;
                    elseif strcmp(app.unifycardiaccyclelengthviaDropDown.Value,averagestr)
                        app.waveforms(idx,:)=app.waveforms(idx,:)+squeeze(app.v(x_seg(i_temp),y_seg(i_temp),z_seg(i_temp),:))'/avn;
                    end
                    if i_neighbors==1
                        raw=squeeze(app.v(x_seg(i_temp),y_seg(i_temp),z_seg(i_temp),:))';
                    end
                    %plot(squeeze(app.v(x_seg(i_temp),y_seg(i_temp),z_seg(i_temp),:)),'--')
                end
                smooth=app.waveforms(idx,:);
                %noiselevel=norm(raw-smooth)/norm(smooth)
                %plot(app.waveforms(idx,:))
            end
            app.waveforms=app.waveforms-mean(app.waveforms,2);
            switch app.NormalizationmethodDropDown.Value
                case "int"
                    app.waveforms=normalize(app.waveforms,2,"norm",1);
                case "range"
                    app.waveforms=normalize(app.waveforms,2,"range");
                case "scale"
                    app.waveforms=normalize(app.waveforms,2,"scale");
                case "L2"
                    app.waveforms=normalize(app.waveforms,2,"norm",2);
                case "no"
            end
            cla(app.PWVCalcDisplay);
            app.PWVCalcDisplay.Visible='on';
            plot(app.PWVCalcDisplay,card_time,app.waveforms');
            app.PWVCalcDisplay.YLim = [min(app.waveforms,[],'all') max(app.waveforms,[],'all')];
            app.PWVCalcDisplay.XLim = [0 card_time(end)];
            app.PWVCalcDisplay.XLabel.String = 'cardiac time (ms)';
            app.PWVCalcDisplay.YLabel.String = 'blood flow (a.u.)';
            if app.numberofpoints>7
                legend(app.PWVCalcDisplay,"off");
            end
            %average v_data with neighbors to get waves
            app.NumberofinvolvedpointsEditField.Value=app.numberofpoints;
            app.OptionsPanel.Visible='on';
            app.PWVforsplittingEditField.Visible='on';
            app.PWVforsplittingEditFieldLabel.Visible='on';
            app.CompareregularizationparametersButton.Visible='on';
            app.PerformSplittingonlyButton.Visible='on';
            app.PerformPulsewaveAnalysisButton.Visible='on';
            app.showpointNrDropDown.Visible='on';
            app.ShowsumCheckBox.Visible='on';
            app.PWVDisplay.Visible='on';
            app.PWVinmsLabel.Visible='on';
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: PerformPulsewaveAnalysisButton
        function PerformPulsewaveAnalysisButtonPushed(app, event)
            % grab PWV calc type: 1 is cross correlation, 2 is Wavelet, 3 is
            % maximum likelihood estimation, 4 is splitting
            % can be updated with update to calc_pwv
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            switch app.PWVType.Value
                case 'Cross-correlation'
                    PWVcalctype = 1;
                    if app.numberofpoints < 3
                        errordlg('Need at least 3 points for cross-correlation PWV calculation')
                        return;
                    end
                case 'Wavelet'
                    PWVcalctype = 2;
                    if app.numberofpoints < 3
                        errordlg('Need at least 3 points for Wavelet PWV calculation')
                        return;
                    end
                case 'PWVE via splitting'
                    PWVcalctype = 4;
                    if app.numberofpoints < 3
                        errordlg('Need at least 3 points for Splitting PWV calculation')
                        return;
                    end
                case 'Maximum likelihood'
                    PWVcalctype = 3;
            end
            % total distance along centerline
            if PWVcalctype > 2 % max likelihood & splitting needs the 'zero' distance
                dist_total = app.distances-app.distances(1);
            else
                dist_temp = app.distances-app.distances(1);
                dist_total = dist_temp(2:end);
            end

            if PWVcalctype < 3

                % calculate PWV using the delay times using xcorrelation
                [D,fitObject, ~, dist_total2] = calc_pwv(app.waveforms,dist_total,app.timeres,PWVcalctype);
                % the PWV, 1/slope of fit
                app.PWV = 1/fitObject(1);

                y1 = polyval(fitObject,dist_total2,'r');

                % plot and display slope
                cla(app.PWVCalcDisplay)
                scatter(app.PWVCalcDisplay,dist_total2,D,'.k','SizeData',75);
                hold(app.PWVCalcDisplay,'on');
                plot(app.PWVCalcDisplay,dist_total2,y1,'b','LineWidth',2);
                legend(app.PWVCalcDisplay,'delays','linear fit','Location','Northwest')
                app.PWVCalcDisplay.XLabel.String = 'distance (mm)';
                switch PWVcalctype
                    case 1
                        str = 'cross-corr delay (ms)';
                        app.PWVCalcDisplay.YLim = [0 max(D)+1];
                    case 2
                        str = 'wavelet delay (ms)';
                        app.PWVCalcDisplay.YLim = [0 max(D)+1];
                end
                app.PWVCalcDisplay.YLabel.String = str;
                app.PWVDisplay.Visible='on';
                app.PWVDisplay.Value = app.PWV;
            elseif PWVcalctype==3 % directly calculate PWV using maximum likelihood
                %distance in meters
                d = dist_total/1000;

                % time res in seconds
                tRes = app.timeres/1000;
                
                % to follow Anders/Cecilia code, convert to velocity
                pwv0 = 10; %initial guess
                vel=app.waveforms;
                scaling = 1./std(vel(:,:),[],2);
                vsc = vel.*repmat(scaling,1,app.cardiacFrames);
                mean_flow = mean(vsc);
                inParams=[randn(1,app.cardiacFrames), pwv0]; %
                fun1=@(inParams)PWVest3_share(inParams,d,vsc,tRes,ones(length(d),1));
                options = optimset('Display','iter', 'TolCon', 1e-7, 'TolX', 1e-7, 'TolFun', 1e-7,'DiffMinChange', 1e-3);
                [params,exitflag,output] = fminunc(fun1,inParams, options);

                app.PWV = params(end); % have a look at the PWV

                card_time = [0:app.cardiacFrames-1]*app.timeres;
                cla(app.PWVCalcDisplay)
                plot(app.PWVCalcDisplay,card_time,mean_flow,'k','LineWidth',2);
                hold(app.PWVCalcDisplay,'on');
                plot(app.PWVCalcDisplay,card_time,params(1:end-1),'b','LineWidth',2);
                app.PWVCalcDisplay.YLim = [min([params(1:end-1) mean_flow]) ...
                    max([params(1:end-1) mean_flow])];
                legend(app.PWVCalcDisplay,'initial guess','final waveform','Location','Northeast')
                app.PWVCalcDisplay.XLabel.String = 'cardiac time (ms)';
                app.PWVCalcDisplay.YLabel.String = 'velocity wave (a.u.)';
                app.PWVDisplay.Visible='on';
                app.PWVDisplay.Value = app.PWV;

%%   PWS              
            elseif PWVcalctype==4  % Splitting
                
                PWVs=1:14/(150-1):15;
                app.param=paramSettingsAPP(app.timeres,app.cardiacFrames,app.numberofpoints,app.distances);
                %smoothing-parameter exponent (Recommended: Fix as s=1 (Tikhonov) or s=0 (FISTA))
                app.param.s=app.SmoothingparameterEditField.Value; 
                % apriori parameter
                app.param.alpha=app.RegularizationparameterEditField.Value;
                %Computation
                app.rho=getRealWave(app.waveforms,app.param);
                app.param.showwaitbar=1;
                switch app.MethodDropDown.Value
                    case 'FISTA'
                        [app.reconstructed_waveforms,~,data_error,app.PWV] = solveLinear_tikh_min(app.rho,PWVs,app.param,'FISTA');
                    case 'FISTA + bw cond'
                        [app.reconstructed_waveforms,~,data_error,app.PWV] = solveLinear_tikh_min(app.rho,PWVs,app.param,'FISTA + bw cond');
                    case 'Tikhonov'
                        [app.reconstructed_waveforms,~,data_error,app.PWV] = solveLinear_tikh_min(app.rho,PWVs,app.param,'tikh');
                    case 'Tikhonov + bw cond'
                        [app.reconstructed_waveforms,~,data_error,app.PWV] = solveLinear_tikh_min(app.rho,PWVs,app.param,'tikh + bw cond');
                end
                numval=1:app.numberofpoints;
                app.showpointNrDropDown.Items=["data" string(numval)];
                app.showpointNrDropDown.Value=app.showpointNrDropDown.Items(2);
                fprintf("Relative data error value is %.8f\n",dataError(app.rho,app.reconstructed_waveforms,app.param.Nwaves));
                %Display result
                card_time = [0:app.cardiacFrames-1]*app.timeres;
                cla(app.PWVCalcDisplay)
                if app.ShowsumCheckBox.Value
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)},'b','LineWidth',1.5);
                    hold(app.PWVCalcDisplay,'on');
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)},'c','LineWidth',1.5);
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.sum{str2num(app.showpointNrDropDown.Value)},'k','LineWidth',2);
                    plot(app.PWVCalcDisplay,card_time,app.rho.sum{str2num(app.showpointNrDropDown.Value)},'k--','LineWidth',1.5);
                    app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)};...
                        app.reconstructed_waveforms.sum{str2num(app.showpointNrDropDown.Value)};app.rho.sum{str2num(app.showpointNrDropDown.Value)}]) ...
                        max([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)};...
                        app.reconstructed_waveforms.sum{str2num(app.showpointNrDropDown.Value)};app.rho.sum{str2num(app.showpointNrDropDown.Value)}])];
                    app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                    legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Sum','Original','Location','Northeast')
                    app.PWVCalcDisplay.XLabel.String = 'cardiac time (ms)';
                    app.PWVCalcDisplay.YLabel.String = 'velocity wave (a.u.)';
                else
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)},'b','LineWidth',1.5);
                    hold(app.PWVCalcDisplay,'on');
                    %plot(app.PWVCalcDisplay,card_time,app.rho.sum{str2num(app.showpointNrDropDown.Value)},'k--','LineWidth',1.5);
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)},'c','LineWidth',1.5);
                    app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)}]) ...
                        max([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)}])];
                    app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                    legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Location','Northeast')
                    app.PWVCalcDisplay.XLabel.String = 'cardiac time (ms)';
                    app.PWVCalcDisplay.YLabel.String = 'velocity wave (a.u.)';
                end
                cla(app.Display2);
                app.Display2.Visible='off';
                app.Display3.Visible='on';
                cla(app.Display3)
                semilogy(app.Display3,PWVs',data_error)
                hold(app.Display3,'on');
                view(app.Display3, 2);
                app.Display3.XLim=[PWVs(1) PWVs(end)];
                app.Display3.YLim=[min(data_error) max(data_error)];
                daspect(app.Display3,'auto');
                xline(app.Display3,app.PWV,'Color','green')
                app.SaveresultsButton.Visible="on";
            end
            app.PWVDisplay.Visible='on';
            app.PWVDisplay.Value = app.PWV;
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: CompareregularizationparametersButton
        function CompareregularizationparametersButtonPushed(app, event)
            RegCompApp=parametertestoptions(app);
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            RegularizationParameterSize=RegCompApp.totalofvaluesEditField.Value;
            RegularizationParameterScale=RegCompApp.ScalingSwitch.Value;
            RegularizationParameterMinValue=RegCompApp.minvalueEditField.Value;
            RegularizationParameterMaxValue=RegCompApp.maxvalueEditField.Value;
            delete(RegCompApp);
            h=waitbar(0,'Performing parameter test...');
            %tRes = app.timeres/1000;
            PWVs=1:14/(150-1):15;
            app.param=paramSettingsAPP(app.timeres,app.cardiacFrames,app.numberofpoints,app.distances);
            %smoothing-parameter exponent (Recommended: Fix as s=1 (Tikhonov) or s=0 (FISTA))
            app.param.s=app.SmoothingparameterEditField.Value; 
            % apriori parameter
            s1='Lin';
            if strcmp(s1,RegularizationParameterScale)
                alpha=linspace(RegularizationParameterMinValue,RegularizationParameterMaxValue,RegularizationParameterSize);
            else
                alpha=logspace(log10(RegularizationParameterMinValue),log10(RegularizationParameterMaxValue),RegularizationParameterSize);
            end
            app.rho=getRealWave(app.waveforms,app.param);
            app.param.showwaitbar=0;
            for j=1:RegularizationParameterSize
                waitbar(j/double(RegularizationParameterSize),h,'Performing parameter test...');
                app.param.alpha=alpha(j);
                
                %Computation
                switch app.MethodDropDown.Value
                    case 'FISTA'
                        [app.reconstructed_waveforms,~,data_error,pwv_rec(j)] = solveLinear_tikh_min(app.rho,PWVs,app.param,'FISTA');
                    case 'FISTA + bw cond'
                        [app.reconstructed_waveforms,~,data_error,pwv_rec(j)] = solveLinear_tikh_min(app.rho,PWVs,app.param,'FISTA + bw cond');
                    case 'Tikhonov'
                        [app.reconstructed_waveforms,~,data_error,pwv_rec(j)] = solveLinear_tikh_min(app.rho,PWVs,app.param,'tikh');
                    case 'Tikhonov + bw cond'
                        [app.reconstructed_waveforms,~,data_error,pwv_rec(j)] = solveLinear_tikh_min(app.rho,PWVs,app.param,'tikh + bw cond');
                end
                error_plot(j)=min(data_error);
            end
            % Plot results
            figure
            yyaxis left
            ylabel('Computed PWV')
            hold on
            if strcmp(s1,RegularizationParameterScale)
                plot(alpha,pwv_rec)
                yyaxis right
                ylabel('Data error')
                plot(alpha,error_plot)
            else
                semilogx(alpha,pwv_rec)
                yyaxis right
                ylabel('data error')
                semilogx(alpha,error_plot)
            end
            %hold on
            xlabel('Regularization parameter \alpha')
            
            %ylim([0 0.4])
            
            hold off

            close(h)
            set(app.UIFigure,'Pointer','arrow');
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            switch app.MethodDropDown.Value
                case 'FISTA'
                    app.SmoothingparameterEditField.Value=0;
                    app.RegularizationparameterEditField.Value=0.5;
                    app.infoButton.Visible='on';
                case 'FISTA + bw cond'
                    app.SmoothingparameterEditField.Value=0;
                    app.RegularizationparameterEditField.Value=0.5;
                    app.infoButton.Visible='on';
                case 'Tikhonov'
                    app.SmoothingparameterEditField.Value=1;
                    app.RegularizationparameterEditField.Value=0.001;
                    app.infoButton.Visible='off';
                case 'Tikhonov + bw cond'
                    app.SmoothingparameterEditField.Value=1;
                    app.RegularizationparameterEditField.Value=0.001;
                    app.infoButton.Visible='off';
            end    
        end

        % Button pushed function: PerformSplittingonlyButton
        function PerformSplittingonlyButtonPushed(app, event)
                set(app.UIFigure,'Pointer','watch');
                drawnow()
                tRes = app.timeres/1000;
                PWVs=1:9/(150-1):15;
                app.param=paramSettingsAPP(app.timeres,app.cardiacFrames,app.numberofpoints,app.distances);
                %smoothing-parameter exponent (Recommended: Fix as s=1 (Tikhonov) or s=0 (FISTA))
                app.param.s=app.SmoothingparameterEditField.Value; 
                % apriori parameter
                app.param.alpha=app.RegularizationparameterEditField.Value;
                %Computation
                app.rho=getRealWave(app.waveforms,app.param);
                app.param.tau=app.param.distance/app.param.effectivedT/app.PWVforsplittingEditField.Value;
                switch app.MethodDropDown.Value
                    case 'FISTA'
                        [app.reconstructed_waveforms,~] = solveLinear_FISTA(app.rho,app.param);
                    case 'FISTA + bw cond'
                        [app.reconstructed_waveforms,~] = solveLinear_FISTAbw(app.rho,app.param);
                    case 'Tikhonov'
                        [app.reconstructed_waveforms,~] = solveLinear_tikh(app.rho,app.param);
                    case 'Tikhonov + bw cond'
                        [app.reconstructed_waveforms,~] = solveLinear_tikh_GD(app.rho,app.param);
                end
                fprintf("Relative data error value is %.8f\n",dataError(app.rho,app.reconstructed_waveforms,app.param.Nwaves));
                %%Display result
                card_time = [0:app.cardiacFrames-1]*app.timeres;
                cla(app.PWVCalcDisplay)
                if app.ShowsumCheckBox.Value
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)},'b','LineWidth',1.5);
                    hold(app.PWVCalcDisplay,'on');
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)},'c','LineWidth',1.5);
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.sum{str2num(app.showpointNrDropDown.Value)},'k','LineWidth',2);
                    plot(app.PWVCalcDisplay,card_time,app.rho.sum{str2num(app.showpointNrDropDown.Value)},'k--','LineWidth',1.5);
                    app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)};...
                        app.reconstructed_waveforms.sum{str2num(app.showpointNrDropDown.Value)};app.rho.sum{str2num(app.showpointNrDropDown.Value)}]) ...
                        max([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)};...
                        app.reconstructed_waveforms.sum{str2num(app.showpointNrDropDown.Value)};app.rho.sum{str2num(app.showpointNrDropDown.Value)}])];
                    app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                    legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Sum','Original','Location','Northeast')
                    app.PWVCalcDisplay.XLabel.String = 'cardiac time (ms)';
                    app.PWVCalcDisplay.YLabel.String = 'velocity wave (a.u.)';
                else
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)},'b','LineWidth',1.5);
                    hold(app.PWVCalcDisplay,'on');
                    %plot(app.PWVCalcDisplay,card_time,app.rho.sum{str2num(app.showpointNrDropDown.Value)},'k--','LineWidth',1.5);
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)},'c','LineWidth',1.5);
                    app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)}]) ...
                        max([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)}])];
                    app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                    legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Location','Northeast')
                    app.PWVCalcDisplay.XLabel.String = 'cardiac time (ms)';
                    app.PWVCalcDisplay.YLabel.String = 'velocity wave (a.u.)';
                end
                set(app.UIFigure,'Pointer','arrow');
        end

        % Value changed function: showpointNrDropDown
        function showpointNrDropDownValueChanged(app, event)
               %%Display result
           set(app.UIFigure,'Pointer','watch');
           drawnow()
            showdata=strcmp(app.showpointNrDropDown.Value,"data");
            if isfield(app.reconstructed_waveforms,'for') && ~showdata
                card_time = [0:app.cardiacFrames-1]*app.timeres;
                cla(app.PWVCalcDisplay)
                if app.ShowsumCheckBox.Value
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)},'b','LineWidth',1.5);
                    hold(app.PWVCalcDisplay,'on');
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)},'c','LineWidth',1.5);
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.sum{str2num(app.showpointNrDropDown.Value)},'k','LineWidth',2);
                    plot(app.PWVCalcDisplay,card_time,app.rho.sum{str2num(app.showpointNrDropDown.Value)},'k--','LineWidth',1.5);
                    app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)};...
                        app.reconstructed_waveforms.sum{str2num(app.showpointNrDropDown.Value)};app.rho.sum{str2num(app.showpointNrDropDown.Value)}]) ...
                        max([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)};...
                        app.reconstructed_waveforms.sum{str2num(app.showpointNrDropDown.Value)};app.rho.sum{str2num(app.showpointNrDropDown.Value)}])];
                    app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                    legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Sum','Original','Location','Northeast')
                    app.PWVCalcDisplay.XLabel.String = 'cardiac time (ms)';
                    app.PWVCalcDisplay.YLabel.String = 'velocity wave (a.u.)';
                else
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)},'b','LineWidth',1.5);
                    hold(app.PWVCalcDisplay,'on');
                    %plot(app.PWVCalcDisplay,card_time,app.rho.sum{str2num(app.showpointNrDropDown.Value)},'k--','LineWidth',1.5);
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)},'c','LineWidth',1.5);
                    app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)}]) ...
                        max([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)}])];
                    app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                    legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Location','Northeast')
                    app.PWVCalcDisplay.XLabel.String = 'cardiac time (ms)';
                    app.PWVCalcDisplay.YLabel.String = 'velocity wave (a.u.)';
                end
            else
                card_time = [0:app.cardiacFrames-1]*app.timeres;
                cla(app.PWVCalcDisplay);
                app.PWVCalcDisplay.Visible='on';
                plot(app.PWVCalcDisplay,card_time,app.waveforms');
                app.PWVCalcDisplay.YLim = [min(app.waveforms,[],'all') max(app.waveforms,[],'all')];
                app.PWVCalcDisplay.XLim = [0 card_time(end)];
                app.PWVCalcDisplay.XLabel.String = 'cardiac time (ms)';
                app.PWVCalcDisplay.YLabel.String = 'blood flow (a.u.)';
                if app.numberofpoints>7
                    legend(app.PWVCalcDisplay,"off");
                end
            end
            set(app.UIFigure,'Pointer','arrow');
        end

        % Value changed function: ShowsumCheckBox
        function ShowsumCheckBoxValueChanged(app, event)
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            showdata=strcmp(app.showpointNrDropDown.Value,"data");
            if isfield(app.reconstructed_waveforms,'for')  &&  ~showdata
                card_time = [0:app.cardiacFrames-1]*app.timeres;
                cla(app.PWVCalcDisplay)
                if app.ShowsumCheckBox.Value
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)},'b','LineWidth',1.5);
                    hold(app.PWVCalcDisplay,'on');
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)},'c','LineWidth',1.5);
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.sum{str2num(app.showpointNrDropDown.Value)},'k','LineWidth',2);
                    plot(app.PWVCalcDisplay,card_time,app.rho.sum{str2num(app.showpointNrDropDown.Value)},'k--','LineWidth',1.5);
                    app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.sum{str2num(app.showpointNrDropDown.Value)}]) ...
                        max([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.sum{str2num(app.showpointNrDropDown.Value)}])];
                    app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                    legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Sum','Original','Location','Northeast')
                    app.PWVCalcDisplay.XLabel.String = 'cardiac time (ms)';
                    app.PWVCalcDisplay.YLabel.String = 'velocity wave (a.u.)';
                else
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)},'b','LineWidth',1.5);
                    hold(app.PWVCalcDisplay,'on');
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)},'c','LineWidth',1.5);
                    app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)}]) ...
                        max([app.reconstructed_waveforms.for{str2num(app.showpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.showpointNrDropDown.Value)}])];
                    app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                    legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Location','Northeast')
                    app.PWVCalcDisplay.XLabel.String = 'cardiac time (ms)';
                    app.PWVCalcDisplay.YLabel.String = 'velocity wave (a.u.)';
                end
            end
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: translateXplus
        function translateXplusPushed(app, event)
            %move data
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            temp_vsegmentation=zeros(size(app.vsegmentation));
            temp_v=zeros(size(app.v));
            temp_vsegmentation(:,3:end,:)=app.vsegmentation(:,1:(end-2),:);
            temp_v(:,3:end,:)=app.v(:,1:(end-2),:);
            app.vsegmentation=temp_vsegmentation;
            app.v=temp_v;
            %update figure
            reset3DSegmentationAndCenterline(app);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: translateXminus
        function translateXminusPushed(app, event)
            %move data
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            temp_vsegmentation=zeros(size(app.vsegmentation));
            temp_v=zeros(size(app.v));
            temp_vsegmentation(:,1:(end-2),:)=app.vsegmentation(:,3:end,:);
            temp_v(:,3:end,:)=app.v(:,1:(end-2),:);
            app.vsegmentation=temp_vsegmentation;
            app.v=temp_v;
            %update figure
            reset3DSegmentationAndCenterline(app);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: translateYplus
        function translateYplusButtonPushed(app, event)
            %move data
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            temp_vsegmentation=zeros(size(app.vsegmentation));
            temp_v=zeros(size(app.v));
            temp_vsegmentation(3:end,:,:)=app.vsegmentation(1:(end-2),:,:);
            temp_v(3:end,:,:)=app.v(1:(end-2),:,:);
            app.vsegmentation=temp_vsegmentation;
            app.v=temp_v;
            %update figure
            reset3DSegmentationAndCenterline(app);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: translateYminus
        function translateYminusButtonPushed(app, event)
            %move data
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            temp_vsegmentation=zeros(size(app.vsegmentation));
            temp_v=zeros(size(app.v));
            temp_vsegmentation(1:(end-2),:,:)=app.vsegmentation(3:end,:,:);
            temp_v(3:end,:,:)=app.v(1:(end-2),:,:);
            app.vsegmentation=temp_vsegmentation;
            app.v=temp_v;
            %update figure
            reset3DSegmentationAndCenterline(app);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: translateZplus
        function translateZplusButtonPushed(app, event)
            %move data
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            temp_vsegmentation=zeros(size(app.vsegmentation));
            temp_v=zeros(size(app.v));
            temp_vsegmentation(:,:,3:end)=app.vsegmentation(:,:,1:(end-2));
            temp_v(:,:,3:end)=app.v(:,:,1:(end-2));
            app.vsegmentation=temp_vsegmentation;
            app.v=temp_v;
            %update figure
            reset3DSegmentationAndCenterline(app);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: translateZminus
        function translateZminusButtonPushed(app, event)
            %move data
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            temp_vsegmentation=zeros(size(app.vsegmentation));
            temp_v=zeros(size(app.v));
            temp_vsegmentation(:,:,1:(end-2))=app.vsegmentation(:,:,3:end);
            temp_v(:,:,3:end)=app.v(:,:,1:(end-2));
            app.vsegmentation=temp_vsegmentation;
            app.v=temp_v;
            %update figure
            reset3DSegmentationAndCenterline(app);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Value changed function: PWVType
        function PWVTypeValueChanged(app, event)
            value = app.PWVType.Value;
            splittingstring="PWVE via splitting";
            if strcmp(value,splittingstring)
                app.SmoothingparameterEditField.Visible="on";
                app.SmoothingparameterEditFieldLabel.Visible="on";
                app.MethodDropDown.Visible="on";
                app.MethodDropDownLabel.Visible="on";
                app.RegularizationparameterEditField.Visible="on";
                app.MethodDropDownLabel.Visible="on";
                app.PWVforsplittingEditField.Visible="on";
                app.PWVforsplittingEditFieldLabel.Visible="on";
                app.PerformSplittingonlyButton.Visible="on";
                app.CompareregularizationparametersButton.Visible="on";
                app.showpointNrDropDown.Visible="on";
                app.showpointNrDropDownLabel.Visible="on";
                app.ShowsumCheckBox.Visible="on";
                app.Display3.Visible="on";
                app.RegularizationparameterEditFieldLabel.Visible="on";
                app.infoButton.Visible="on";
                app.SaveresultsButton.Visible="on";
            else
                app.SmoothingparameterEditField.Visible="off";
                app.SmoothingparameterEditFieldLabel.Visible="off";
                app.MethodDropDown.Visible="off";
                app.MethodDropDownLabel.Visible="off";
                app.RegularizationparameterEditField.Visible="off";
                app.MethodDropDownLabel.Visible="off";
                app.PWVforsplittingEditField.Visible="off";
                app.PWVforsplittingEditFieldLabel.Visible="off";
                app.PerformSplittingonlyButton.Visible="off";
                app.CompareregularizationparametersButton.Visible="off";
                app.showpointNrDropDown.Visible="off";
                app.showpointNrDropDownLabel.Visible="off";
                app.ShowsumCheckBox.Visible="off";
                app.Display3.Visible="off";
                cla(app.Display3);
                app.RegularizationparameterEditFieldLabel.Visible="off";
                app.infoButton.Visible="off";
                app.SaveresultsButton.Visible="off";
            end
        end

        % Value changed function: XYButton
        function XYButtonValueChanged(app, event)
            value = app.XYButton.Value;
            if value==0
                app.XYButton.Value=1;
            else
                app.XZButton.Value=0;
                app.YZButton.Value=0;
                app.DButton.Value=0;
            end
            if app.XYButton.Value
                view(app.UIAxes2, [0 90]);
            elseif app.XZButton.Value
                view(app.UIAxes2, [0 0]);
            elseif app.YZButton.Value
                view(app.UIAxes2, [90 0]);
            elseif app.DButton.Value
                view(app.UIAxes2,3);
            end
        end

        % Value changed function: XZButton
        function XZButtonValueChanged(app, event)
            value = app.XZButton.Value;
            if value==0
                app.XZButton.Value=1;
            else
                app.XYButton.Value=0;
                app.YZButton.Value=0;
                app.DButton.Value=0;
            end
            if app.XYButton.Value
                view(app.UIAxes2, [0 90]);
            elseif app.XZButton.Value
                view(app.UIAxes2, [0 0]);
            elseif app.YZButton.Value
                view(app.UIAxes2, [90 0]);
            elseif app.DButton.Value
                view(app.UIAxes2,3);
            end
        end

        % Value changed function: YZButton
        function YZButtonValueChanged(app, event)
            value = app.YZButton.Value;
            if value==0
                app.YZButton.Value=1;
            else
                app.XYButton.Value=0;
                app.XZButton.Value=0;
                app.DButton.Value=0;
            end
            if app.XYButton.Value
                view(app.UIAxes2, [0 90]);
            elseif app.XZButton.Value
                view(app.UIAxes2, [0 0]);
            elseif app.YZButton.Value
                view(app.UIAxes2, [90 0]);
            elseif app.DButton.Value
                view(app.UIAxes2,3);
            end
        end

        % Value changed function: DButton
        function DButtonValueChanged(app, event)
            value = app.DButton.Value;
            if value==0
                app.DButton.Value=1;
            else
                app.XZButton.Value=0;
                app.YZButton.Value=0;
                app.XYButton.Value=0;
            end
            if app.XYButton.Value
                view(app.UIAxes2, [0 90]);
            elseif app.XZButton.Value
                view(app.UIAxes2, [0 0]);
            elseif app.YZButton.Value
                view(app.UIAxes2, [90 0]);
            elseif app.DButton.Value
                view(app.UIAxes2,3);
            end
        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            % Get the current system time
            currentTime = string(datetime('now','Format','yyyy_MM_dd_HH_mm_ss'));
            
            % Create the filename based on the current time
            filename = strcat('PWA_saved_', currentTime, '.mat');
            
            % Save variables into the file
            reconstructed_waveforms=app.reconstructed_waveforms;
            rho=app.rho;
            distances=app.distances;
            param=app.param;
            PWV=app.PWV;
            save(filename, 'reconstructed_waveforms', 'rho', 'distances', 'param', 'PWV');
                
            disp(['Variables saved to file: ' filename]);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {704, 704};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {469, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '__________________________________________________________________';
            app.Panel = uipanel(app.LeftPanel);
            app.LoadangiodataButton = uibutton(app.Panel, 'push');
            app.LoadangiodataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadangiodataButtonPushed, true);
            app.LoadangiodataButton.Visible = 'off';
            app.LoadangiodataButton.Text = 'Load angio data';
            app.segmentationthresholdSlider_2Label = uilabel(app.Panel);
            app.segmentationthresholdSlider_2Label.Visible = 'off';
            app.segmentationthresholdSlider_2Label.Text = {'segmentation'; ' threshold'};
            app.segmentationthresholdSlider_2 = uislider(app.Panel);
            app.segmentationthresholdSlider_2.Limits = [0 1];
            app.segmentationthresholdSlider_2.MajorTicks = [0 1];
            app.segmentationthresholdSlider_2.ValueChangedFcn = createCallbackFcn(app, @segmentationthresholdSlider_2ValueChanged, true);
            app.segmentationthresholdSlider_2.Visible = 'off';
            app.segmentationthresholdSlider_2.Value = 1;
            app.CropangioButton = uibutton(app.Panel, 'push');
            app.CropangioButton.ButtonPushedFcn = createCallbackFcn(app, @CropangioButtonPushed, true);
            app.CropangioButton.Visible = 'off';
            app.CropangioButton.Text = 'Crop angio';
            app.LoadvelocitydataPanel = uipanel(app.LeftPanel);
            app.LoadvelocitydataPanel.Title = 'Load velocity data';
            app.dicomButton = uibutton(app.LeftPanel, 'push');
            app.dicomButton.ButtonPushedFcn = createCallbackFcn(app, @dicomButtonPushed, true);
            app.dicomButton.Text = 'dicom';
            app.LoadangioCheckBox = uicheckbox(app.LeftPanel);
            app.LoadangioCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadangioCheckBoxValueChanged, true);
            app.LoadangioCheckBox.Text = 'Load angio';
            app.PWAToolLabel = uilabel(app.LeftPanel);
            app.PWAToolLabel.Text = 'PWA Tool';
            app.SelectdatapointsforPWVestimationButton = uibutton(app.LeftPanel, 'push');
            app.SelectdatapointsforPWVestimationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdatapointsforPWVestimationButtonPushed, true);
            app.SelectdatapointsforPWVestimationButton.Text = 'Select datapoints for PWV estimation';
            app.CenterlineextractionandBranchidentificationButton = uibutton(app.LeftPanel, 'push');
            app.CenterlineextractionandBranchidentificationButton.ButtonPushedFcn = createCallbackFcn(app, @CenterlineextractionandBranchidentificationButtonPushed, true);
            app.CenterlineextractionandBranchidentificationButton.Text = 'Centerline extraction and Branch identification';
            app.RestartButton = uibutton(app.LeftPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.PerformPulsewaveAnalysisButton = uibutton(app.LeftPanel, 'push');
            app.PerformPulsewaveAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformPulsewaveAnalysisButtonPushed, true);
            app.PerformPulsewaveAnalysisButton.Visible = 'off';
            app.PerformPulsewaveAnalysisButton.Text = 'Perform Pulsewave Analysis';
            app.ScanInfoTable = uitable(app.LeftPanel);
            app.ScanInfoTable.ColumnName = {'matrix'; 'resolution (mm)'; 'time resolution (ms)'; 'cardiac frames'};
            app.ScanInfoTable.RowName = {};
            app.ScanInfoTable.ColumnSortable = [false false false false];
            app.ScanInfoTable.ColumnEditable = [false false false false];
            app.CropvelocityButton = uibutton(app.LeftPanel, 'push');
            app.CropvelocityButton.ButtonPushedFcn = createCallbackFcn(app, @CropvelocityButtonPushed, true);
            app.CropvelocityButton.Visible = 'off';
            app.CropvelocityButton.Text = 'Crop velocity';
            app.OptionsPanel = uipanel(app.LeftPanel);
            app.OptionsPanel.Title = 'Options';
            app.OptionsPanel.Visible = 'off';
            app.PWVType = uidropdown(app.OptionsPanel);
            app.PWVType.Items = {'Cross-correlation', 'Wavelet', 'Maximum likelihood', 'PWVE via splitting'};
            app.PWVType.ValueChangedFcn = createCallbackFcn(app, @PWVTypeValueChanged, true);
            app.PWVType.Value = 'PWVE via splitting';
            app.MethodDropDownLabel = uilabel(app.OptionsPanel);
            app.MethodDropDownLabel.Text = ' Method';
            app.MethodDropDown = uidropdown(app.OptionsPanel);
            app.MethodDropDown.Items = {'Tikhonov', 'Tikhonov + bw cond', 'FISTA', 'FISTA + bw cond'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Tikhonov';
            app.RegularizationparameterEditFieldLabel = uilabel(app.OptionsPanel);
            app.RegularizationparameterEditFieldLabel.Text = 'Regularization parameter ';
            app.RegularizationparameterEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.RegularizationparameterEditField.Limits = [0 Inf];
            app.RegularizationparameterEditField.Value = 0.001;
            app.SmoothingparameterEditFieldLabel = uilabel(app.OptionsPanel);
            app.SmoothingparameterEditFieldLabel.Text = 'Smoothing parameter ';
            app.SmoothingparameterEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.SmoothingparameterEditField.Limits = [0 Inf];
            app.SmoothingparameterEditField.Value = 1;
            app.infoButton = uibutton(app.OptionsPanel, 'push');
            app.infoButton.Icon = fullfile(pathToMLAPP, 'utilities', 'infoicon.jpg');
            app.infoButton.Visible = 'off';
            app.infoButton.Tooltip = {'FISTA uses hard thresholding. Regularization parameter is the percentage of cut off frequencies'};
            app.infoButton.Text = '';
            app.PerformSplittingonlyButton = uibutton(app.LeftPanel, 'push');
            app.PerformSplittingonlyButton.ButtonPushedFcn = createCallbackFcn(app, @PerformSplittingonlyButtonPushed, true);
            app.PerformSplittingonlyButton.Visible = 'off';
            app.PerformSplittingonlyButton.Text = 'Perform Splitting only';
            app.CompareregularizationparametersButton = uibutton(app.LeftPanel, 'push');
            app.CompareregularizationparametersButton.ButtonPushedFcn = createCallbackFcn(app, @CompareregularizationparametersButtonPushed, true);
            app.CompareregularizationparametersButton.Visible = 'off';
            app.CompareregularizationparametersButton.Text = 'Compare regularization parameters';
            app.PWVforsplittingEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PWVforsplittingEditField.Limits = [0.1 Inf];
            app.PWVforsplittingEditField.Visible = 'off';
            app.PWVforsplittingEditField.Value = 1;
            app.PWVforsplittingEditFieldLabel = uilabel(app.LeftPanel);
            app.PWVforsplittingEditFieldLabel.Visible = 'off';
            app.PWVforsplittingEditFieldLabel.Text = 'PWV for splitting';
            app.recButton = uibutton(app.LeftPanel, 'push');
            app.recButton.ButtonPushedFcn = createCallbackFcn(app, @recButtonPushed, true);
            app.recButton.Text = '.rec';
            app.computewaveformsButton = uibutton(app.LeftPanel, 'push');
            app.computewaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @computewaveformsButtonPushed, true);
            app.computewaveformsButton.Visible = 'off';
            app.computewaveformsButton.Text = {'compute'; 'waveforms'};
            app.segmentationthresholdLabel = uilabel(app.LeftPanel);
            app.segmentationthresholdLabel.Text = {'segmentation'; 'threshold'};
            app.segmentationthresholdSlider = uislider(app.LeftPanel);
            app.segmentationthresholdSlider.Limits = [0 1];
            app.segmentationthresholdSlider.MajorTicks = [0 1];
            app.segmentationthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @segmentationthresholdSliderValueChanged, true);
            app.NumberofinvolvedpointsforsplittingLabel = uilabel(app.LeftPanel);
            app.NumberofinvolvedpointsforsplittingLabel.Text = 'Number of involved points:';
            app.NumberofinvolvedpointsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofinvolvedpointsEditField.Limits = [0 Inf];
            app.NumberofinvolvedpointsEditField.RoundFractionalValues = 'on';
            app.NumberofinvolvedpointsEditField.Editable = 'off';
            app.NumberofneighborsforaveragingwaveformsDropDownLabel = uilabel(app.LeftPanel);
            app.NumberofneighborsforaveragingwaveformsDropDownLabel.Visible = 'off';
            app.NumberofneighborsforaveragingwaveformsDropDownLabel.Text = 'Number of neighbors for averaging waveforms';
            app.NumberofneighborsforaveragingwaveformsDropDown = uidropdown(app.LeftPanel);
            app.NumberofneighborsforaveragingwaveformsDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumberofneighborsforaveragingwaveformsDropDown.Visible = 'off';
            app.NumberofneighborsforaveragingwaveformsDropDown.Value = '4';
            app.NormalizationmethodDropDownLabel = uilabel(app.LeftPanel);
            app.NormalizationmethodDropDownLabel.Visible = 'off';
            app.NormalizationmethodDropDownLabel.Text = 'Normalization method';
            app.NormalizationmethodDropDown = uidropdown(app.LeftPanel);
            app.NormalizationmethodDropDown.Items = {'int', 'range', 'scale', 'L2'};
            app.NormalizationmethodDropDown.Visible = 'off';
            app.NormalizationmethodDropDown.Value = 'int';
            app.unifycardiaccyclelengthviaDropDownLabel = uilabel(app.LeftPanel);
            app.unifycardiaccyclelengthviaDropDownLabel.Visible = 'off';
            app.unifycardiaccyclelengthviaDropDownLabel.Text = 'unify cardiac cycle length via';
            app.unifycardiaccyclelengthviaDropDown = uidropdown(app.LeftPanel);
            app.unifycardiaccyclelengthviaDropDown.Items = {'cutoff', 'average'};
            app.unifycardiaccyclelengthviaDropDown.Visible = 'off';
            app.unifycardiaccyclelengthviaDropDown.Value = 'cutoff';
            app.TotaldistanceincmEditFieldLabel = uilabel(app.LeftPanel);
            app.TotaldistanceincmEditFieldLabel.Text = 'Total distance in cm';
            app.TotaldistanceincmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TotaldistanceincmEditField.Editable = 'off';
            app.Step2Label = uilabel(app.LeftPanel);
            app.Step2Label.Text = 'Step 2: ';
            app.Step3Label = uilabel(app.LeftPanel);
            app.Step3Label.Text = 'Step 3:';
            app.Step1LoadDataLabel = uilabel(app.LeftPanel);
            app.Step1LoadDataLabel.Text = 'Step 1: Load Data';
            app.Step4ComputeWaveformsLabel = uilabel(app.LeftPanel);
            app.Step4ComputeWaveformsLabel.Text = 'Step 4: Compute Waveforms';
            app.Step5PerformPWALabel = uilabel(app.LeftPanel);
            app.Step5PerformPWALabel.Text = 'Step 5: Perform PWA';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Data visualization')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.PWVCalcDisplay = uiaxes(app.RightPanel);
            xlabel(app.PWVCalcDisplay, 'cardiac phase')
            ylabel(app.PWVCalcDisplay, 'blood flow [a.u.]')
            zlabel(app.PWVCalcDisplay, 'Z')
            app.PWVCalcDisplay.Visible = 'off';
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'angio visualization')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.Display2 = uiaxes(app.RightPanel);
            title(app.Display2, 'Artery segment')
            xlabel(app.Display2, 'X')
            ylabel(app.Display2, 'Y')
            zlabel(app.Display2, 'Z')
            app.Display2.Visible = 'off';
            app.Display3 = uiaxes(app.RightPanel);
            xlabel(app.Display3, 'PWV')
            ylabel(app.Display3, 'error')
            zlabel(app.Display3, 'Z')
            app.Display3.Visible = 'off';
            app.PWVDisplay = uieditfield(app.RightPanel, 'numeric');
            app.PWVDisplay.Editable = 'off';
            app.PWVDisplay.Visible = 'off';
            app.showpointNrDropDownLabel = uilabel(app.RightPanel);
            app.showpointNrDropDownLabel.Visible = 'off';
            app.showpointNrDropDownLabel.Text = 'show point Nr';
            app.showpointNrDropDown = uidropdown(app.RightPanel);
            app.showpointNrDropDown.Items = {'data'};
            app.showpointNrDropDown.ValueChangedFcn = createCallbackFcn(app, @showpointNrDropDownValueChanged, true);
            app.showpointNrDropDown.Visible = 'off';
            app.showpointNrDropDown.Value = 'data';
            app.ShowsumCheckBox = uicheckbox(app.RightPanel);
            app.ShowsumCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowsumCheckBoxValueChanged, true);
            app.ShowsumCheckBox.Visible = 'off';
            app.ShowsumCheckBox.Text = 'Show sum';
            app.PWVinmsLabel = uilabel(app.RightPanel);
            app.PWVinmsLabel.Visible = 'off';
            app.PWVinmsLabel.Text = 'PWV in m/s';
            app.YButton = uibutton(app.RightPanel, 'push');
            app.YButton.Visible = 'off';
            app.YButton.Text = '+Y';
            app.YButton_2 = uibutton(app.RightPanel, 'push');
            app.YButton_2.Visible = 'off';
            app.YButton_2.Text = '-Y';
            app.XButton_2 = uibutton(app.RightPanel, 'push');
            app.XButton_2.Visible = 'off';
            app.XButton_2.Text = '-X';
            app.XButton = uibutton(app.RightPanel, 'push');
            app.XButton.Visible = 'off';
            app.XButton.Text = '+X';
            app.ZButton = uibutton(app.RightPanel, 'push');
            app.ZButton.Visible = 'off';
            app.ZButton.Text = '+Z';
            app.ZButton_2 = uibutton(app.RightPanel, 'push');
            app.ZButton_2.Visible = 'off';
            app.ZButton_2.Text = '-Z';
            app.translateYplus = uibutton(app.RightPanel, 'push');
            app.translateYplus.ButtonPushedFcn = createCallbackFcn(app, @translateYplusButtonPushed, true);
            app.translateYplus.Visible = 'off';
            app.translateYplus.Text = '+Y';
            app.translateYminus = uibutton(app.RightPanel, 'push');
            app.translateYminus.ButtonPushedFcn = createCallbackFcn(app, @translateYminusButtonPushed, true);
            app.translateYminus.Visible = 'off';
            app.translateYminus.Text = '-Y';
            app.translateXplus = uibutton(app.RightPanel, 'push');
            app.translateXplus.ButtonPushedFcn = createCallbackFcn(app, @translateXplusPushed, true);
            app.translateXplus.Visible = 'off';
            app.translateXplus.Text = '+X';
            app.translateXminus = uibutton(app.RightPanel, 'push');
            app.translateXminus.ButtonPushedFcn = createCallbackFcn(app, @translateXminusPushed, true);
            app.translateXminus.Visible = 'off';
            app.translateXminus.Text = '-X';
            app.translateZplus = uibutton(app.RightPanel, 'push');
            app.translateZplus.ButtonPushedFcn = createCallbackFcn(app, @translateZplusButtonPushed, true);
            app.translateZplus.Visible = 'off';
            app.translateZplus.Text = '+Z';
            app.translateZminus = uibutton(app.RightPanel, 'push');
            app.translateZminus.ButtonPushedFcn = createCallbackFcn(app, @translateZminusButtonPushed, true);
            app.translateZminus.Visible = 'off';
            app.translateZminus.Text = '-Z';
            app.TranslateLabel = uilabel(app.RightPanel);
            app.TranslateLabel.Visible = 'off';
            app.TranslateLabel.Text = 'Translate';
            app.RotateLabel = uilabel(app.RightPanel);
            app.RotateLabel.Visible = 'off';
            app.RotateLabel.Text = 'Rotate';
            app.AdjustAlignmentofangioandvelocitydataLabel = uilabel(app.RightPanel);
            app.AdjustAlignmentofangioandvelocitydataLabel.Visible = 'off';
            app.AdjustAlignmentofangioandvelocitydataLabel.Text = 'Adjust Alignment of angio and velocity data';
            app.ViewLabel = uilabel(app.RightPanel);
            app.ViewLabel.Visible = 'off';
            app.ViewLabel.Text = 'View:';
            app.YZButton = uibutton(app.RightPanel, 'state');
            app.YZButton.ValueChangedFcn = createCallbackFcn(app, @YZButtonValueChanged, true);
            app.YZButton.Visible = 'off';
            app.YZButton.Text = 'YZ';
            app.XZButton = uibutton(app.RightPanel, 'state');
            app.XZButton.ValueChangedFcn = createCallbackFcn(app, @XZButtonValueChanged, true);
            app.XZButton.Visible = 'off';
            app.XZButton.Text = 'XZ';
            app.XYButton = uibutton(app.RightPanel, 'state');
            app.XYButton.ValueChangedFcn = createCallbackFcn(app, @XYButtonValueChanged, true);
            app.XYButton.Visible = 'off';
            app.XYButton.Text = 'XY';
            app.SaveresultsButton = uibutton(app.RightPanel, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Visible = 'off';
            app.SaveresultsButton.Text = 'Save results';
            app.DButton = uibutton(app.RightPanel, 'state');
            app.DButton.ValueChangedFcn = createCallbackFcn(app, @DButtonValueChanged, true);
            app.DButton.Visible = 'off';
            app.DButton.Text = '3D';
            app.DButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PWA_Tool_LW

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end