classdef PWApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        MinBranchlengthmmSpinner        matlab.ui.control.Spinner
        MinBranchlengthmmSpinnerLabel   matlab.ui.control.Label
        segmentationthresholdSlider     matlab.ui.control.Slider
        segmentationthresholdLabel      matlab.ui.control.Label
        Step2Label                      matlab.ui.control.Label
        CreatecenterlineBranchesButton  matlab.ui.control.Button
        SavesettingButton               matlab.ui.control.Button
        LoadprevioussettingButton       matlab.ui.control.Button
        Step5PerformPWALabel            matlab.ui.control.Label
        Step4ComputewaveformsLabel      matlab.ui.control.Label
        Step1LoaddataLabel              matlab.ui.control.Label
        Step3Label                      matlab.ui.control.Label
        TotaldistanceincmEditField      matlab.ui.control.NumericEditField
        TotaldistanceincmEditFieldLabel  matlab.ui.control.Label
        UnifycardiaccyclelengthviaDropDown  matlab.ui.control.DropDown
        UnifycardiaccyclelengthviaDropDownLabel  matlab.ui.control.Label
        NormalizationmethodDropDown     matlab.ui.control.DropDown
        NormalizationmethodDropDownLabel  matlab.ui.control.Label
        NumberofneighborsforaveragingwaveformsDropDown  matlab.ui.control.DropDown
        NumberofneighborsforaveragingwaveformsDropDownLabel  matlab.ui.control.Label
        NumberofinvolvedpointsEditField  matlab.ui.control.NumericEditField
        NumberofinvolvedpointsforsplittingLabel  matlab.ui.control.Label
        ComputewaveformsButton          matlab.ui.control.Button
        PWVforsplittingEditFieldLabel   matlab.ui.control.Label
        PWVforsplittingEditField        matlab.ui.control.NumericEditField
        CompareregularizationparametersButton  matlab.ui.control.Button
        PerformsplittingonlyButton      matlab.ui.control.Button
        OptionsPanel                    matlab.ui.container.Panel
        infoButton                      matlab.ui.control.Button
        SmoothingparameterEditField     matlab.ui.control.NumericEditField
        SmoothingparameterEditFieldLabel  matlab.ui.control.Label
        RegularizationparameterEditField  matlab.ui.control.NumericEditField
        RegularizationparameterEditFieldLabel  matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        PWVType                         matlab.ui.control.DropDown
        CropvelocityButton              matlab.ui.control.Button
        ScanInfoTable                   matlab.ui.control.Table
        PerformpulsewaveanalysisButton  matlab.ui.control.Button
        RestartButton                   matlab.ui.control.Button
        SelectdatapointsforPWVestimationButton  matlab.ui.control.Button
        PWAppLabel                      matlab.ui.control.Label
        LoadangioCheckBox               matlab.ui.control.CheckBox
        LoadvelocitydataPanel           matlab.ui.container.Panel
        EditField                       matlab.ui.control.NumericEditField
        LoadvelocitydataButton          matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        EditField_2                     matlab.ui.control.NumericEditField
        CropangioButton                 matlab.ui.control.Button
        segmentationthresholdSlider_2   matlab.ui.control.Slider
        segmentationthresholdSlider_2Label  matlab.ui.control.Label
        LoadangiodataButton             matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        DButton_2                       matlab.ui.control.StateButton
        XYButton_2                      matlab.ui.control.StateButton
        XZButton_2                      matlab.ui.control.StateButton
        YZButton_2                      matlab.ui.control.StateButton
        ViewLabel_2                     matlab.ui.control.Label
        DButton                         matlab.ui.control.StateButton
        SaveresultsButton               matlab.ui.control.Button
        XYButton                        matlab.ui.control.StateButton
        XZButton                        matlab.ui.control.StateButton
        YZButton                        matlab.ui.control.StateButton
        ViewLabel                       matlab.ui.control.Label
        PWVinmsLabel                    matlab.ui.control.Label
        ShowsumCheckBox                 matlab.ui.control.CheckBox
        ShowpointNrDropDown             matlab.ui.control.DropDown
        ShowpointNrDropDownLabel        matlab.ui.control.Label
        PWVDisplay                      matlab.ui.control.NumericEditField
        Display3                        matlab.ui.control.UIAxes
        Display2                        matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        PWVCalcDisplay                  matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        centerline_performed=0
        data_loaded=0
    end
    
    properties (Access = public)
        publicangio    % Public angios to pass and take from crop app 
        PWVpointList
        flip1 = 0
        flip2 = 0 
        flip3 = 0
        flip4 = 0
        pointsselectedManually = 1
        branches
        %Public from here on only to pass to subapps for visualization of
        %waveforms
        cardiacFrames
        pixdim
        delays
        timeres
        v
        directionFlagVelocity
        MAG
        res
        vangio3d
        vsegmentation
        angiores
        angiopixdim
        angio3d
        directionFlagAngio
        angiosegmentation
        branchList
        branchActual
        angioOrientation
        vOrientation
        angioPosition
        vPosition
        v_shifted
        waveforms
        rho
        reconstructed_waveforms
        distances
        numberofpoints
        angiovis
        angiochangedflag
        param
        PWV
        currentstep = 1
    end
    
    methods (Access = private)

        function Update3DSegmentation(app)
            % Visualization update for left figure
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            app.vsegmentation = zeros(size(app.vangio3d));
            w=app.segmentationthresholdSlider.Value*max(app.vangio3d,[],"all");
            app.vsegmentation(app.vangio3d>w) = 1;
            app.vsegmentation = bwareaopen(app.vsegmentation,round(sum(app.vsegmentation(:)).*0.0002),26); %The value at the end of the command in the minimum area of each segment to keep
            app.vsegmentation = imfill(app.vsegmentation,'holes'); % Fill in holes created by slow flow on the inside of vessels
            app.vsegmentation = single(app.vsegmentation);  
            app.UIAxes.Visible='on';
            app.XYButton_2.Visible='on';
            app.XZButton_2.Visible='on';
            app.YZButton_2.Visible='on';
            app.DButton_2.Visible='on';
            app.ViewLabel_2.Visible='on';
            %----------
            cla(app.UIAxes)
            ss = smooth3(app.vsegmentation);
            hpatch1 = patch(app.UIAxes,isosurface(ss,.05),'FaceColor','red','EdgeColor', 'red','FaceAlpha',0.15);
            hpatch2 = patch(app.UIAxes,isocaps(ss,.05),'FaceColor','red','EdgeColor', 'none','FaceAlpha',0.25);
            reducepatch(hpatch1 ,0.15);

            camlight(app.UIAxes);
            lighting(app.UIAxes,'gouraud');
            lightangle(app.UIAxes,0,0);
            if app.XYButton_2.Value
                view(app.UIAxes, [0 90]);
            elseif app.XZButton_2.Value
                view(app.UIAxes, [0 0]);
            elseif app.YZButton_2.Value
                view(app.UIAxes, [90 0]);
            elseif app.DButton_2.Value
                view(app.UIAxes,3);
            end
            daspect(app.UIAxes,[1/app.pixdim(1) 1/app.pixdim(2) 1/app.pixdim(3)])
            disableDefaultInteractivity(app.UIAxes); 
            index=find(app.vsegmentation);
            [x,y,z]=ind2sub(size(app.vsegmentation),index);
            m_xstart = min(x); m_ystart = min(y); m_zstart = min(z);
            m_xstop = max(x); m_ystop = max(y); m_zstop = max(z);
            if ~isempty(m_ystart)
                xlim(app.UIAxes,[m_ystart-5 m_ystop+5]);
            end
            if ~isempty(m_xstart)
                ylim(app.UIAxes,[m_xstart-5 m_xstop+5]);
            end
            set(app.UIFigure,'Pointer','arrow');
        end


        function Update3DAngioSegmentation(app)
            % Visualization update for right figure
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            app.angiochangedflag=1;
            app.angiosegmentation = zeros(size(app.angio3d));
            max_angio = max(app.angio3d,[],"all");
            w=app.segmentationthresholdSlider_2.Value* max_angio;
            app.angiosegmentation(app.angio3d>w) = 1;
            app.angiosegmentation = bwareaopen(app.angiosegmentation,round(sum(app.angiosegmentation(:)).*0.0002),26); %The value at the end of the commnad in the minimum area of each segment to keep
            app.angiosegmentation = imfill(app.angiosegmentation,'holes'); % Fill in holes created by slow flow on the inside of vessels
            app.angiosegmentation = single(app.angiosegmentation); 
            app.UIAxes2.Visible='on';
            app.XYButton.Visible='on';
            app.XZButton.Visible='on';
            app.YZButton.Visible='on';
            app.DButton.Visible='on';
            app.ViewLabel.Visible='on';
            %----------
            cla(app.UIAxes2)
            ss = smooth3(app.angiosegmentation);
            is=isosurface(ss,.05);
            reducepatch(is ,0.15);
            hpatch1 = patch(app.UIAxes2,is,'FaceColor','red','EdgeColor', 'none','FaceAlpha',0.25);
            camlight(app.UIAxes2);
            lighting(app.UIAxes2,'gouraud');
            lightangle(app.UIAxes2,0,0);
            if app.XYButton.Value
                view(app.UIAxes2, [0 90]);
            elseif app.XZButton.Value
                view(app.UIAxes2, [0  0]);
            elseif app.YZButton.Value
                view(app.UIAxes2, [90 0]);
            end
            disableDefaultInteractivity(app.UIAxes2); 
            daspect(app.UIAxes2,[1/app.angiopixdim(1) 1/app.angiopixdim(2) 1/app.angiopixdim(3)])
            index=find(app.angiosegmentation);
            [x,y,z]=ind2sub(size(app.angiosegmentation),index);
            m_xstart = min(x); m_ystart = min(y); m_zstart = min(z); 
            m_xstop = max(x); m_ystop = max(y); m_zstop = max(z);
            
            if ~isempty(m_xstart)
                ylim(app.UIAxes2,[m_xstart-5 m_xstop+5]);
            end
            if ~isempty(m_ystart)
                xlim(app.UIAxes2,[m_ystart-5 m_ystop+5]);
            end
            if ~isempty(m_zstart)
                zlim(app.UIAxes2,[m_zstart-5 m_zstop+5]);
            end
            set(app.UIFigure,'Pointer','arrow');
        end


        function reset3DSegmentationAndCenterline(app)
            % Update visualization with centerline
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            % Initialize figure
            colorbar(app.UIAxes2,'off')
            cla(app.UIAxes2);
            app.XYButton.Visible='on';
            app.XZButton.Visible='on';
            app.YZButton.Visible='on';
            app.DButton.Visible='on';
            app.ViewLabel.Visible='on';
            if app.LoadangioCheckBox.Value
                if app.angiochangedflag
                    ss = smooth3(app.angiosegmentation);
                    app.angiovis=isosurface(ss,0.5);
                    app.angiochangedflag=0;
                end
                hpatch = patch(app.UIAxes2,app.angiovis,'FaceAlpha',0.20);
                reducepatch(hpatch,0.3);
                set(hpatch,'FaceColor',[0.7 0.7 0.7],'EdgeColor', 'none','PickableParts','none');
                [app.v_shifted,~]=shift_position_affine(app.vsegmentation,app.vPosition,app.vOrientation,app.pixdim, ...
                size(app.angiosegmentation), app.angioPosition,app.angioOrientation,app.angiopixdim,app.directionFlagVelocity,app.directionFlagAngio,"interpolation","cutoff");
                ss = smooth3(app.v_shifted);
                
                hpatch1 = patch(app.UIAxes2,isosurface(ss,.05),'FaceColor','red','EdgeColor', 'none','FaceAlpha',0.25);
                reducepatch(hpatch1 ,0.3);
                index=find(app.v_shifted);
                [x,y,z]=ind2sub(size(app.angiosegmentation),index);
                m_xstart = min(x); m_ystart = min(y); m_zstart = min(z);
                m_xstop = max(x); m_ystop = max(y); m_zstop = max(z);
                
                if ~isempty(m_xstart)
                    ylim(app.UIAxes2,[m_xstart-5 m_xstop+5]);
                end
                if ~isempty(m_ystart)
                    xlim(app.UIAxes2,[m_ystart-5 m_ystop+5]);
                end
                if ~isempty(m_zstart)
                    zlim(app.UIAxes2,[m_zstart-5 m_zstop+5]);
                end
                daspect(app.UIAxes2,[(1/app.angiopixdim(1))/(1/app.angiopixdim(1)) (1/app.angiopixdim(2))/(1/app.angiopixdim(1)) (1/app.angiopixdim(3))/(1/app.angiopixdim(1))])
            else
                ss = smooth3(app.vsegmentation);
                hpatch = patch(app.UIAxes2,isosurface(ss,0.5),'FaceAlpha',0.20);
                reducepatch(hpatch,0.3);
                set(hpatch,'FaceColor',[0.7 0.7 0.7],'EdgeColor', 'none','PickableParts','none');
                index=find(app.vsegmentation);
                [x,y,z]=ind2sub(size(app.vsegmentation),index);
                m_xstart = min(x); m_ystart = min(y); m_zstart = min(z);
                m_xstop = max(x); m_ystop = max(y); m_zstop = max(z);
                if ~isempty(m_xstart)
                    ylim(app.UIAxes2,[m_xstart-5 m_xstop+5]);
                end
                if ~isempty(m_ystart)
                    xlim(app.UIAxes2,[m_ystart-5 m_ystop+5]);
                end
                if ~isempty(m_zstart)
                    zlim(app.UIAxes2,[m_zstart-5 m_zstop+5]);
                end
                daspect(app.UIAxes2,[1/app.pixdim(1) 1/app.pixdim(2) 1/app.pixdim(3)])
                
            end
            axis(app.UIAxes2, 'vis3d')
            axis(app.UIAxes2, 'on')
            colormap(app.UIAxes2,'colorcube')
            camlight(app.UIAxes2);
            lighting(app.UIAxes2,'gouraud');
            if app.XYButton.Value
                view(app.UIAxes2, [0 90]);
            elseif app.XZButton.Value
                view(app.UIAxes2, [0 0]);
            elseif app.YZButton.Value
                view(app.UIAxes2, [90 0]);
            end
            if isempty(app.branchList)
                errordlg("No artery branches were found in the data");
            else
                unqBranches = unique(app.branchList(:,4));
                c = lines(length(unqBranches));
                for b = 1:length(unqBranches)
                    % extract coordinates for branch and plot
                    currBranch = find(app.branchList(:,4) == b);
                    hline(b) = line(app.UIAxes2, ...
                        app.branchList(currBranch,2),app.branchList(currBranch,1),app.branchList(currBranch,3),...
                        'Color',c(b,:),'Marker','.','MarkerSize',12,'LineStyle','none');
                end
                c_dark=c.*0.8;
                tmp=c_dark<0;
                c_dark(tmp)=0;
                % Put the number labels on the CenterlinePlot
                for i = 1:length(unqBranches)
                    %find rows where textint = branchList(:,4) and take the mid point
                    temp = app.branchList(app.branchList(:,4) == i,1:3);
                    textLoc(i,1:3) = temp(round(size(temp,1)/2),:);
                    Ntxt = text(app.UIAxes2,textLoc(unqBranches(i),2)+4,textLoc(unqBranches(i),1)+4,textLoc(unqBranches(i),3)+4,...
                    num2str(unqBranches(i)),'Color',c_dark(i,:),'FontSize',18,'FontWeight', 'bold','Margin', 1,...
                    'HitTest','off','PickableParts','none');
                end
            end
            disableDefaultInteractivity(app.UIAxes2); 
            set(app.UIFigure,'Pointer','arrow');
        end
        
        function make_movie(app)
            %Show movie of flow data in seperate figure after data loading
            data_amp=max(app.v,[],4)-min(app.v,[],4);
            [~,mipzind]=max(data_amp,[],3);
            frames=zeros(app.res(1),app.res(2),app.cardiacFrames);
            for x=1:app.res(1)
                for y=1:app.res(2)
                    frames(x,y,:)=app.v(x,y,mipzind(x,y),:);
                end
            end

            meanframes=mean(frames,3);
            for t=1:app.cardiacFrames
                frames(:,:,t)=frames(:,:,t)-meanframes; 
            end 
            frames = rescale(frames,0,1);

            h=figure;
            h.CloseRequestFcn = @(src, event) onCloseFigure();
            ax = axes(h);
            colormap('gray')
            axis image
            axis off

            % Show the first frame and get image handle
            hImage = imagesc(frames(:, :, 1), 'Parent', ax);
            set(ax, 'YDir', 'normal');
            frameIndex = 1;
            movieTimer = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', round(app.timeres/1000,3), ...  % seconds per frame
                'TimerFcn', @(~,~) updateFrame());
                
            start(movieTimer);

            % Local nested function to update frames
            function updateFrame()
                if isvalid(h)  % Make sure the image/figure still exists
                    frameIndex = mod(frameIndex, app.cardiacFrames) + 1;
                    set(hImage, 'CData', frames(:,:,frameIndex));
                else
                    stop(movieTimer);
                    delete(movieTimer);
                end
            end
            
            % Local nested function for closing
            function onCloseFigure()
                try 
                    if isvalid(movieTimer)
                        stop(movieTimer);
                        delete(movieTimer);
                    end
                catch
                end
                delete(h);
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % when app is started, make sure to add the utilities
            % folder (and subfolders) so all functions are in the current
            % path
            [appDirectory,~,~] = fileparts(mfilename("fullpath"));
            addpath(genpath([appDirectory '/utilities']));
            app.UIFigure.Position = [0 0 1198 704];
            app.EditField.Value = app.segmentationthresholdSlider.Value;
            app.EditField_2.Value = app.segmentationthresholdSlider_2.Value;
        end

        % Button pushed function: LoadvelocitydataButton
        function LoadvelocitydataButtonPushed(app, event)
            %data loading via specified script
            app.currentstep = 1;
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [appDirectory,~,~] = fileparts(mfilename("fullpath"));
            dir_str=append(appDirectory,'/*.m');
            [filename_loadingfile,directory_loadingfile] = uigetfile(dir_str,'Select data-loading skript','read_dicoms.m');
            delete(f); %delete the dummy figure
            if directory_loadingfile ~= 0
                addpath(directory_loadingfile)
                evalstr = erase(append(filename_loadingfile,'()'),'.m');
                [app.cardiacFrames, app.res, app.pixdim, app.delays, app.v, app.MAG, ...
                app.vOrientation,app.vPosition,app.directionFlagVelocity] = eval(evalstr);
                

                %check whether data loading was successful via correct size
                %of transfer parameters
                if length(app.pixdim) == 3 ...
                        && length(app.res) == 3 ...
                        && all(size(app.delays) == [app.res(3) app.cardiacFrames]) ...
                        && all(size(app.v) == [app.res(1) app.res(2) app.res(3) app.cardiacFrames]) ...
                        && all(size(app.MAG) == [app.res(1) app.res(2) app.res(3) app.cardiacFrames]) ...
                        && length(app.vOrientation) == 6 ...
                        && length(app.vPosition) == 3 ...
                        && abs(app.directionFlagVelocity)== 1
                    app.vangio3d = mean(app.MAG.*app.v,4);
                    app.timeres=mean(diff(app.delays,1,2),'all');
                    % add to info table
                    app.ScanInfoTable.Data = cat(2,cellstr([num2str(app.res(1)) ' x ' num2str(app.res(2)) ' x ' num2str(app.res(3))]),...
                        cellstr([num2str(round(app.pixdim(1),1)) ' x ' num2str(round(app.pixdim(2),1)) ' x ' num2str(round(app.pixdim(3),1))]),...
                        cellstr(num2str(app.timeres)),cellstr(num2str(app.cardiacFrames)));
         
                    % update visualization
                    Update3DSegmentation(app);
        
                    % set intial parameters
                    app.CropvelocityButton.Visible='on';
                    app.centerline_performed=0;
                    app.data_loaded=1;
                    make_movie(app)
                else
                    errordlg("Data loading not successful, sizes of transferred variables dont match.")
                end
            end
            set(app.UIFigure,'Pointer','arrow');
        end

        % Value changed function: LoadangioCheckBox
        function LoadangioCheckBoxValueChanged(app, event)
            %optional loading of angiogram 
            app.currentstep = 1;
            value = app.LoadangioCheckBox.Value;
            if value==1 
                app.LoadangiodataButton.Visible='on';
                app.segmentationthresholdSlider_2.Visible='on';
                app.EditField_2.Visible = 'on';
                app.segmentationthresholdSlider_2Label.Visible='on';
            else 
                app.LoadangiodataButton.Visible='off';
                app.segmentationthresholdSlider_2.Visible='off';
                app.EditField_2.Visible = 'on';
                app.segmentationthresholdSlider_2Label.Visible='off';
                app.CropangioButton.Visible='off';
            end
        end

        % Value changed function: segmentationthresholdSlider
        function segmentationthresholdSliderValueChanged(app, event)
            %changes segmentation and updates visualization
            app.currentstep = 1;
            if app.data_loaded==1
                set(app.UIFigure,'Pointer','watch');
                drawnow()
                Update3DSegmentation(app);
                app.centerline_performed=0;
                set(app.UIFigure,'Pointer','arrow');
            end
            app.EditField.Value = app.segmentationthresholdSlider.Value;
        end

        % Button pushed function: LoadangiodataButton
        function LoadangiodataButtonPushed(app, event)
            % angiogram loading via specified script
            app.currentstep = 1;
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [appDirectory,~,~] = fileparts(mfilename("fullpath"));
            dir_str=append(appDirectory,'/*.m');
            [filename_loadingfile,directory_loadingfile] = uigetfile(dir_str,'Select angio-loading skript','read_angio_dicoms.m');
            delete(f); %delete the dummy figure
            if directory_loadingfile ~= 0
                addpath(directory_loadingfile)
                evalstr = erase(append(filename_loadingfile,'()'),'.m');
                [app.angiores, app.angiopixdim,...
                    app.angio3d,app.angioOrientation,app.angioPosition,app.directionFlagAngio] = eval(evalstr);
                if length(app.angiopixdim) == 3 ...
                        && length(app.angiores) == 3 ...
                        && all(size(app.angio3d) == [app.angiores(1) app.angiores(2) app.angiores(3)]) ...
                        && length(app.angioOrientation) == 6 ...
                        && length(app.angioPosition) == 3 ...
                        && abs(app.directionFlagAngio)== 1
                    app.segmentationthresholdSlider_2.Visible='on';
                    app.EditField_2.Visible = 'on';
                    Update3DAngioSegmentation(app);
                    app.CropangioButton.Visible='on';
                else
                    errordlg("Angio loading not successful, sizes of transferred variables dont match.")
                end
            end
            set(app.UIFigure,'Pointer','arrow');
        end

        % Value changed function: segmentationthresholdSlider_2
        function segmentationthresholdSlider_2ValueChanged(app, event)
            % changes segmentation of angiogram data and updates right
            % visualization figure
            app.EditField_2.Value = app.segmentationthresholdSlider_2.Value;
            app.currentstep = 1;
            set(app.UIFigure,'Pointer','watch');  
            drawnow()
            Update3DAngioSegmentation(app);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: CropvelocityButton
        function CropvelocityButtonPushed(app, event)
            %start crop app for flow data and update visualization after cropping
            app.currentstep = 1;
            Crop=crop_app(app,app.vangio3d);
            while isvalid(Crop);pause(0.1);end
            app.vangio3d=app.publicangio;
            Update3DSegmentation(app);
        end

        % Button pushed function: CropangioButton
        function CropangioButtonPushed(app, event)
            %start crop app for angiogram data and update visualization after cropping
            app.currentstep = 1;
            Crop=crop_app(app,app.angio3d);
            while isvalid(Crop);pause(0.1);end
            app.angio3d=app.publicangio;
            Update3DAngioSegmentation(app);
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            %fully restart app
            drawnow()
            delete(app);
            PWApp;
        end

        % Button pushed function: CreatecenterlineBranchesButton
        function CreatecenterlineBranchesButtonPushed(app, event)
            % skeletonization, branch identification and update of
            % visualization
            
            if app.data_loaded == 1
                set(app.UIFigure,'Pointer','watch');
                drawnow()
                sortingCriteria = 3;
                spurLength = app.MinBranchlengthmmSpinner.Value/min(app.pixdim);
                se = strel('sphere',1);
                if app.LoadangioCheckBox.Value
                    se = strel('sphere',1);
                    ss = imerode(app.angiosegmentation,se);
                else
                    ss = app.vsegmentation;   
                end
                d = uiprogressdlg(app.UIFigure, 'Title','Processing', 'Message','Centerline & branch computation', 'Indeterminate','on');
                drawnow;

                % h=waitbar(0,"Centerline & branch computation");
                % wbch = allchild(h);
                % jp = wbch(1).JavaPeer;
                % jp.setIndeterminate(1)
                Y=thinning(ss);
                [~,~, app.branchList, ~] = centerline(Y, spurLength, sortingCriteria);
                reset3DSegmentationAndCenterline(app);
                %delete(h);
                delete(d);
                app.centerline_performed=1;
                app.currentstep = 2;
                app.PWVpointList = [];
                app.flip1 = 0;
                app.flip2 = 0; 
                app.flip3 = 0;
                app.flip4 = 0;
                app.branches = [];
                app.waveforms = [];
                app.distances = [];
                app.numberofpoints = [];
                app.rho = [];
                app.reconstructed_waveforms = [];
                app.param = [];
                app.PWV = [];

            else
                errordlg("Perform step 1 first!")
            end
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: SelectdatapointsforPWVestimationButton
        function SelectdatapointsforPWVestimationButtonPushed(app, event)
            %opens subapp for datapoint selection, uf datapoints are
            %successfully selected, the distances between the points are
            %calculated
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            if app.currentstep > 1
                if app.LoadangioCheckBox.Value
                    select=select_datapoints_app(app,app.angiosegmentation,app.branchList,app.v_shifted,app.angiopixdim,app.pixdim,app.v);
                else
                    select=select_datapoints_app(app,app.angiosegmentation,app.branchList,app.vsegmentation,app.angiopixdim,app.pixdim,app.v);
                end
                while isvalid(select);pause(0.1);end
                if ~isempty(app.PWVpointList)
                    idx = [];
                    %  extract branches, if flip checkbox on, flip the centerline
                    for b = 1:numel(app.branches)
                        tmpIdx = find(app.branchList(:,4)==app.branches(b));
                        switch b
                            case 1
                                if app.flip1
                                    tmpIdx = flip(tmpIdx,1);
                                end
                            case 2
                                if app.flip2
                                    tmpIdx = flip(tmpIdx,1);
                                end
                            case 3
                                if app.flip3
                                    tmpIdx = flip(tmpIdx,1);
                                end
                            case 4
                                if app.flip4
                                    tmpIdx = flip(tmpIdx,1);
                                end
                        end
                        idx = cat(1,idx,tmpIdx);
                    end
                    app.branchActual = app.branchList(idx,1:3);
                    %distance computation
                    if app.LoadangioCheckBox.Value
                        [all_distances,~]=curvedistance(app,app.angiopixdim'.*app.branchActual,1,5);% in mm
                    else
                        [all_distances,~]=curvedistance(app,app.pixdim'.*app.branchActual,1,5);% in mm
                    end
                    [~,k]=ismember(app.PWVpointList(:,1:3),app.branchActual,'rows');
                    [~,I]=sort(k);
                    app.PWVpointList=app.PWVpointList(I,:);
                    app.numberofpoints=size(app.PWVpointList,1);
                    app.distances=all_distances(sort(k));
                    app.distances=app.distances-app.distances(1);
                    if max(diff(all_distances)) > 10
                        uialert(app.UIFigure,"Looks like the artery segment is not connected, better double check your branch-selection and orientation!","Warning","Icon","warning");
                    end
                    app.TotaldistanceincmEditField.Value=round(app.distances(end)/10,1);
                    %transform PWVPoints in shifted image back to original
                    if app.LoadangioCheckBox.Value
                        [~,v_ind]=shift_position_affine(app.PWVpointList(:,1:3),app.angioPosition,app.angioOrientation,app.angiopixdim, ...
                            size(app.vsegmentation), app.vPosition,app.vOrientation,app.pixdim,app.directionFlagAngio,app.directionFlagVelocity,"nointerpolation","nocutoff");
                        [~,branch_ind]=shift_position_affine(app.branchActual,app.angioPosition,app.angioOrientation,app.angiopixdim, ...
                            size(app.vsegmentation), app.vPosition,app.vOrientation,app.pixdim,app.directionFlagAngio,app.directionFlagVelocity,"nointerpolation","nocutoff");
                    else
                        v_ind=app.PWVpointList(:,1:3);
                        branch_ind=app.branchActual;
                    end
                    Update3DSegmentation(app);
                    hline1 = line(app.UIAxes,branch_ind(:,2),branch_ind(:,1),branch_ind(:,3),...
                            'Marker','none','LineStyle','-','LineWidth', 1.5);
                    c = lines(app.numberofpoints);
                    for i = 1:app.numberofpoints
                        hline2 = line(app.UIAxes,v_ind(i,2),v_ind(i,1),v_ind(i,3),...
                        'Marker','.','MarkerSize',30,'Color',c(i,:),'LineStyle','none');
                        if app.numberofpoints<15
                        Ntxt = text(app.UIAxes,v_ind(i,2)-2,v_ind(i,1)+2,v_ind(i,3)-2,...
                        num2str(i),'FontSize',14,'FontWeight', 'bold','Margin', 1,...
                        'HitTest','off','PickableParts','none');
                        end
                    end
                    app.NumberofinvolvedpointsEditField.Value=app.numberofpoints;
                    app.ComputewaveformsButton.Visible='on';
                    app.UnifycardiaccyclelengthviaDropDown.Visible='on';
                    app.UnifycardiaccyclelengthviaDropDownLabel.Visible='on';
                    app.NormalizationmethodDropDown.Visible='on';
                    app.NormalizationmethodDropDownLabel.Visible='on';
                    app.NumberofneighborsforaveragingwaveformsDropDown.Visible='on';
                    app.NumberofneighborsforaveragingwaveformsDropDownLabel.Visible='on';
                    app.SavesettingButton.Visible='on';
                end
                app.currentstep = 3;
                app.waveforms = [];
                app.rho = [];
                app.reconstructed_waveforms = [];
                app.param = [];
                app.PWV = [];
            else
                errordlg("Perform step 2 first!")
            end
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: ComputewaveformsButton
        function ComputewaveformsButtonPushed(app, event)
            %calculate waveforms from selected points with specified
            %options. waveforms are plotted
            if app.currentstep < 3
                errordlg("Perform step 3 first!")
            else
                set(app.UIFigure,'Pointer','watch');
                drawnow()
                if app.LoadangioCheckBox.Value
                    [~,v_ind]=shift_position_affine(app.PWVpointList(:,1:3),app.angioPosition,app.angioOrientation,app.angiopixdim, ...
                        size(app.vsegmentation), app.vPosition,app.vOrientation,app.pixdim,app.directionFlagAngio,app.directionFlagVelocity,"nointerpolation","nocutoff");
                else
                    v_ind=app.PWVpointList(:,1:3);
                end
                [x_seg,y_seg,z_seg]=ind2sub(size(app.vsegmentation),find(app.vsegmentation));
                avn=str2num(app.NumberofneighborsforaveragingwaveformsDropDown.Value);
                neighbor_idx=knnsearch([x_seg y_seg z_seg],v_ind,'K',avn);
                %use v_segmentation to find neighbors
                app.waveforms=zeros(app.numberofpoints,size(app.v,4));
                cutoffstr="cutoff";
                averagestr="average";
                if strcmp(app.UnifycardiaccyclelengthviaDropDown.Value,averagestr)
                    card_time = [0:app.cardiacFrames-1]*app.timeres;
                end
                for idx=1:app.numberofpoints
                    for i_neighbors=1:avn
                        i_temp=neighbor_idx(idx,i_neighbors);
                        if strcmp(app.UnifycardiaccyclelengthviaDropDown.Value,cutoffstr)
                            v_cutoff=squeeze(app.v(x_seg(i_temp),y_seg(i_temp),z_seg(i_temp),:))';
                            if i_neighbors==1 && idx==1
                                [~,min_ind]=min(v_cutoff);
                                [~,shortest_interval]=min(app.delays(:,end));
                                %card_time as shortest inverval from app.delays
                                card_time = app.delays(shortest_interval,:);
                                app.timeres = card_time(end)/app.cardiacFrames;
                                t_shift=app.delays(z_seg(1),min_ind);
                                pixelshift=t_shift*app.cardiacFrames./app.delays(:,end);   
                            end
                            shift_temp=pixelshift(z_seg(i_temp));
                            % interpolation of waves on shortest interval
                            v_cutoff=FourierShift(v_cutoff,-shift_temp);
                            v_cutoff=interp1(app.delays(z_seg(i_temp),:),v_cutoff,app.delays(shortest_interval,:));
                            app.waveforms(idx,:)=app.waveforms(idx,:)+v_cutoff/avn;
                        elseif strcmp(app.UnifycardiaccyclelengthviaDropDown.Value,averagestr)
                            app.waveforms(idx,:)=app.waveforms(idx,:)+squeeze(app.v(x_seg(i_temp),y_seg(i_temp),z_seg(i_temp),:))'/avn;
                        end
                    end
                end
                app.waveforms=app.waveforms-mean(app.waveforms,2);
                switch app.NormalizationmethodDropDown.Value
                    case "int"
                        app.waveforms=normalize(app.waveforms,2,"norm",1);
                    case "range"
                        app.waveforms=normalize(app.waveforms,2,"range");
                    case "scale"
                        app.waveforms=normalize(app.waveforms,2,"scale");
                    case "L2"
                        app.waveforms=normalize(app.waveforms,2,"norm",2);
                    case "no"
                end
                cla(app.PWVCalcDisplay);
                app.PWVCalcDisplay.Visible='on';
                plot(app.PWVCalcDisplay,card_time,app.waveforms');
                app.PWVCalcDisplay.YLim = [min(app.waveforms,[],'all') max(app.waveforms,[],'all')];
                app.PWVCalcDisplay.XLim = [0 card_time(end)];
                app.PWVCalcDisplay.XLabel.String = 'Cardiac time (ms)';
                app.PWVCalcDisplay.YLabel.String = 'Blood flow (a.u.)';
                if app.numberofpoints>7
                    legend(app.PWVCalcDisplay,"off");
                end
                %average v_data with neighbors to get waves
                app.NumberofinvolvedpointsEditField.Value=app.numberofpoints;
                app.OptionsPanel.Visible='on';
                app.PWVforsplittingEditField.Visible='on';
                app.PWVforsplittingEditFieldLabel.Visible='on';
                app.CompareregularizationparametersButton.Visible='on';
                app.PerformsplittingonlyButton.Visible='on';
                app.PerformpulsewaveanalysisButton.Visible='on';
                app.ShowpointNrDropDown.Visible='on';
                app.ShowsumCheckBox.Visible='on';
                app.PWVDisplay.Visible='on';
                app.PWVinmsLabel.Visible='on';
                set(app.UIFigure,'Pointer','arrow');
                app.currentstep = 4;
                app.rho = [];
                app.reconstructed_waveforms = [];
                app.param = [];
                app.PWV = [];

            end
        end

        % Button pushed function: PerformpulsewaveanalysisButton
        function PerformpulsewaveanalysisButtonPushed(app, event)
            % grab PWV calc type: 1 is cross correlation, 2 is Wavelet, 3 is TTF, 4 is
            % maximum likelihood estimation, 5 is splitting
            % can be updated with update to calc_pwv
            if app.currentstep < 4
                errordlg("Perform step 4 first!")
            else
                set(app.UIFigure,'Pointer','watch');
                drawnow()
                switch app.PWVType.Value
                    case 'Cross-correlation'
                        PWVcalctype = 1;
                        if app.numberofpoints < 3
                            errordlg('Need at least 3 points for cross-correlation PWV calculation')
                            return;
                        end
                    case 'Wavelet'
                        PWVcalctype = 2;
                        if app.numberofpoints < 3
                            errordlg('Need at least 3 points for Wavelet PWV calculation')
                            return;
                        end
                    case 'PWVE via splitting'
                        PWVcalctype = 5;
                        if app.numberofpoints < 3
                            errordlg('Need at least 3 points for Splitting PWV calculation')
                            return;
                        end
                    case 'Maximum likelihood'
                        PWVcalctype = 4;
                    case 'TTF'
                        PWVcalctype = 3;
                        if app.numberofpoints < 3
                            errordlg('Need at least 3 points for cross-correlation PWV calculation')
                            return;
                        end
                end
                % total distance along centerline
                if PWVcalctype > 3 % max likelihood & splitting needs the 'zero' distance
                    dist_total = app.distances-app.distances(1);
                else
                    dist_temp = app.distances-app.distances(1);
                    dist_total = dist_temp(2:end);
                end
    
                if PWVcalctype < 4
    
                    % calculate PWV using the delay times using xcorrelation
                    [D,fitObject, ~, dist_total2] = calc_pwv(app.waveforms,dist_total,app.timeres,PWVcalctype);
                    % the PWV, 1/slope of fit
                    app.PWV = 1/fitObject(1);
    
                    y1 = polyval(fitObject,dist_total2,'r');
    
                    % plot and display slope
                    cla(app.PWVCalcDisplay)
                    scatter(app.PWVCalcDisplay,dist_total2,D,'.k','SizeData',75);
                    hold(app.PWVCalcDisplay,'on');
                    plot(app.PWVCalcDisplay,dist_total2,y1,'b','LineWidth',2);
                    legend(app.PWVCalcDisplay,'delays','linear fit','Location','Northwest')
                    app.PWVCalcDisplay.XLabel.String = 'distance (mm)';
                    switch PWVcalctype
                        case 1
                            str = 'cross-corr delay (ms)';
                            app.PWVCalcDisplay.YLim = [0 max(D)+1];
                        case 2
                            str = 'wavelet delay (ms)';
                            app.PWVCalcDisplay.YLim = [0 max(D)+1];
                        case 3
                            str = 'time delay (ms)';
                            app.PWVCalcDisplay.YLim = [0 max(D)+1];
                    end
                    app.PWVCalcDisplay.YLabel.String = str;
                    app.PWVDisplay.Visible='on';
                    app.PWVDisplay.Value = app.PWV;
                elseif PWVcalctype==4 % directly calculate PWV using maximum likelihood
                    %distance in meters
                    d = dist_total/1000;
    
                    % time res in seconds
                    tRes = app.timeres/1000;
                    
                    % to follow Anders/Cecilia code, convert to velocity
                    pwv0 = 10; %initial guess
                    vel=app.waveforms;
                    scaling = 1./std(vel(:,:),[],2);
                    vsc = vel.*repmat(scaling,1,app.cardiacFrames);
                    mean_flow = mean(vsc);
                    inParams=[randn(1,app.cardiacFrames), pwv0]; %
                    fun1=@(inParams)PWVest3_share(inParams,d,vsc,tRes,ones(length(d),1));
                    options = optimset('Display','iter', 'TolCon', 1e-7, 'TolX', 1e-7, 'TolFun', 1e-7,'DiffMinChange', 1e-3);
                    [params,exitflag,output] = fminunc(fun1,inParams, options);
    
                    app.PWV = params(end); % have a look at the PWV
    
                    card_time = [0:app.cardiacFrames-1]*app.timeres;
                    cla(app.PWVCalcDisplay)
                    plot(app.PWVCalcDisplay,card_time,mean_flow,'k','LineWidth',2);
                    hold(app.PWVCalcDisplay,'on');
                    plot(app.PWVCalcDisplay,card_time,params(1:end-1),'b','LineWidth',2);
                    app.PWVCalcDisplay.YLim = [min([params(1:end-1) mean_flow]) ...
                        max([params(1:end-1) mean_flow])];
                    legend(app.PWVCalcDisplay,'initial guess','final waveform','Location','Northeast')
                    app.PWVCalcDisplay.XLabel.String = 'Cardiac time (ms)';
                    app.PWVCalcDisplay.YLabel.String = 'Velocity wave (a.u.)';
                    app.PWVDisplay.Visible='on';
                    app.PWVDisplay.Value = app.PWV;
    
    %%   PWS              
                elseif PWVcalctype==5  % Splitting
                    
                    PWVs=1:14/(150-1):15;
                    app.param=paramSettingsAPP(app.timeres,app.cardiacFrames,app.numberofpoints,app.distances);
                    %smoothing-parameter exponent (Recommended: Fix as s=1 (Tikhonov) or s=0 (FISTA))
                    app.param.s=app.SmoothingparameterEditField.Value; 
                    % apriori parameter
                    app.param.alpha=app.RegularizationparameterEditField.Value;
                    %Computation
                    app.rho=getRealWave(app.waveforms,app.param);
                    app.param.showwaitbar=1;
                    switch app.MethodDropDown.Value
                        case 'FISTA'
                            [app.reconstructed_waveforms,~,data_error,app.PWV,success] = solveLinear_tikh_min(app.rho,PWVs,app.param,'FISTA');
                        case 'FISTA + bw cond'
                            [app.reconstructed_waveforms,~,data_error,app.PWV,success] = solveLinear_tikh_min(app.rho,PWVs,app.param,'FISTA + bw cond');
                        case 'Tikhonov'
                            [app.reconstructed_waveforms,~,data_error,app.PWV,success] = solveLinear_tikh_min(app.rho,PWVs,app.param,'tikh');
                        case 'Tikhonov + bw cond'
                            [app.reconstructed_waveforms,~,data_error,app.PWV,success] = solveLinear_tikh_min(app.rho,PWVs,app.param,'tikh + bw cond');
                    end
                    if success ==1
                        numval=1:app.numberofpoints;
                        app.ShowpointNrDropDown.Items=["data" string(numval)];
                        app.ShowpointNrDropDown.Value=app.ShowpointNrDropDown.Items(2);
                        fprintf("Relative data error value is %.8f\n",dataError(app.rho,app.reconstructed_waveforms,app.param.Nwaves));
                        %Display result
                        card_time = [0:app.cardiacFrames-1]*app.timeres;
                        cla(app.PWVCalcDisplay)
                        if app.ShowsumCheckBox.Value
                            plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)},'b','LineWidth',1.5);
                            hold(app.PWVCalcDisplay,'on');
                            plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)},'c','LineWidth',1.5);
                            plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.sum{str2num(app.ShowpointNrDropDown.Value)},'k','LineWidth',2);
                            plot(app.PWVCalcDisplay,card_time,app.rho.sum{str2num(app.ShowpointNrDropDown.Value)},'k--','LineWidth',1.5);
                            app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)};...
                                app.reconstructed_waveforms.sum{str2num(app.ShowpointNrDropDown.Value)};app.rho.sum{str2num(app.ShowpointNrDropDown.Value)}]) ...
                                max([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)};...
                                app.reconstructed_waveforms.sum{str2num(app.ShowpointNrDropDown.Value)};app.rho.sum{str2num(app.ShowpointNrDropDown.Value)}])];
                            app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                            legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Sum','Original','Location','Northeast')
                            app.PWVCalcDisplay.XLabel.String = 'Cardiac time (ms)';
                            app.PWVCalcDisplay.YLabel.String = 'Velocity wave (a.u.)';
                        else
                            plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)},'b','LineWidth',1.5);
                            hold(app.PWVCalcDisplay,'on');
                            %plot(app.PWVCalcDisplay,card_time,app.rho.sum{str2num(app.ShowpointNrDropDown.Value)},'k--','LineWidth',1.5);
                            plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)},'c','LineWidth',1.5);
                            app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)}]) ...
                                max([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)}])];
                            app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                            legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Location','Northeast')
                            app.PWVCalcDisplay.XLabel.String = 'Cardiac time (ms)';
                            app.PWVCalcDisplay.YLabel.String = 'Velocity wave (a.u.)';
                        end
                        cla(app.Display2);
                        app.Display2.Visible='off';
                        app.Display3.Visible='on';
                        cla(app.Display3)
                        semilogy(app.Display3,PWVs',data_error)
                        hold(app.Display3,'on');
                        view(app.Display3, 2);
                        app.Display3.XLim=[PWVs(1) PWVs(end)];
                        app.Display3.YLim=[min(data_error) max(data_error)];
                        daspect(app.Display3,'auto');
                        xline(app.Display3,app.PWV,'Color','green')
                        app.SaveresultsButton.Visible="on";
                    end
                    app.PWVDisplay.Visible='on';
                    app.PWVDisplay.Value = app.PWV;
                end
                set(app.UIFigure,'Pointer','arrow');
                app.currentstep = 5;
            end
        end

        % Button pushed function: CompareregularizationparametersButton
        function CompareregularizationparametersButtonPushed(app, event)
            %special option for splitting only: comparison of calculated PWV several
            %regularization parameters
            if app.currentstep < 4
                errordlg("Perform step 4 first!")
            else

                RegCompApp=parametertestoptions(app);
                set(app.UIFigure,'Pointer','watch');
                drawnow()
                if RegCompApp.cancelled == 0 && isvalid(RegCompApp)
                    RegularizationParameterSize=RegCompApp.totalofvaluesEditField.Value;
                    RegularizationParameterScale=RegCompApp.ScalingSwitch.Value;
                    RegularizationParameterMinValue=RegCompApp.minvalueEditField.Value;
                    RegularizationParameterMaxValue=RegCompApp.maxvalueEditField.Value;
                    delete(RegCompApp);
                    success = 0;
                    h=waitbar(0,'Performing parameter test...', 'CreateCancelBtn', 'setappdata(gcbf,''canceling'',1)');
                    setappdata(h, 'canceling', 0);
                    PWVs=1:14/(150-1):15;
                    app.param=paramSettingsAPP(app.timeres,app.cardiacFrames,app.numberofpoints,app.distances);
                    %smoothing-parameter exponent (Recommended: Fix as s=1 (Tikhonov) or s=0 (FISTA))
                    app.param.s=app.SmoothingparameterEditField.Value; 
                    % apriori parameter
                    s1='Lin';
                    if strcmp(s1,RegularizationParameterScale)
                        alpha=linspace(RegularizationParameterMinValue,RegularizationParameterMaxValue,RegularizationParameterSize);
                    else
                        alpha=logspace(log10(RegularizationParameterMinValue),log10(RegularizationParameterMaxValue),RegularizationParameterSize);
                    end
                    app.rho=getRealWave(app.waveforms,app.param);
                    app.param.showwaitbar=0;
                    for j=1:RegularizationParameterSize
                        if isvalid(h)
                            waitbar(j/double(RegularizationParameterSize),h,'Performing parameter test...');
                            if getappdata(h, 'canceling')
                                disp('User cancelled the operation.');
                                delete(h)
                                break;
                            end
                        end
                        app.param.alpha=alpha(j);
                        %Computation
                        switch app.MethodDropDown.Value
                            case 'FISTA'
                                [app.reconstructed_waveforms,~,data_error,pwv_rec(j)] = solveLinear_tikh_min(app.rho,PWVs,app.param,'FISTA');
                            case 'FISTA + bw cond'
                                [app.reconstructed_waveforms,~,data_error,pwv_rec(j)] = solveLinear_tikh_min(app.rho,PWVs,app.param,'FISTA + bw cond');
                            case 'Tikhonov'
                                [app.reconstructed_waveforms,~,data_error,pwv_rec(j)] = solveLinear_tikh_min(app.rho,PWVs,app.param,'tikh');
                            case 'Tikhonov + bw cond'
                                [app.reconstructed_waveforms,~,data_error,pwv_rec(j)] = solveLinear_tikh_min(app.rho,PWVs,app.param,'tikh + bw cond');
                        end
                        error_plot(j)=min(data_error);
                        if j == RegularizationParameterSize
                            success = 1;
                        end
                    end
                    if success == 1
                        % Plot results
                        figure
                        yyaxis left
                        ylabel('Computed PWV')
                        hold on
                        if strcmp(s1,RegularizationParameterScale)
                            plot(alpha,pwv_rec)
                            yyaxis right
                            ylabel('Data error')
                            plot(alpha,error_plot)
                        else
                            semilogx(alpha,pwv_rec)
                            yyaxis right
                            ylabel('data error')
                            semilogx(alpha,error_plot)
                        end
                        xlabel('Regularization parameter \alpha')
                        hold off
                        delete(h)
                    end
                end
                set(app.UIFigure,'Pointer','arrow');
            end
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            % set default values for each regularization method
            switch app.MethodDropDown.Value
                case 'FISTA'
                    app.SmoothingparameterEditField.Value=0;
                    app.RegularizationparameterEditField.Value=0.5;
                    app.infoButton.Visible='on';
                case 'FISTA + bw cond'
                    app.SmoothingparameterEditField.Value=0;
                    app.RegularizationparameterEditField.Value=0.5;
                    app.infoButton.Visible='on';
                case 'Tikhonov'
                    app.SmoothingparameterEditField.Value=1;
                    app.RegularizationparameterEditField.Value=0.001;
                    app.infoButton.Visible='off';
                case 'Tikhonov + bw cond'
                    app.SmoothingparameterEditField.Value=1;
                    app.RegularizationparameterEditField.Value=0.001;
                    app.infoButton.Visible='off';
            end    
        end

        % Button pushed function: PerformsplittingonlyButton
        function PerformsplittingonlyButtonPushed(app, event)
            % Perform pulse wave splitting only, for specified PWV. Result
            % is plotted
            if app.currentstep < 4
                errordlg("Perform step 2 first!")
            else 
                set(app.UIFigure,'Pointer','watch');
                drawnow()
                tRes = app.timeres/1000;
                PWVs = 1:9/(150-1):15;
                app.param=paramSettingsAPP(app.timeres,app.cardiacFrames,app.numberofpoints,app.distances);
                %smoothing-parameter exponent (Recommended: Fix as s=1 (Tikhonov) or s=0 (FISTA))
                app.param.s=app.SmoothingparameterEditField.Value; 
                % apriori parameter
                app.param.alpha=app.RegularizationparameterEditField.Value;
                %Computation
                app.rho=getRealWave(app.waveforms,app.param);
                app.param.tau=app.param.distance/app.param.effectivedT/app.PWVforsplittingEditField.Value;
                switch app.MethodDropDown.Value
                    case 'FISTA'
                        [app.reconstructed_waveforms,~] = solveLinear_FISTA(app.rho,app.param);
                    case 'FISTA + bw cond'
                        [app.reconstructed_waveforms,~] = solveLinear_FISTAbw(app.rho,app.param);
                    case 'Tikhonov'
                        [app.reconstructed_waveforms,~] = solveLinear_tikh(app.rho,app.param);
                    case 'Tikhonov + bw cond'
                        [app.reconstructed_waveforms,~] = solveLinear_tikh_GD(app.rho,app.param);
                end
                fprintf("Relative data error value is %.8f\n",dataError(app.rho,app.reconstructed_waveforms,app.param.Nwaves));
                %%Display result
                card_time = [0:app.cardiacFrames-1]*app.timeres;
                cla(app.PWVCalcDisplay)
                if app.ShowsumCheckBox.Value
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)},'b','LineWidth',1.5);
                    hold(app.PWVCalcDisplay,'on');
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)},'c','LineWidth',1.5);
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.sum{str2num(app.ShowpointNrDropDown.Value)},'k','LineWidth',2);
                    plot(app.PWVCalcDisplay,card_time,app.rho.sum{str2num(app.ShowpointNrDropDown.Value)},'k--','LineWidth',1.5);
                    app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)};...
                        app.reconstructed_waveforms.sum{str2num(app.ShowpointNrDropDown.Value)};app.rho.sum{str2num(app.ShowpointNrDropDown.Value)}]) ...
                        max([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)};...
                        app.reconstructed_waveforms.sum{str2num(app.ShowpointNrDropDown.Value)};app.rho.sum{str2num(app.ShowpointNrDropDown.Value)}])];
                    app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                    legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Sum','Original','Location','Northeast')
                    app.PWVCalcDisplay.XLabel.String = 'Cardiac time (ms)';
                    app.PWVCalcDisplay.YLabel.String = 'Velocity wave (a.u.)';
                else
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)},'b','LineWidth',1.5);
                    hold(app.PWVCalcDisplay,'on');
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)},'c','LineWidth',1.5);
                    app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)}]) ...
                        max([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)}])];
                    app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                    legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Location','Northeast')
                    app.PWVCalcDisplay.XLabel.String = 'Cardiac time (ms)';
                    app.PWVCalcDisplay.YLabel.String = 'Velocity wave (a.u.)';
                end
                set(app.UIFigure,'Pointer','arrow');
            end
        end

        % Value changed function: ShowpointNrDropDown
        function ShowpointNrDropDownValueChanged(app, event)
            % Display set of spilt waves in a certain spacial point (where
            % a wavefront was chosen)
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            showdata=strcmp(app.ShowpointNrDropDown.Value,"data");
            if isfield(app.reconstructed_waveforms,'for') && ~showdata
                card_time = [0:app.cardiacFrames-1]*app.timeres;
                cla(app.PWVCalcDisplay)
                if app.ShowsumCheckBox.Value
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)},'b','LineWidth',1.5);
                    hold(app.PWVCalcDisplay,'on');
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)},'c','LineWidth',1.5);
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.sum{str2num(app.ShowpointNrDropDown.Value)},'k','LineWidth',2);
                    plot(app.PWVCalcDisplay,card_time,app.rho.sum{str2num(app.ShowpointNrDropDown.Value)},'k--','LineWidth',1.5);
                    app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)};...
                        app.reconstructed_waveforms.sum{str2num(app.ShowpointNrDropDown.Value)};app.rho.sum{str2num(app.ShowpointNrDropDown.Value)}]) ...
                        max([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)};...
                        app.reconstructed_waveforms.sum{str2num(app.ShowpointNrDropDown.Value)};app.rho.sum{str2num(app.ShowpointNrDropDown.Value)}])];
                    app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                    legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Sum','Original','Location','Northeast')
                    app.PWVCalcDisplay.XLabel.String = 'Cardiac time (ms)';
                    app.PWVCalcDisplay.YLabel.String = 'Velocity wave (a.u.)';
                else
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)},'b','LineWidth',1.5);
                    hold(app.PWVCalcDisplay,'on');
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)},'c','LineWidth',1.5);
                    app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)}]) ...
                        max([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)}])];
                    app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                    legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Location','Northeast')
                    app.PWVCalcDisplay.XLabel.String = 'Cardiac time (ms)';
                    app.PWVCalcDisplay.YLabel.String = 'Velocity wave (a.u.)';
                end
            else
                card_time = [0:app.cardiacFrames-1]*app.timeres;
                cla(app.PWVCalcDisplay);
                app.PWVCalcDisplay.Visible='on';
                plot(app.PWVCalcDisplay,card_time,app.waveforms');
                app.PWVCalcDisplay.YLim = [min(app.waveforms,[],'all') max(app.waveforms,[],'all')];
                app.PWVCalcDisplay.XLim = [0 card_time(end)];
                app.PWVCalcDisplay.XLabel.String = 'Cardiac time (ms)';
                app.PWVCalcDisplay.YLabel.String = 'Blood flow (a.u.)';
                if app.numberofpoints>7
                    legend(app.PWVCalcDisplay,"off");
                end
            end
            set(app.UIFigure,'Pointer','arrow');
        end

        % Value changed function: ShowsumCheckBox
        function ShowsumCheckBoxValueChanged(app, event)
            %update result visualization
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            showdata=strcmp(app.ShowpointNrDropDown.Value,"data");
            if isfield(app.reconstructed_waveforms,'for')  &&  ~showdata
                card_time = [0:app.cardiacFrames-1]*app.timeres;
                cla(app.PWVCalcDisplay)
                if app.ShowsumCheckBox.Value
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)},'b','LineWidth',1.5);
                    hold(app.PWVCalcDisplay,'on');
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)},'c','LineWidth',1.5);
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.sum{str2num(app.ShowpointNrDropDown.Value)},'k','LineWidth',2);
                    plot(app.PWVCalcDisplay,card_time,app.rho.sum{str2num(app.ShowpointNrDropDown.Value)},'k--','LineWidth',1.5);
                    app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.sum{str2num(app.ShowpointNrDropDown.Value)}]) ...
                        max([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.sum{str2num(app.ShowpointNrDropDown.Value)}])];
                    app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                    legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Sum','Original','Location','Northeast')
                    app.PWVCalcDisplay.XLabel.String = 'Cardiac time (ms)';
                    app.PWVCalcDisplay.YLabel.String = 'Velocity wave (a.u.)';
                else
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)},'b','LineWidth',1.5);
                    hold(app.PWVCalcDisplay,'on');
                    plot(app.PWVCalcDisplay,card_time,app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)},'c','LineWidth',1.5);
                    app.PWVCalcDisplay.YLim = [min([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)}]) ...
                        max([app.reconstructed_waveforms.for{str2num(app.ShowpointNrDropDown.Value)};app.reconstructed_waveforms.back{str2num(app.ShowpointNrDropDown.Value)}])];
                    app.PWVCalcDisplay.XLim = [min(card_time) max(card_time)];
                    legend(app.PWVCalcDisplay,'Forward wave','Backward wave','Location','Northeast')
                    app.PWVCalcDisplay.XLabel.String = 'Cardiac time (ms)';
                    app.PWVCalcDisplay.YLabel.String = 'Velocity wave (a.u.)';
                end
            end
            set(app.UIFigure,'Pointer','arrow');
        end

        % Callback function
        function translateXplusPushed(app, event)
            %move data
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            temp_vsegmentation=zeros(size(app.vsegmentation));
            temp_v=zeros(size(app.v));
            temp_vsegmentation(:,3:end,:)=app.vsegmentation(:,1:(end-2),:);
            temp_v(:,3:end,:)=app.v(:,1:(end-2),:);
            app.vsegmentation=temp_vsegmentation;
            app.v=temp_v;
            %update figure
            reset3DSegmentationAndCenterline(app);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Callback function
        function translateXminusPushed(app, event)
            %move data
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            temp_vsegmentation=zeros(size(app.vsegmentation));
            temp_v=zeros(size(app.v));
            temp_vsegmentation(:,1:(end-2),:)=app.vsegmentation(:,3:end,:);
            temp_v(:,3:end,:)=app.v(:,1:(end-2),:);
            app.vsegmentation=temp_vsegmentation;
            app.v=temp_v;
            %update figure
            reset3DSegmentationAndCenterline(app);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Callback function
        function translateYplusButtonPushed(app, event)
            %move data
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            temp_vsegmentation=zeros(size(app.vsegmentation));
            temp_v=zeros(size(app.v));
            temp_vsegmentation(3:end,:,:)=app.vsegmentation(1:(end-2),:,:);
            temp_v(3:end,:,:)=app.v(1:(end-2),:,:);
            app.vsegmentation=temp_vsegmentation;
            app.v=temp_v;
            %update figure
            reset3DSegmentationAndCenterline(app);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Callback function
        function translateYminusButtonPushed(app, event)
            %move data
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            temp_vsegmentation=zeros(size(app.vsegmentation));
            temp_v=zeros(size(app.v));
            temp_vsegmentation(1:(end-2),:,:)=app.vsegmentation(3:end,:,:);
            temp_v(3:end,:,:)=app.v(1:(end-2),:,:);
            app.vsegmentation=temp_vsegmentation;
            app.v=temp_v;
            %update figure
            reset3DSegmentationAndCenterline(app);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Callback function
        function translateZplusButtonPushed(app, event)
            %move data
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            temp_vsegmentation=zeros(size(app.vsegmentation));
            temp_v=zeros(size(app.v));
            temp_vsegmentation(:,:,3:end)=app.vsegmentation(:,:,1:(end-2));
            temp_v(:,:,3:end)=app.v(:,:,1:(end-2));
            app.vsegmentation=temp_vsegmentation;
            app.v=temp_v;
            %update figure
            reset3DSegmentationAndCenterline(app);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Callback function
        function translateZminusButtonPushed(app, event)
            %move data
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            temp_vsegmentation=zeros(size(app.vsegmentation));
            temp_v=zeros(size(app.v));
            temp_vsegmentation(:,:,1:(end-2))=app.vsegmentation(:,:,3:end);
            temp_v(:,:,3:end)=app.v(:,:,1:(end-2));
            app.vsegmentation=temp_vsegmentation;
            app.v=temp_v;
            %update figure
            reset3DSegmentationAndCenterline(app);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Value changed function: PWVType
        function PWVTypeValueChanged(app, event)
            % when PWV-estimation via splitting is chosen, show additional
            % options
            value = app.PWVType.Value;
            splittingstring="PWVE via splitting";
            if strcmp(value,splittingstring)
                app.SmoothingparameterEditField.Visible="on";
                app.SmoothingparameterEditFieldLabel.Visible="on";
                app.MethodDropDown.Visible="on";
                app.MethodDropDownLabel.Visible="on";
                app.RegularizationparameterEditField.Visible="on";
                app.MethodDropDownLabel.Visible="on";
                app.PWVforsplittingEditField.Visible="on";
                app.PWVforsplittingEditFieldLabel.Visible="on";
                app.PerformsplittingonlyButton.Visible="on";
                app.CompareregularizationparametersButton.Visible="on";
                app.ShowpointNrDropDown.Visible="on";
                app.ShowpointNrDropDownLabel.Visible="on";
                app.ShowsumCheckBox.Visible="on";
                app.Display3.Visible="on";
                app.RegularizationparameterEditFieldLabel.Visible="on";
                app.infoButton.Visible="on";
                app.SaveresultsButton.Visible="on";
            else
                app.SmoothingparameterEditField.Visible="off";
                app.SmoothingparameterEditFieldLabel.Visible="off";
                app.MethodDropDown.Visible="off";
                app.MethodDropDownLabel.Visible="off";
                app.RegularizationparameterEditField.Visible="off";
                app.MethodDropDownLabel.Visible="off";
                app.PWVforsplittingEditField.Visible="off";
                app.PWVforsplittingEditFieldLabel.Visible="off";
                app.PerformsplittingonlyButton.Visible="off";
                app.CompareregularizationparametersButton.Visible="off";
                app.ShowpointNrDropDown.Visible="off";
                app.ShowpointNrDropDownLabel.Visible="off";
                app.ShowsumCheckBox.Visible="off";
                app.Display3.Visible="off";
                cla(app.Display3);
                app.RegularizationparameterEditFieldLabel.Visible="off";
                app.infoButton.Visible="off";
                app.SaveresultsButton.Visible="off";
            end
        end

        % Value changed function: XYButton
        function XYButtonValueChanged(app, event)
            %change view angle of right visualization plot
            value = app.XYButton.Value;
            if value==0
                app.XYButton.Value=1;
            else
                app.XZButton.Value=0;
                app.YZButton.Value=0;
                app.DButton.Value=0;
            end
            if app.XYButton.Value
                view(app.UIAxes2, [0 90]);
            elseif app.XZButton.Value
                view(app.UIAxes2, [0 0]);
            elseif app.YZButton.Value
                view(app.UIAxes2, [90 0]);
            elseif app.DButton.Value
                view(app.UIAxes2,3);
            end
        end

        % Value changed function: XZButton
        function XZButtonValueChanged(app, event)
            %change view angle of right visualization plot
            value = app.XZButton.Value;
            if value==0
                app.XZButton.Value=1;
            else
                app.XYButton.Value=0;
                app.YZButton.Value=0;
                app.DButton.Value=0;
            end
            if app.XYButton.Value
                view(app.UIAxes2, [0 90]);
            elseif app.XZButton.Value
                view(app.UIAxes2, [0 0]);
            elseif app.YZButton.Value
                view(app.UIAxes2, [90 0]);
            elseif app.DButton.Value
                view(app.UIAxes2,3);
            end
        end

        % Value changed function: YZButton
        function YZButtonValueChanged(app, event)
            %change view angle of right visualization plot
            value = app.YZButton.Value;
            if value==0
                app.YZButton.Value=1;
            else
                app.XYButton.Value=0;
                app.XZButton.Value=0;
                app.DButton.Value=0;
            end
            if app.XYButton.Value
                view(app.UIAxes2, [0 90]);
            elseif app.XZButton.Value
                view(app.UIAxes2, [0 0]);
            elseif app.YZButton.Value
                view(app.UIAxes2, [90 0]);
            elseif app.DButton.Value
                view(app.UIAxes2,3);
            end
        end

        % Value changed function: DButton
        function DButtonValueChanged(app, event)
            %change view angle of right visualization plot
            value = app.DButton.Value;
            if value==0
                app.DButton.Value=1;
            else
                app.XZButton.Value=0;
                app.YZButton.Value=0;
                app.XYButton.Value=0;
            end
            if app.XYButton.Value
                view(app.UIAxes2, [0 90]);
            elseif app.XZButton.Value
                view(app.UIAxes2, [0 0]);
            elseif app.YZButton.Value
                view(app.UIAxes2, [90 0]);
            elseif app.DButton.Value
                view(app.UIAxes2,3);
            end
        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
            %save results into .mat file
            if app.currentstep < 4
                errordlg("Perform step 4 first!")
            else
                set(app.UIFigure,'Pointer','watch');
                drawnow()
                % Get the current system time
                %currentTime = string(datetime('now','Format','yyyy_MM_dd_HH_mm_ss'));
                
                % Create the filename based on the current time
                %filename = strcat('PWA_saved_', currentTime, '.mat');
                
                % Save variables into the file
                reconstructed_waveforms=app.reconstructed_waveforms;
                rho=app.rho;
                distances=app.distances;
                param=app.param;
                PWV=app.PWV;
                [filename, pathname] = uiputfile('*.mat', 'Save MAT-file as');
                % Check if user did not cancel
                if filename ~= 0
                    fullpath = fullfile(pathname, filename);
                    save(fullpath, 'reconstructed_waveforms', 'rho', 'distances', 'param', 'PWV');
                    disp(['Variables saved to file: ' fullpath]);
                end                
                set(app.UIFigure,'Pointer','arrow');
            end
        end

        % Value changed function: XYButton_2
        function XYButton_2ValueChanged(app, event)
            %change view angle of left visualization plot
            value = app.XYButton_2.Value;
            if value==0
                app.XYButton_2.Value=1;
            else
                app.XZButton_2.Value=0;
                app.YZButton_2.Value=0;
                app.DButton_2.Value=0;
            end
            if app.XYButton_2.Value
                view(app.UIAxes, [0 90]);
            elseif app.XZButton_2.Value
                view(app.UIAxes, [0 0]);
            elseif app.YZButton_2.Value
                view(app.UIAxes, [90 0]);
            elseif app.DButton_2.Value
                view(app.UIAxes,3);
            end
        end

        % Value changed function: XZButton_2
        function XZButton_2ValueChanged(app, event)
            %change view angle of left visualization plot
            value = app.XZButton_2.Value;
            if value==0
                app.XZButton_2.Value=1;
            else
                app.XYButton_2.Value=0;
                app.YZButton_2.Value=0;
                app.DButton_2.Value=0;
            end
            if app.XYButton_2.Value
                view(app.UIAxes, [0 90]);
            elseif app.XZButton_2.Value
                view(app.UIAxes, [0 0]);
            elseif app.YZButton_2.Value
                view(app.UIAxes, [90 0]);
            elseif app.DButton_2.Value
                view(app.UIAxes,3);
            end
        end

        % Value changed function: YZButton_2
        function YZButton_2ValueChanged(app, event)
            %change view angle of left visualization plot
            value = app.YZButton_2.Value;
            if value==0
                app.YZButton_2.Value=1;
            else
                app.XYButton_2.Value=0;
                app.XZButton_2.Value=0;
                app.DButton_2.Value=0;
            end
            if app.XYButton_2.Value
                view(app.UIAxes, [0 90]);
            elseif app.XZButton_2.Value
                view(app.UIAxes, [0 0]);
            elseif app.YZButton_2.Value
                view(app.UIAxes, [90 0]);
            elseif app.DButton_2.Value
                view(app.UIAxes,3);
            end
        end

        % Value changed function: DButton_2
        function DButton_2ValueChanged(app, event)
            %change view angle of left visualization plot
            value = app.DButton_2.Value;
            if value==0
                app.DButton_2.Value=1;
            else
                app.XZButton_2.Value=0;
                app.YZButton_2.Value=0;
                app.XYButton_2.Value=0;
            end
            if app.XYButton_2.Value
                view(app.UIAxes, [0 90]);
            elseif app.XZButton_2.Value
                view(app.UIAxes, [0 0]);
            elseif app.YZButton_2.Value
                view(app.UIAxes, [90 0]);
            elseif app.DButton_2.Value
                view(app.UIAxes,3);
            end
        end

        % Button pushed function: LoadprevioussettingButton
        function LoadprevioussettingButtonPushed(app, event)
            %choose matlab-file to load setting from
            %h=waitbar(0,"Loading previous setting");
            set(app.UIFigure,'Pointer','watch');
            
            d = uiprogressdlg(app.UIFigure, 'Title','Processing', 'Message','Loading previous setting', 'Indeterminate','on');
            drawnow;
            % drawnow()
            % wbch = allchild(h);
            % jp = wbch(1).JavaPeer;
            % jp.setIndeterminate(1)

            [filename, pathname] = uigetfile('*.mat', 'Load setting from MAT-file');
            % Check if user did not cancel
            if filename ~= 0
                fullpath = fullfile(pathname, filename);
                load(fullpath, 'PWA_data');
                %set variables
                app.v = PWA_data.v;
                app.cardiacFrames = PWA_data.cardiacFrames;
                app.currentstep = PWA_data.currentstep;
                app.pixdim = PWA_data.pixdim;
                app.delays = PWA_data.delays;
                app.timeres = PWA_data.timeres;
                app.res = PWA_data.res;
                app.branchList = PWA_data.branchList;
                app.vsegmentation = PWA_data.vsegmentation;
                app.angiosegmentation = PWA_data.angiosegmentation;
                app.vPosition = PWA_data.vPosition;
                app.vOrientation = PWA_data.vOrientation;
                app.angioPosition = PWA_data.angioPosition;
                app.angioOrientation = PWA_data.angioOrientation;
                app.v_shifted = PWA_data.v_shifted;
                app.angiopixdim = PWA_data.angiopixdim;
                app.directionFlagAngio = PWA_data.directionFlagAngio;
                app.directionFlagVelocity = PWA_data.directionFlagVelocity;
                app.angio3d = PWA_data.angio3d;
                app.vangio3d = PWA_data.vangio3d;
                app.MAG = PWA_data.MAG;
                app.segmentationthresholdSlider.Value = PWA_data.segmentationthresholdSlider.Value;
                app.segmentationthresholdSlider_2.Value = PWA_data.segmentationthresholdSlider_2.Value;
                app.PWVpointList = PWA_data.PWVpointList;
                app.branches = PWA_data.branches;
                app.branchActual = PWA_data.branchActual;
                app.distances = PWA_data.distances;
                app.numberofpoints = PWA_data.numberofpoints;
                app.waveforms = PWA_data.waveforms;
                app.UnifycardiaccyclelengthviaDropDown.Value = PWA_data.UnifycardiaccyclelengthviaDropDown.Value;
                app.NormalizationmethodDropDown.Value = PWA_data.NormalizationmethodDropDown.Value;
                app.NumberofneighborsforaveragingwaveformsDropDown.Value = PWA_data.NumberofneighborsforaveragingwaveformsDropDown.Value;
                app.LoadangioCheckBox.Value = PWA_data.LoadangioCheckBox.Value;
                app.flip1 = PWA_data.flip1;
                app.flip2 = PWA_data.flip2;
                app.flip3 = PWA_data.flip3;
                app.flip4 = PWA_data.flip4;
                app.ComputewaveformsButton.Visible='on';
                app.UnifycardiaccyclelengthviaDropDown.Visible='on';
                app.UnifycardiaccyclelengthviaDropDownLabel.Visible='on';
                app.NormalizationmethodDropDown.Visible='on';
                app.NormalizationmethodDropDownLabel.Visible='on';
                app.NumberofneighborsforaveragingwaveformsDropDown.Visible='on';
                app.NumberofneighborsforaveragingwaveformsDropDownLabel.Visible='on';
                app.SavesettingButton.Visible='on';
                app.CropvelocityButton.Visible='on';
                value = app.LoadangioCheckBox.Value;
                if value==1 
                    app.LoadangiodataButton.Visible='on';
                    app.segmentationthresholdSlider_2.Visible='on';
                    app.EditField_2.Visible = 'on';
                    app.segmentationthresholdSlider_2Label.Visible='on';
                    app.CropangioButton.Visible='on';
                    app.angiochangedflag=1;
                else 
                    app.LoadangiodataButton.Visible='off';
                    app.segmentationthresholdSlider_2.Visible='off';
                    app.EditField_2.Visible = 'off';
                    app.segmentationthresholdSlider_2Label.Visible='off';
                    app.CropangioButton.Visible='off';
                end
                %update visualization
                Update3DSegmentation(app);
                set(app.UIFigure,'Pointer','watch');
                drawnow()   
                make_movie(app);            
                reset3DSegmentationAndCenterline(app);
                set(app.UIFigure,'Pointer','watch');
                drawnow()
                app.centerline_performed=1;
                app.data_loaded=1;
                if app.LoadangioCheckBox.Value
                    [~,v_ind]=shift_position_affine(app.PWVpointList(:,1:3),app.angioPosition,app.angioOrientation,app.angiopixdim, ...
                        size(app.vsegmentation), app.vPosition,app.vOrientation,app.pixdim,app.directionFlagAngio,app.directionFlagVelocity,"nointerpolation","nocutoff");
                    [~,branch_ind]=shift_position_affine(app.branchActual,app.angioPosition,app.angioOrientation,app.angiopixdim, ...
                        size(app.vsegmentation), app.vPosition,app.vOrientation,app.pixdim,app.directionFlagAngio,app.directionFlagVelocity,"nointerpolation","nocutoff");
                else
                    v_ind=app.PWVpointList(:,1:3);
                    branch_ind=app.branchActual;
                end
                hline1 = line(app.UIAxes,branch_ind(:,2),branch_ind(:,1),branch_ind(:,3),...
                        'Marker','none','LineStyle','-','LineWidth', 1.5);
                c = lines(app.numberofpoints);
                for i = 1:app.numberofpoints
                    hline2 = line(app.UIAxes,v_ind(i,2),v_ind(i,1),v_ind(i,3),...
                    'Marker','.','MarkerSize',30,'Color',c(i,:),'LineStyle','none');
                    if app.numberofpoints<15
                    Ntxt = text(app.UIAxes,v_ind(i,2)-2,v_ind(i,1)+2,v_ind(i,3)-2,...
                    num2str(i),'FontSize',14,'FontWeight', 'bold','Margin', 1,...
                    'HitTest','off','PickableParts','none');
                    end
                end
                app.NumberofinvolvedpointsEditField.Value=app.numberofpoints;
                ComputewaveformsButtonPushed(app)
                set(app.UIFigure,'Pointer','watch');
                drawnow()   
                app.ScanInfoTable.Data = cat(2,cellstr([num2str(app.res(1)) ' x ' num2str(app.res(2)) ' x ' num2str(app.res(3))]),...
                        cellstr([num2str(round(app.pixdim(1),1)) ' x ' num2str(round(app.pixdim(2),1)) ' x ' num2str(round(app.pixdim(3),1))]),...
                        cellstr(num2str(app.timeres)),cellstr(num2str(app.cardiacFrames)));
            end
            %delete(h);
            delete(d);
            set(app.UIFigure,'Pointer','arrow');
        end

        % Button pushed function: SavesettingButton
        function SavesettingButtonPushed(app, event)
            % save variables of current setting into .mat file
            if app.currentstep < 4
                errordlg("Perform step 4 first!")
            else
                set(app.UIFigure,'Pointer','watch');
                drawnow()
                %save the needed variables
                PWA_data.v = app.v;
                PWA_data.currentstep = app.currentstep;
                PWA_data.cardiacFrames = app.cardiacFrames;
                PWA_data.pixdim = app.pixdim;
                PWA_data.delays = app.delays;
                PWA_data.timeres = app.timeres;
                PWA_data.res = app.res;
                PWA_data.branchList = app.branchList;
                PWA_data.vsegmentation = app.vsegmentation;
                PWA_data.angiosegmentation = app.angiosegmentation;
                PWA_data.vPosition = app.vPosition;
                PWA_data.vOrientation = app.vOrientation;
                PWA_data.angioPosition = app.angioPosition;
                PWA_data.angioOrientation = app.angioOrientation;
                PWA_data.v_shifted = app.v_shifted;
                PWA_data.angiopixdim = app.angiopixdim;
                PWA_data.directionFlagAngio = app.directionFlagAngio;
                PWA_data.directionFlagVelocity = app.directionFlagVelocity;
                PWA_data.angio3d = app.angio3d;
                PWA_data.vangio3d = app.vangio3d;
                PWA_data.MAG = app.MAG;
                PWA_data.segmentationthresholdSlider.Value = app.segmentationthresholdSlider.Value;
                PWA_data.segmentationthresholdSlider_2.Value = app.segmentationthresholdSlider_2.Value;
                PWA_data.PWVpointList = app.PWVpointList;
                PWA_data.branches = app.branches;
                PWA_data.branchActual = app.branchActual;
                PWA_data.distances = app.distances;
                PWA_data.numberofpoints = app.numberofpoints;
                PWA_data.waveforms = app.waveforms;
                PWA_data.UnifycardiaccyclelengthviaDropDown.Value = app.UnifycardiaccyclelengthviaDropDown.Value;
                PWA_data.NormalizationmethodDropDown.Value = app.NormalizationmethodDropDown.Value;
                PWA_data.NumberofneighborsforaveragingwaveformsDropDown.Value = app.NumberofneighborsforaveragingwaveformsDropDown.Value;
                PWA_data.LoadangioCheckBox.Value = app.LoadangioCheckBox.Value;
                PWA_data.flip1 = app.flip1;
                PWA_data.flip2 = app.flip2;
                PWA_data.flip3 = app.flip3;
                PWA_data.flip4 = app.flip4;
                [filename, pathname] = uiputfile('*.mat', 'Save MAT-file as');
                % Check if user did not cancel
                if filename ~= 0
                    fullpath = fullfile(pathname, filename);
                    save(fullpath, 'PWA_data');
                end
                set(app.UIFigure,'Pointer','arrow');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.segmentationthresholdSlider.Value = app.EditField.Value;
            app.segmentationthresholdSliderValueChanged();            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.segmentationthresholdSlider_2.Value = app.EditField_2.Value;
            app.segmentationthresholdSlider_2ValueChanged();   
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {704, 704};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {469, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '__________________________________________________________________';
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = ' ';
            app.LoadangiodataButton = uibutton(app.Panel, 'push');
            app.LoadangiodataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadangiodataButtonPushed, true);
            app.LoadangiodataButton.Visible = 'off';
            app.LoadangiodataButton.Text = 'Load angio data';
            app.segmentationthresholdSlider_2Label = uilabel(app.Panel);
            app.segmentationthresholdSlider_2Label.Visible = 'off';
            app.segmentationthresholdSlider_2Label.Text = {'segmentation'; ' threshold'};
            app.segmentationthresholdSlider_2 = uislider(app.Panel);
            app.segmentationthresholdSlider_2.Limits = [0 1];
            app.segmentationthresholdSlider_2.MajorTicks = [0 1];
            app.segmentationthresholdSlider_2.ValueChangedFcn = createCallbackFcn(app, @segmentationthresholdSlider_2ValueChanged, true);
            app.segmentationthresholdSlider_2.Visible = 'off';
            app.segmentationthresholdSlider_2.Value = 0.35;
            app.CropangioButton = uibutton(app.Panel, 'push');
            app.CropangioButton.ButtonPushedFcn = createCallbackFcn(app, @CropangioButtonPushed, true);
            app.CropangioButton.Visible = 'off';
            app.CropangioButton.Text = 'Crop angio';
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Visible = 'off';
            app.LoadvelocitydataPanel = uipanel(app.LeftPanel);
            app.LoadvelocitydataPanel.Title = 'Load velocity data';
            app.LoadvelocitydataButton = uibutton(app.LoadvelocitydataPanel, 'push');
            app.LoadvelocitydataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvelocitydataButtonPushed, true);
            app.LoadvelocitydataButton.Text = 'Load velocity data';
            app.EditField = uieditfield(app.LoadvelocitydataPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.LoadangioCheckBox = uicheckbox(app.LeftPanel);
            app.LoadangioCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadangioCheckBoxValueChanged, true);
            app.LoadangioCheckBox.Text = 'Load angio';
            app.PWAppLabel = uilabel(app.LeftPanel);
            app.PWAppLabel.Text = 'PWApp';
            app.SelectdatapointsforPWVestimationButton = uibutton(app.LeftPanel, 'push');
            app.SelectdatapointsforPWVestimationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdatapointsforPWVestimationButtonPushed, true);
            app.SelectdatapointsforPWVestimationButton.Text = 'Select datapoints for PWV estimation';
            app.RestartButton = uibutton(app.LeftPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.PerformpulsewaveanalysisButton = uibutton(app.LeftPanel, 'push');
            app.PerformpulsewaveanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformpulsewaveanalysisButtonPushed, true);
            app.PerformpulsewaveanalysisButton.Visible = 'off';
            app.PerformpulsewaveanalysisButton.Text = 'Perform pulsewave analysis';
            app.ScanInfoTable = uitable(app.LeftPanel);
            app.ScanInfoTable.ColumnName = {'Matrix'; 'Resolution (mm)'; 'Time resolution (ms)'; 'Cardiac frames'};
            app.ScanInfoTable.RowName = {};
            app.ScanInfoTable.ColumnSortable = [false false false false];
            app.ScanInfoTable.ColumnEditable = [false false false false];
            app.CropvelocityButton = uibutton(app.LeftPanel, 'push');
            app.CropvelocityButton.ButtonPushedFcn = createCallbackFcn(app, @CropvelocityButtonPushed, true);
            app.CropvelocityButton.Visible = 'off';
            app.CropvelocityButton.Text = 'Crop velocity';
            app.OptionsPanel = uipanel(app.LeftPanel);
            app.OptionsPanel.Title = 'Options';
            app.OptionsPanel.Visible = 'off';
            app.PWVType = uidropdown(app.OptionsPanel);
            app.PWVType.Items = {'Cross-correlation', 'TTF', 'Wavelet', 'Maximum likelihood', 'PWVE via splitting'};
            app.PWVType.ValueChangedFcn = createCallbackFcn(app, @PWVTypeValueChanged, true);
            app.PWVType.Tooltip = {'long computation times possible when bw cond is included'};
            app.PWVType.Value = 'PWVE via splitting';
            app.MethodDropDownLabel = uilabel(app.OptionsPanel);
            app.MethodDropDownLabel.Text = ' Method';
            app.MethodDropDown = uidropdown(app.OptionsPanel);
            app.MethodDropDown.Items = {'Tikhonov', 'Tikhonov + bw cond', 'FISTA', 'FISTA + bw cond'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Tikhonov';
            app.RegularizationparameterEditFieldLabel = uilabel(app.OptionsPanel);
            app.RegularizationparameterEditFieldLabel.Text = 'Regularization parameter ';
            app.RegularizationparameterEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.RegularizationparameterEditField.Limits = [0 Inf];
            app.RegularizationparameterEditField.Value = 0.001;
            app.SmoothingparameterEditFieldLabel = uilabel(app.OptionsPanel);
            app.SmoothingparameterEditFieldLabel.Text = 'Smoothing parameter ';
            app.SmoothingparameterEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.SmoothingparameterEditField.Limits = [0 Inf];
            app.SmoothingparameterEditField.Value = 2;
            app.infoButton = uibutton(app.OptionsPanel, 'push');
            app.infoButton.Icon = fullfile(pathToMLAPP, 'utilities', 'infoicon.jpg');
            app.infoButton.Visible = 'off';
            app.infoButton.Tooltip = {'FISTA uses hard thresholding. Regularization parameter is the percentage of cut off frequencies'};
            app.infoButton.Text = '';
            app.PerformsplittingonlyButton = uibutton(app.LeftPanel, 'push');
            app.PerformsplittingonlyButton.ButtonPushedFcn = createCallbackFcn(app, @PerformsplittingonlyButtonPushed, true);
            app.PerformsplittingonlyButton.Visible = 'off';
            app.PerformsplittingonlyButton.Text = 'Perform splitting only';
            app.CompareregularizationparametersButton = uibutton(app.LeftPanel, 'push');
            app.CompareregularizationparametersButton.ButtonPushedFcn = createCallbackFcn(app, @CompareregularizationparametersButtonPushed, true);
            app.CompareregularizationparametersButton.Visible = 'off';
            app.CompareregularizationparametersButton.Text = 'Compare regularization parameters';
            app.PWVforsplittingEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PWVforsplittingEditField.Limits = [0.1 Inf];
            app.PWVforsplittingEditField.Visible = 'off';
            app.PWVforsplittingEditField.Value = 1;
            app.PWVforsplittingEditFieldLabel = uilabel(app.LeftPanel);
            app.PWVforsplittingEditFieldLabel.Visible = 'off';
            app.PWVforsplittingEditFieldLabel.Text = 'PWV for splitting';
            app.ComputewaveformsButton = uibutton(app.LeftPanel, 'push');
            app.ComputewaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputewaveformsButtonPushed, true);
            app.ComputewaveformsButton.Visible = 'off';
            app.ComputewaveformsButton.Text = {'Compute'; 'waveforms'};
            app.NumberofinvolvedpointsforsplittingLabel = uilabel(app.LeftPanel);
            app.NumberofinvolvedpointsforsplittingLabel.Text = 'Number of involved points:';
            app.NumberofinvolvedpointsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofinvolvedpointsEditField.Limits = [0 Inf];
            app.NumberofinvolvedpointsEditField.RoundFractionalValues = 'on';
            app.NumberofinvolvedpointsEditField.Editable = 'off';
            app.NumberofneighborsforaveragingwaveformsDropDownLabel = uilabel(app.LeftPanel);
            app.NumberofneighborsforaveragingwaveformsDropDownLabel.Visible = 'off';
            app.NumberofneighborsforaveragingwaveformsDropDownLabel.Text = 'Number of neighbors for averaging waveforms';
            app.NumberofneighborsforaveragingwaveformsDropDown = uidropdown(app.LeftPanel);
            app.NumberofneighborsforaveragingwaveformsDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumberofneighborsforaveragingwaveformsDropDown.Visible = 'off';
            app.NumberofneighborsforaveragingwaveformsDropDown.Value = '4';
            app.NormalizationmethodDropDownLabel = uilabel(app.LeftPanel);
            app.NormalizationmethodDropDownLabel.Visible = 'off';
            app.NormalizationmethodDropDownLabel.Text = 'Normalization method';
            app.NormalizationmethodDropDown = uidropdown(app.LeftPanel);
            app.NormalizationmethodDropDown.Items = {'int', 'range', 'scale', 'L2'};
            app.NormalizationmethodDropDown.Visible = 'off';
            app.NormalizationmethodDropDown.Value = 'int';
            app.UnifycardiaccyclelengthviaDropDownLabel = uilabel(app.LeftPanel);
            app.UnifycardiaccyclelengthviaDropDownLabel.Visible = 'off';
            app.UnifycardiaccyclelengthviaDropDownLabel.Text = 'Unify cardiac cycle length via';
            app.UnifycardiaccyclelengthviaDropDown = uidropdown(app.LeftPanel);
            app.UnifycardiaccyclelengthviaDropDown.Items = {'cutoff', 'average'};
            app.UnifycardiaccyclelengthviaDropDown.Visible = 'off';
            app.UnifycardiaccyclelengthviaDropDown.Value = 'cutoff';
            app.TotaldistanceincmEditFieldLabel = uilabel(app.LeftPanel);
            app.TotaldistanceincmEditFieldLabel.Text = 'Total distance in cm';
            app.TotaldistanceincmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TotaldistanceincmEditField.Editable = 'off';
            app.Step3Label = uilabel(app.LeftPanel);
            app.Step3Label.Text = 'Step 3:';
            app.Step1LoaddataLabel = uilabel(app.LeftPanel);
            app.Step1LoaddataLabel.Text = 'Step 1: Load data';
            app.Step4ComputewaveformsLabel = uilabel(app.LeftPanel);
            app.Step4ComputewaveformsLabel.Text = 'Step 4: Compute waveforms';
            app.Step5PerformPWALabel = uilabel(app.LeftPanel);
            app.Step5PerformPWALabel.Text = 'Step 5: Perform PWA';
            app.LoadprevioussettingButton = uibutton(app.LeftPanel, 'push');
            app.LoadprevioussettingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadprevioussettingButtonPushed, true);
            app.LoadprevioussettingButton.Text = 'Load previous setting';
            app.SavesettingButton = uibutton(app.LeftPanel, 'push');
            app.SavesettingButton.ButtonPushedFcn = createCallbackFcn(app, @SavesettingButtonPushed, true);
            app.SavesettingButton.Visible = 'off';
            app.SavesettingButton.Text = 'Save setting';
            app.CreatecenterlineBranchesButton = uibutton(app.LeftPanel, 'push');
            app.CreatecenterlineBranchesButton.ButtonPushedFcn = createCallbackFcn(app, @CreatecenterlineBranchesButtonPushed, true);
            app.CreatecenterlineBranchesButton.Text = 'Create centerline & Branches';
            app.Step2Label = uilabel(app.LeftPanel);
            app.Step2Label.Text = 'Step 2: ';
            app.segmentationthresholdLabel = uilabel(app.LeftPanel);
            app.segmentationthresholdLabel.Text = {'segmentation'; 'threshold'};
            app.segmentationthresholdSlider = uislider(app.LeftPanel);
            app.segmentationthresholdSlider.Limits = [0 1];
            app.segmentationthresholdSlider.MajorTicks = [0 1];
            app.segmentationthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @segmentationthresholdSliderValueChanged, true);
            app.segmentationthresholdSlider.Value = 0.35;
            app.MinBranchlengthmmSpinnerLabel = uilabel(app.LeftPanel);
            app.MinBranchlengthmmSpinnerLabel.Text = 'Min. Branchlength [mm]';
            app.MinBranchlengthmmSpinner = uispinner(app.LeftPanel);
            app.MinBranchlengthmmSpinner.Value = 15;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Velocity visualization')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.PWVCalcDisplay = uiaxes(app.RightPanel);
            xlabel(app.PWVCalcDisplay, 'Cardiac phase')
            ylabel(app.PWVCalcDisplay, 'Blood flow [a.u.]')
            zlabel(app.PWVCalcDisplay, 'Z')
            app.PWVCalcDisplay.Visible = 'off';
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Branch visualization')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.Display2 = uiaxes(app.RightPanel);
            title(app.Display2, 'Artery segment')
            xlabel(app.Display2, 'X')
            ylabel(app.Display2, 'Y')
            zlabel(app.Display2, 'Z')
            app.Display2.Visible = 'off';
            app.Display3 = uiaxes(app.RightPanel);
            xlabel(app.Display3, 'PWV')
            ylabel(app.Display3, 'Error')
            zlabel(app.Display3, 'Z')
            app.Display3.Visible = 'off';
            app.PWVDisplay = uieditfield(app.RightPanel, 'numeric');
            app.PWVDisplay.Editable = 'off';
            app.PWVDisplay.Visible = 'off';
            app.ShowpointNrDropDownLabel = uilabel(app.RightPanel);
            app.ShowpointNrDropDownLabel.Visible = 'off';
            app.ShowpointNrDropDownLabel.Text = 'Show point Nr';
            app.ShowpointNrDropDown = uidropdown(app.RightPanel);
            app.ShowpointNrDropDown.Items = {'Data'};
            app.ShowpointNrDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowpointNrDropDownValueChanged, true);
            app.ShowpointNrDropDown.Visible = 'off';
            app.ShowpointNrDropDown.Value = 'Data';
            app.ShowsumCheckBox = uicheckbox(app.RightPanel);
            app.ShowsumCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowsumCheckBoxValueChanged, true);
            app.ShowsumCheckBox.Visible = 'off';
            app.ShowsumCheckBox.Text = 'Show sum';
            app.ShowsumCheckBox.Value = true;
            app.PWVinmsLabel = uilabel(app.RightPanel);
            app.PWVinmsLabel.Visible = 'off';
            app.PWVinmsLabel.Text = 'PWV in m/s';
            app.ViewLabel = uilabel(app.RightPanel);
            app.ViewLabel.Visible = 'off';
            app.ViewLabel.Text = 'View:';
            app.YZButton = uibutton(app.RightPanel, 'state');
            app.YZButton.ValueChangedFcn = createCallbackFcn(app, @YZButtonValueChanged, true);
            app.YZButton.Visible = 'off';
            app.YZButton.Text = 'YZ';
            app.XZButton = uibutton(app.RightPanel, 'state');
            app.XZButton.ValueChangedFcn = createCallbackFcn(app, @XZButtonValueChanged, true);
            app.XZButton.Visible = 'off';
            app.XZButton.Text = 'XZ';
            app.XYButton = uibutton(app.RightPanel, 'state');
            app.XYButton.ValueChangedFcn = createCallbackFcn(app, @XYButtonValueChanged, true);
            app.XYButton.Visible = 'off';
            app.XYButton.Text = 'XY';
            app.XYButton.Value = true;
            app.SaveresultsButton = uibutton(app.RightPanel, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Visible = 'off';
            app.SaveresultsButton.Text = 'Save results';
            app.DButton = uibutton(app.RightPanel, 'state');
            app.DButton.ValueChangedFcn = createCallbackFcn(app, @DButtonValueChanged, true);
            app.DButton.Visible = 'off';
            app.DButton.Text = '3D';
            app.ViewLabel_2 = uilabel(app.RightPanel);
            app.ViewLabel_2.Visible = 'off';
            app.ViewLabel_2.Text = 'View:';
            app.YZButton_2 = uibutton(app.RightPanel, 'state');
            app.YZButton_2.ValueChangedFcn = createCallbackFcn(app, @YZButton_2ValueChanged, true);
            app.YZButton_2.Visible = 'off';
            app.YZButton_2.Text = 'YZ';
            app.XZButton_2 = uibutton(app.RightPanel, 'state');
            app.XZButton_2.ValueChangedFcn = createCallbackFcn(app, @XZButton_2ValueChanged, true);
            app.XZButton_2.Visible = 'off';
            app.XZButton_2.Text = 'XZ';
            app.XYButton_2 = uibutton(app.RightPanel, 'state');
            app.XYButton_2.ValueChangedFcn = createCallbackFcn(app, @XYButton_2ValueChanged, true);
            app.XYButton_2.Visible = 'off';
            app.XYButton_2.Text = 'XY';
            app.XYButton_2.Value = true;
            app.DButton_2 = uibutton(app.RightPanel, 'state');
            app.DButton_2.ValueChangedFcn = createCallbackFcn(app, @DButton_2ValueChanged, true);
            app.DButton_2.Visible = 'off';
            app.DButton_2.Text = '3D';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PWApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end