classdef crop_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CropPanel             matlab.ui.container.Panel
        OKButton              matlab.ui.control.Button
        CropButton_3          matlab.ui.control.Button
        CropButton_2          matlab.ui.control.Button
        CropButton            matlab.ui.control.Button
        ZrangeEditField       matlab.ui.control.EditField
        ZrangeEditFieldLabel  matlab.ui.control.Label
        toZEditField          matlab.ui.control.EditField
        toZEditFieldLabel     matlab.ui.control.Label
        YrangeEditField       matlab.ui.control.EditField
        YrangeEditFieldLabel  matlab.ui.control.Label
        toYEditField          matlab.ui.control.EditField
        toYEditFieldLabel     matlab.ui.control.Label
        toXEditField          matlab.ui.control.EditField
        toXEditFieldLabel     matlab.ui.control.Label
        XrangeEditField       matlab.ui.control.EditField
        XrangeEditFieldLabel  matlab.ui.control.Label
        AxesZ                 matlab.ui.control.UIAxes
        AxesY                 matlab.ui.control.UIAxes
        AxesX                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Callingapp % PWApp that calls the crop_app
        mask
        angio
    end
    
    methods (Access = private)
        
        function updateMIPs(app, m_xstart, m_xstop, m_ystart, m_ystop, m_zstart, m_zstop)
            % update range of MIPs in edit fields
            app.XrangeEditField.Value = num2str(m_xstart);
            app.toXEditField.Value = num2str(m_xstop);

            app.YrangeEditField.Value = num2str(m_ystart);
            app.toYEditField.Value = num2str(m_ystop);

            app.ZrangeEditField.Value = num2str(m_zstart);
            app.toZEditField.Value = num2str(m_zstop);

            % update images with set range
            cla(app.AxesX);
            imagesc(app.AxesX,reshape(max(app.angio,[],1),[size(app.angio,2) size(app.angio,3)]));
            set(app.AxesX, 'YDir', 'normal');
            set(app.AxesX,'XTickLabel','','YTickLabel','')
            colormap(app.AxesX,'gray')
            axis(app.AxesX,'equal')
            daspect(app.AxesX,[1 1 1]);
            disableDefaultInteractivity(app.AxesX); 

            cla(app.AxesY);
            imagesc(app.AxesY,reshape(max(app.angio,[],2),[size(app.angio,1) size(app.angio,3)]));
            set(app.AxesY, 'YDir', 'normal');
            set(app.AxesY,'XTickLabel','','YTickLabel','')
            colormap(app.AxesY,'gray')
            axis(app.AxesY,'equal')
            daspect(app.AxesY,[1 1 1]);
            disableDefaultInteractivity(app.AxesY); 

            cla(app.AxesZ);
            imagesc(app.AxesZ,reshape(max(app.angio,[],3),[size(app.angio,1) size(app.angio,2)]));
            set(app.AxesZ, 'YDir', 'normal');
            set(app.AxesZ,'XTickLabel','','YTickLabel','')
            colormap(app.AxesZ,'gray')
            axis(app.AxesZ,'equal')
            daspect(app.AxesZ,[1 1 1]);
            disableDefaultInteractivity(app.AxesZ); 
        end

        function maskSz = cropImage(app,img)
            % crop img via drawing a rectangle 
            choice = 0;
            while choice == 0
                cropFig = figure(100);
                set(cropFig,'Units','normalized');
                set(cropFig,'Position',[0.0016 0.0481 0.4969 0.8454])
                set(cropFig,'Name','Draw rectangle to crop image')

                % View MIP
                imagesc(img);
                set(gca, 'YDir', 'normal');
                colormap('gray')
                axis equal off
                daspect([1 1 1]);
                disableDefaultInteractivity(gca); 

                % range of cropped image is border of drawed rectangle
                h = drawrectangle(gca,'DrawingArea',[1 1 size(img,2)-1 size(img,1)-1]);
                maskSz = h.Position;
                maskSz(1:2) = floor(maskSz(1:2));
                maskSz(3:4) = ceil(maskSz(3:4));
                maskSz(maskSz<1) = 1;
                mask = zeros(size(img));
                mask(maskSz(2):(maskSz(2)+maskSz(4)), maskSz(1):(maskSz(1)+maskSz(3))) = 1;
                imgCropped = img.*mask;

                clf(cropFig);
                imagesc(imgCropped);
                set(gca, 'YDir', 'normal');
                colormap('gray')
                axis equal off
                daspect([1 1 1]);
                set(cropFig,'Name','Cropped image')
                choice = checkCrop;

            end
            % if cancel, reset the mask and img
            if choice == 2
                maskSz = [1 1 size(img,2)-1 size(img,1)-1];
            end
            close(cropFig)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, angio)
            app.Callingapp=caller;
            % initialize the mask
            app.mask = ones(size(angio));
            app.angio=angio;
            app.Callingapp.publicangio=app.angio;
            updateMIPs(app, 1, size(angio,1), 1, size(angio,2), 1, size(angio,3));
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            % Crop yz-image
            img = app.AxesX.Children(length(app.AxesX.Children),1).CData;
            maskSz = cropImage(app,img);
            m_xstart = str2double(app.XrangeEditField.Value);
            m_xstop = str2double(app.toXEditField.Value);
            m_ystart = maskSz(2);m_ystop = maskSz(2)+maskSz(4);
            m_zstart = maskSz(1);m_zstop = maskSz(1)+maskSz(3);

            tempMask = zeros(size(img));tempMask(m_ystart:m_ystop,m_zstart:m_zstop) = 1;
            app.mask = app.mask.*repmat(permute(tempMask,[3 1 2]),[size(app.mask,1) 1 1]);

            % update angio
            app.angio = app.angio.*app.mask;
            updateMIPs(app, m_xstart, m_xstop, m_ystart, m_ystop, m_zstart, m_zstop);

        end

        % Button pushed function: CropButton_2
        function CropButton_2Pushed(app, event)
            % Crop xz-image
            img = app.AxesY.Children(length(app.AxesY.Children),1).CData;
            maskSz = cropImage(app,img);

            m_xstart = maskSz(2);m_xstop = maskSz(2)+maskSz(4);
            m_ystart = str2double(app.YrangeEditField.Value);
            m_ystop = str2double(app.toYEditField.Value);
            m_zstart = maskSz(1);m_zstop = maskSz(1)+maskSz(3);

            tempMask = zeros(size(img));tempMask(m_xstart:m_xstop,m_zstart:m_zstop) = 1;
            app.mask = app.mask.*repmat(permute(tempMask,[1 3 2]),[1 size(app.mask,2) 1]);

            % update angio 
            app.angio = app.angio.*app.mask;
            updateMIPs(app, m_xstart, m_xstop, m_ystart, m_ystop, m_zstart, m_zstop);
        end

        % Button pushed function: CropButton_3
        function CropButton_3Pushed(app, event)
            % Crop xy-image
            img = app.AxesZ.Children(length(app.AxesZ.Children),1).CData;
            maskSz = cropImage(app,img);
            m_xstart = maskSz(2);m_xstop = maskSz(2)+maskSz(4);
            m_ystart = maskSz(1);m_ystop = maskSz(1)+maskSz(3);
            m_zstart = str2double(app.ZrangeEditField.Value);
            m_zstop = str2double(app.toZEditField.Value);

            tempMask = zeros(size(img));tempMask(m_xstart:m_xstop,m_ystart:m_ystop) = 1;
            app.mask = app.mask.*repmat(tempMask,[1 1 size(app.mask,3)]);

            % update angio 
            app.angio = app.angio.*app.mask;          
            updateMIPs(app, m_xstart, m_xstop, m_ystart, m_ystop, m_zstart, m_zstop);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % when cropping is done, the app is closed
            app.Callingapp.publicangio=app.angio;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CropPanel = uipanel(app.UIFigure);
            app.CropPanel.Title = 'Crop';
            app.AxesX = uiaxes(app.CropPanel);
            app.AxesX.XTick = 0;
            app.AxesX.YTick = 0;
            app.AxesY = uiaxes(app.CropPanel);
            app.AxesY.XTick = [];
            app.AxesY.YTick = [];
            app.AxesZ = uiaxes(app.CropPanel);
            app.AxesZ.XTick = [];
            app.AxesZ.YTick = [];
            app.XrangeEditFieldLabel = uilabel(app.CropPanel);
            app.XrangeEditFieldLabel.Text = 'X-range';
            app.XrangeEditField = uieditfield(app.CropPanel, 'text');
            app.XrangeEditField.Editable = 'off';
            app.XrangeEditField.Value = '1';
            app.toXEditFieldLabel = uilabel(app.CropPanel);
            app.toXEditFieldLabel.Text = 'to';
            app.toXEditField = uieditfield(app.CropPanel, 'text');
            app.toXEditField.Editable = 'off';
            app.toXEditField.Value = 'res';
            app.toYEditFieldLabel = uilabel(app.CropPanel);
            app.toYEditFieldLabel.Text = 'to';
            app.toYEditField = uieditfield(app.CropPanel, 'text');
            app.toYEditField.Editable = 'off';
            app.toYEditField.Value = 'res';
            app.YrangeEditFieldLabel = uilabel(app.CropPanel);
            app.YrangeEditFieldLabel.Text = 'Y-range';
            app.YrangeEditField = uieditfield(app.CropPanel, 'text');
            app.YrangeEditField.Editable = 'off';
            app.YrangeEditField.Value = '1';
            app.toZEditFieldLabel = uilabel(app.CropPanel);
            app.toZEditFieldLabel.Text = 'to';
            app.toZEditField = uieditfield(app.CropPanel, 'text');
            app.toZEditField.Editable = 'off';
            app.toZEditField.Value = 'res';
            app.ZrangeEditFieldLabel = uilabel(app.CropPanel);
            app.ZrangeEditFieldLabel.Text = 'Z-range';
            app.ZrangeEditField = uieditfield(app.CropPanel, 'text');
            app.ZrangeEditField.Editable = 'off';
            app.ZrangeEditField.Value = '1';
            app.CropButton = uibutton(app.CropPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.CropButton_2 = uibutton(app.CropPanel, 'push');
            app.CropButton_2.ButtonPushedFcn = createCallbackFcn(app, @CropButton_2Pushed, true);
            app.CropButton_2.Text = 'Crop';
            app.CropButton_3 = uibutton(app.CropPanel, 'push');
            app.CropButton_3.ButtonPushedFcn = createCallbackFcn(app, @CropButton_3Pushed, true);
            app.CropButton_3.Text = 'Crop';
            app.OKButton = uibutton(app.CropPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crop_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end