classdef select_datapoints_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectdatapointsandbranchesPanel  matlab.ui.container.Panel
        DeleteselectedpointButton       matlab.ui.control.Button
        SelectedpointsListBox           matlab.ui.control.ListBox
        SelectedpointsListBoxLabel      matlab.ui.control.Label
        WaveformsinselectedpointsPanel  matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        XYButton                        matlab.ui.control.StateButton
        XZButton                        matlab.ui.control.StateButton
        YZButton                        matlab.ui.control.StateButton
        ViewLabel                       matlab.ui.control.Label
        Retry                           matlab.ui.control.Button
        ProximalbranchpointsarehighlightedflipifneccessaryLabel  matlab.ui.control.Label
        SelectusedBranchesincorrectorderLabel  matlab.ui.control.Label
        FlipLabel                       matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        FlipBranch1_4                   matlab.ui.control.CheckBox
        FlipBranch1_3                   matlab.ui.control.CheckBox
        FlipBranch1_2                   matlab.ui.control.CheckBox
        FlipBranch1                     matlab.ui.control.CheckBox
        ManuallyselectpointsCheckBox    matlab.ui.control.CheckBox
        DropDown_4                      matlab.ui.control.DropDown
        DropDown_3                      matlab.ui.control.DropDown
        DropDown_2                      matlab.ui.control.DropDown
        DropDown                        matlab.ui.control.DropDown
        OKButton                        matlab.ui.control.Button
        Axes                            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Callingapp % Description
        angiosegmentation
        vsegmentation
        branchList
        angio
        angiopixdim
        okpushed=0
        PWVpointList
        pixdim 
        v
        legend_list
        waveforms
        delays
    end
    
    methods (Access = private)

        
        function reset3DSegmentationAndCenterline(app)
            % Initialize figure
            set(app.UIFigure,'Pointer','watch');
            drawnow()
            colorbar(app.Axes,'off')
            cla(app.Axes);
            if app.XYButton.Value || ~( app.XZButton.Value || app.YZButton.Value)
                dimorder=[1 2 3];
                xlabel(app.Axes,'X');
                ylabel(app.Axes,'Y');
            elseif app.XZButton.Value
                dimorder=[3 2 1];
                xlabel(app.Axes,'X');
                ylabel(app.Axes,'Z');
            elseif app.YZButton.Value
                dimorder=[3 1 2];
                xlabel(app.Axes,'Y');
                ylabel(app.Axes,'Z');
            end
            if app.Callingapp.LoadangioCheckBox.Value
                angio_perm = permute(app.angiosegmentation,dimorder);
                ss = smooth3(angio_perm);
                hpatch = patch(app.Axes,isosurface(ss,0.5),'FaceAlpha',0.20);
                reducepatch(hpatch,0.3);
                set(hpatch,'FaceColor',[0.7 0.7 0.7],'EdgeColor', 'none','PickableParts','none');
                index=find(angio_perm);
                [x,y,z]=ind2sub(size(angio_perm),index);
                v_perm = permute(app.vsegmentation,dimorder);
                ss = smooth3(v_perm);
                hpatch1 = patch(app.Axes,isosurface(ss,.05),'FaceColor','red','EdgeColor', 'none','FaceAlpha',0.25);
                reducepatch(hpatch1 ,0.3);
                daspect(app.Axes,[1/app.angiopixdim(dimorder(2)) 1/app.angiopixdim(dimorder(1)) 1/app.angiopixdim(dimorder(3))])
            else
                v_perm = permute(app.vsegmentation,dimorder);
                ss = smooth3(v_perm);
                hpatch = patch(app.Axes,isosurface(ss,0.5),'FaceAlpha',0.20);
                reducepatch(hpatch,0.3);
                set(hpatch,'FaceColor',[0.7 0.7 0.7],'EdgeColor', 'none','PickableParts','none');
                index=find(v_perm);
                [x,y,z]=ind2sub(size(v_perm),index);
                daspect(app.Axes,[1/app.pixdim(dimorder(2)) 1/app.pixdim(dimorder(1)) 1/app.pixdim(dimorder(3))])
            end
            m_xstart = min(x); m_ystart = min(y);m_zstart = min(z);
            m_xstop = max(x); m_ystop = max(y);m_zstop = max(z);
            xlim(app.Axes,[m_ystart-5 m_ystop+5]);
            ylim(app.Axes,[m_xstart-5 m_xstop+5]);
            zlim(app.Axes,[m_zstart-5 m_zstop+5]);
            axis(app.Axes, 'on')
            
            colormap(app.Axes,'colorcube')
            camlight(app.Axes);
            lighting(app.Axes,'gouraud');
            view(app.Axes, 2);
            unqBranches = unique(app.branchList(:,4));
            c = lines(length(unqBranches));
            % plot centerlines
            for b = 1:length(unqBranches)
                % extract coordinates for branch and plot
                currBranch = find(app.branchList(:,4) == b);
                hline(b) = line(app.Axes, ...
                    app.branchList(currBranch,dimorder(2)),app.branchList(currBranch,dimorder(1)),app.branchList(currBranch,dimorder(3)),...
                    'Color',c(b,:),'Marker','.','MarkerSize',12,'LineStyle','none');
            end
            c_bright=c.*1.3;
            tmp=c_bright>1;
            c_bright(tmp)=1;
            c_dark=c.*0.8;
            tmp=c_dark<0;
            c_dark(tmp)=0;
            % proximal point
            for b = 1:length(unqBranches)
                % extract coordinates for branch and plot
                currBranch = find(app.branchList(:,4) == b);
                hline2(b) = line(app.Axes,app.branchList(currBranch(1),dimorder(2)),app.branchList(currBranch(1),dimorder(1)),app.branchList(currBranch(1),dimorder(3)),...
                'Color',c_bright(b,:),'Marker','.','MarkerSize',24,'LineStyle','none');
            end
            % Put the number labels on the CenterlinePlot
            for i = 1:length(unqBranches)
                %find rows where textint = branchList(:,4) and take the mid point
                temp = app.branchList(app.branchList(:,4) == i,1:3);
                textLoc(i,1:3) = temp(round(size(temp,1)/2),:);
                Ntxt = text(app.Axes,textLoc(unqBranches(i),dimorder(2))+2,textLoc(unqBranches(i),dimorder(1))+2,textLoc(unqBranches(i),dimorder(3))-2,...
                num2str(unqBranches(i)),'Color',c_dark(i,:),'FontSize',14,'FontWeight', 'bold','Margin', 1,...
                'HitTest','off','PickableParts','none');
            end
            if app.ManuallyselectpointsCheckBox.Value
                for i=1:size(app.PWVpointList,1)
                    %roi=images.roi.Point(app.Axes,Color="black");
                    %roi.Position=[app.PWVpointList(i,dimorder(2)) app.PWVpointList(i,dimorder(1))]; 
                    %Label="branch "+num2str(app.PWVpointList(i,4));
                    Label="B"+num2str(app.PWVpointList(i,4))+"P"+num2str(app.PWVpointList(i,5));
                    hline2 = line(app.Axes,app.PWVpointList(i,dimorder(2)),app.PWVpointList(i,dimorder(1)),m_zstop,...
                        'Color','black','Marker','.','MarkerSize',18,'LineStyle','none');
                    Ntxt = text(app.Axes,app.PWVpointList(i,dimorder(2))+2,app.PWVpointList(i,dimorder(1)),m_zstop,...
                        Label,'Color','white','BackgroundColor','black','FontSize',14,'FontWeight', 'bold','Margin', 1,...
                        'HitTest','off','PickableParts','none');
                end
            end
            app.DropDown.Items = ['-'; string(unqBranches)];
            app.DropDown_2.Items = ['-'; string(unqBranches)];
            app.DropDown_3.Items = ['-'; string(unqBranches)];
            app.DropDown_4.Items = ['-'; string(unqBranches)];
            userStopped = false; 
            set(app.UIFigure,'Pointer','arrow');
            i=size(app.PWVpointList,1);
            while ~userStopped && app.ManuallyselectpointsCheckBox.Value 
                correct=0;
                roi=drawpoint(app.Axes,'Visible','off');
                if ~isvalid(roi) || isempty(roi.Position) || ~app.ManuallyselectpointsCheckBox.Value
                    % End the loop
                    userStopped = true;
                else
                    if isvalid(roi)
                        if ~(roi.Position(2) <= m_xstart-5) && ~(roi.Position(1) > m_ystop-5) 
                            pause(0.3);
                            idx_cell=knnsearch([app.branchList(:,dimorder(2)) app.branchList(:,dimorder(1))],roi.Position,'IncludeTies',true);
                            idx=idx_cell{1};
                            branchpoint=1;
                            if length(unique(app.branchList(idx,4)))>1
                                while ~correct
                                    prompt = {'On which branch is this point?'};
                                    dlgtitle = 'Branch Nr.:';
                                    fieldsize = [1 45];
                                    definput = {num2str(app.branchList(idx(1),4))};
                                    answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
                                    k=find(app.branchList(idx(:),4)==str2num(answer{1}),1,'first');
                                    if isempty(k)
                                        d=msgbox("I don't think so!");
                                        while isvalid(d);pause(0.1);end
                                    else
                                        correct=1;
                                        branchpoint=k;
                                    end
                                end
                            end
                            %roi.Position = [app.branchList(idx(branchpoint),dimorder(2)) app.branchList(idx(branchpoint),dimorder(1))]; 
                            [x_seg,y_seg,z_seg] = ind2sub(size(app.vsegmentation),find(app.vsegmentation));
                            [~,dist] = knnsearch([x_seg y_seg z_seg],app.branchList(idx(branchpoint),1:3),'K',6);
                            if norm(dist(:,6))>9
                                d = msgbox("We do not have available velocity data for this point!");
                                while isvalid(d);pause(0.1);end
                                delete(roi);
                            else 
                                hline2 = line(app.Axes,app.branchList(idx(branchpoint),dimorder(2)),app.branchList(idx(branchpoint),dimorder(1)),m_zstop,...
                                'Color','black','Marker','.','MarkerSize',18,'LineStyle','none');
                                Label="B"+num2str(app.branchList(idx(branchpoint),4))+"P"+num2str(app.branchList(idx(branchpoint),5));
                                Ntxt = text(app.Axes,app.branchList(idx(branchpoint),dimorder(2))+2,app.branchList(idx(branchpoint),dimorder(1)),m_zstop,...
                                Label,'Color','white','BackgroundColor','black','FontSize',14,'FontWeight', 'bold','Margin', 1,...
                                'HitTest','off','PickableParts','none');
                                i=i+1;
                                app.PWVpointList(i,:) = app.branchList(idx(branchpoint),:);
                                %immediately plot waveform
                                if(1)
                                    if app.Callingapp.LoadangioCheckBox.Value
                                        [~,v_ind] = shift_position_affine(app.PWVpointList(i,1:3),app.Callingapp.angioPosition,app.Callingapp.angioOrientation,app.Callingapp.angiopixdim, ...
                                            size(app.Callingapp.vsegmentation), app.Callingapp.vPosition,app.Callingapp.vOrientation,app.Callingapp.pixdim,app.Callingapp.directionFlagAngio,app.Callingapp.directionFlagVelocity,"nointerpolation","nocutoff");
                                    else
                                        v_ind = app.PWVpointList(i,1:3);
                                    end
                                    [x_seg,y_seg,z_seg]=ind2sub(size(app.Callingapp.vsegmentation),find(app.Callingapp.vsegmentation));
                                    neighbor_idx=knnsearch([x_seg y_seg z_seg],v_ind,'K',1);
                                    app.waveforms(i,:) = squeeze(app.v(x_seg(neighbor_idx),y_seg(neighbor_idx),z_seg(neighbor_idx),:))';
                                    app.delays(i,:) = app.Callingapp.delays(z_seg(neighbor_idx),:);
                                    %cla(ax,'reset')
                                    plot_waveforms(app);
                                end
                            end
                        else
                            delete(roi)
                        end
                    else
                        delete(roi);
                    end
                end 
            end
        end
        
        function plot_waveforms(app)
            cla(app.UIAxes)
            if ~isempty(app.PWVpointList)
                [app.PWVpointList,index] = sortrows(app.PWVpointList,[4 5]);
                app.waveforms = app.waveforms(index,:);
                app.delays = app.delays(index,:);
                for i=1:size(app.PWVpointList,1)
                    plot(app.UIAxes,app.delays(i,:),app.waveforms(i,:))
                    hold(app.UIAxes,'on')
                    app.legend_list{i} = char("Branch "+num2str(app.PWVpointList(i,4))+", Point "+ num2str(app.PWVpointList(i,5)));
                    if i<10
                        legend(app.UIAxes,app.legend_list)
                    elseif i==10
                        legend(app.UIAxes,app.legend_list{1:10})
                        display("Legend entries limited to 10")
                    else
                        legend(app.UIAxes,app.legend_list{1:10})
                    end
                end
                axis(app.UIAxes,'tight')
                app.SelectedpointsListBox.Items = app.legend_list;
                drawnow
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, angiosegmentation, branchList, vsegmentation, angiopixdim, pixdim, v)
            app.Callingapp = caller;
            % initialize the mask
            app.angiosegmentation = angiosegmentation;
            app.vsegmentation = vsegmentation;
            app.branchList = branchList;
            unqBranches = unique(app.branchList(:,4));
            app.DropDown.Items = ['-'; string(unqBranches)];
            app.DropDown_2.Items = ['-'; string(unqBranches)];
            app.DropDown_3.Items = ['-'; string(unqBranches)];
            app.DropDown_4.Items = ['-'; string(unqBranches)];
            app.angiopixdim = angiopixdim;
            app.pixdim = pixdim;
            app.v = v;
            app.PWVpointList = app.Callingapp.PWVpointList;
            app.XYButton.Value = app.Callingapp.XYButton.Value;
            app.XZButton.Value = app.Callingapp.XZButton.Value;
            app.YZButton.Value = app.Callingapp.YZButton.Value;
            app.FlipBranch1.Value = app.Callingapp.flip1;
            app.FlipBranch1_2.Value = app.Callingapp.flip2;
            app.FlipBranch1_3.Value = app.Callingapp.flip3;
            app.FlipBranch1_4.Value = app.Callingapp.flip4;
            app.ManuallyselectpointsCheckBox.Value = app.Callingapp.pointsselectedManually;
            if app.ManuallyselectpointsCheckBox.Value == 0
                app.DeleteselectedpointButton.Visible = 'off';
            else
                app.DeleteselectedpointButton.Visible = 'on';
            end
            num_branches = length(app.Callingapp.branches);
            if num_branches == 1
                app.DropDown.Value = num2str(app.Callingapp.branches(1));
                app.DropDown_2.Visible = 'on';
                app.FlipBranch1_2.Visible="on";
            elseif num_branches == 2
                app.DropDown.Value = num2str(app.Callingapp.branches(1));
                app.DropDown_2.Visible = 'on';
                app.FlipBranch1_2.Visible="on";
                app.DropDown_2.Value = num2str(app.Callingapp.branches(2));
                app.DropDown_3.Visible = 'on';
                app.FlipBranch1_3.Visible="on";
            elseif num_branches == 3
                app.DropDown.Value = num2str(app.Callingapp.branches(1));
                app.DropDown_2.Visible = 'on';
                app.FlipBranch1_2.Visible="on";
                app.DropDown_2.Value = num2str(app.Callingapp.branches(2));
                app.DropDown_3.Visible = 'on';
                app.FlipBranch1_3.Visible="on";
                app.DropDown_3.Value = num2str(app.Callingapp.branches(3));
                app.DropDown_4.Visible = 'on';
                app.FlipBranch1_4.Visible="on";
            elseif num_branches == 4
                app.DropDown.Value = num2str(app.Callingapp.branches(1));
                app.DropDown_2.Visible = 'on';
                app.FlipBranch1_2.Visible="on";
                app.DropDown_2.Value = num2str(app.Callingapp.branches(2));
                app.DropDown_3.Visible = 'on';
                app.FlipBranch1_3.Visible="on";
                app.DropDown_3.Value = num2str(app.Callingapp.branches(3));
                app.DropDown_4.Visible = 'on';
                app.FlipBranch1_4.Visible="on";
                app.DropDown_4.Value = num2str(app.Callingapp.branches(4));
            end

            if ~app.ManuallyselectpointsCheckBox.Value
                branches=zeros(4,1);
                if ~strcmp(app.DropDown.Value,'-')
                    branches(1)=str2num(app.DropDown.Value);
                end
                if ~strcmp(app.DropDown_2.Value,'-')
                    branches(2)=str2num(app.DropDown_2.Value);
                end
                if ~strcmp(app.DropDown_3.Value,'-')
                    branches(3)=str2num(app.DropDown_3.Value);
                end
                if ~strcmp(app.DropDown_4.Value,'-')
                    branches(4)=str2num(app.DropDown_4.Value);
                end
                app.PWVpointList=[];
                app.waveforms=[];
                app.delays=[];
                [x_seg,y_seg,z_seg]=ind2sub(size(app.vsegmentation),find(app.vsegmentation));
                i=0;
                for run_branches=1:4
                    k=find(app.branchList(:,4)==branches(run_branches));
                    if ~isempty(k)
                        for run_elements=1:length(k)
                            [~,dist]=knnsearch([x_seg y_seg z_seg],app.branchList(k(run_elements),1:3),'K',6);
                            if norm(dist(:,6))<=9
                                i=i+1;
                                app.PWVpointList(i,:)=app.branchList(k(run_elements),:);
                            end
                        end
                    end
                end  
            end
            for i=1:size(app.PWVpointList,1)
                if app.Callingapp.LoadangioCheckBox.Value
                    [~,v_ind] = shift_position_affine(app.PWVpointList(i,1:3),app.Callingapp.angioPosition,app.Callingapp.angioOrientation,app.Callingapp.angiopixdim, ...
                        size(app.Callingapp.vsegmentation), app.Callingapp.vPosition,app.Callingapp.vOrientation,app.Callingapp.pixdim,app.Callingapp.directionFlagAngio,app.Callingapp.directionFlagVelocity,"nointerpolation","nocutoff");
                else
                    v_ind = app.PWVpointList(i,1:3);
                end
                [x_seg,y_seg,z_seg]=ind2sub(size(app.Callingapp.vsegmentation),find(app.Callingapp.vsegmentation));
                neighbor_idx=knnsearch([x_seg y_seg z_seg],v_ind,'K',1);
                app.waveforms(i,:) = squeeze(app.v(x_seg(neighbor_idx),y_seg(neighbor_idx),z_seg(neighbor_idx),:))';
                app.delays(i,:) = app.Callingapp.delays(z_seg(neighbor_idx),:);
            end
            plot_waveforms(app)
            reset3DSegmentationAndCenterline(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            branches=zeros(4,1);
            choice=0;
            if ~strcmp(app.DropDown.Value,'-')
                branches(1)=str2num(app.DropDown.Value);
            else
                e=errordlg("Select at least one branch!");
                uiwait(e);
                choice=1;
            end
            if ~strcmp(app.DropDown_2.Value,'-')
                branches(2)=str2num(app.DropDown_2.Value);
            end
            if ~strcmp(app.DropDown_3.Value,'-')
                branches(3)=str2num(app.DropDown_3.Value);
            end
            if ~strcmp(app.DropDown_4.Value,'-')
                branches(4)=str2num(app.DropDown_4.Value);
            end
            if ~app.ManuallyselectpointsCheckBox.Value
                app.PWVpointList=[];
                app.waveforms=[];
                app.delays=[];
                [x_seg,y_seg,z_seg]=ind2sub(size(app.vsegmentation),find(app.vsegmentation));
                i=0;
                for run_branches=1:4
                    k=find(app.branchList(:,4)==branches(run_branches));
                    if ~isempty(k)
                        for run_elements=1:length(k)
                            [~,dist]=knnsearch([x_seg y_seg z_seg],app.branchList(k(run_elements),1:3),'K',6);
                            if norm(dist(:,6))<=9
                                i=i+1;
                                app.PWVpointList(i,:)=app.branchList(k(run_elements),:);
                            end
                        end
                    end
                end    
            end
            if ~isempty(app.PWVpointList)
                comp=ismember(app.PWVpointList(:,4),branches(branches>0));
            else
                comp=1;
            end
            if size(app.PWVpointList,1)<3
                choice=choosedialog;
            elseif sum(comp==0)>0
                e=errordlg("Do not pick points from branches you did not select!");
                uiwait(e);
                choice=1;
            end
            if choice
                plot_waveforms(app)
                reset3DSegmentationAndCenterline(app);
            else
                idx=branches>0;
                branches_real = branches(idx);
                app.Callingapp.PWVpointList = app.PWVpointList;
                app.Callingapp.flip1 = app.FlipBranch1.Value;
                app.Callingapp.flip2 = app.FlipBranch1_2.Value;
                app.Callingapp.flip3 = app.FlipBranch1_3.Value;
                app.Callingapp.flip4 = app.FlipBranch1_4.Value;
                app.Callingapp.branches = branches_real;
                app.Callingapp.pointsselectedManually = app.ManuallyselectpointsCheckBox.Value;
                delete(app);
            end
        end

        % Value changed function: ManuallyselectpointsCheckBox
        function ManuallyselectpointsCheckBoxValueChanged(app, event)
            app.PWVpointList = [];
            app.legend_list = [];
            app.waveforms = [];
            app.delays = [];
            cla(app.UIAxes)
            %ud = findobj('Type','images.roi.Point');
            %delete(ud);
            plot_waveforms(app)
            reset3DSegmentationAndCenterline(app);
            if app.ManuallyselectpointsCheckBox.Value == 0
                app.DeleteselectedpointButton.Visible = 'off';
            else
                app.DeleteselectedpointButton.Visible = 'on';
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if ~strcmp(value,'-')
                app.DropDown_2.Visible="on";
                app.FlipBranch1_2.Visible="on";
            end
            if ~app.ManuallyselectpointsCheckBox.Value
                branches=zeros(4,1);
                if ~strcmp(app.DropDown.Value,'-')
                    branches(1)=str2num(app.DropDown.Value);
                end
                if ~strcmp(app.DropDown_2.Value,'-')
                    branches(2)=str2num(app.DropDown_2.Value);
                end
                if ~strcmp(app.DropDown_3.Value,'-')
                    branches(3)=str2num(app.DropDown_3.Value);
                end
                if ~strcmp(app.DropDown_4.Value,'-')
                    branches(4)=str2num(app.DropDown_4.Value);
                end
                app.PWVpointList=[];
                app.waveforms=[];
                app.delays=[];
                [x_seg,y_seg,z_seg]=ind2sub(size(app.vsegmentation),find(app.vsegmentation));
                i=0;
                for run_branches=1:4
                    k=find(app.branchList(:,4)==branches(run_branches));
                    if ~isempty(k)
                        for run_elements=1:length(k)
                            [~,dist]=knnsearch([x_seg y_seg z_seg],app.branchList(k(run_elements),1:3),'K',6);
                            if norm(dist(:,6))<=9
                                i=i+1;
                                app.PWVpointList(i,:)=app.branchList(k(run_elements),:);
                            end
                        end
                    end
                end   
                for i=1:size(app.PWVpointList,1)
                    if app.Callingapp.LoadangioCheckBox.Value
                        [~,v_ind] = shift_position_affine(app.PWVpointList(i,1:3),app.Callingapp.angioPosition,app.Callingapp.angioOrientation,app.Callingapp.angiopixdim, ...
                            size(app.Callingapp.vsegmentation), app.Callingapp.vPosition,app.Callingapp.vOrientation,app.Callingapp.pixdim,app.Callingapp.directionFlagAngio,app.Callingapp.directionFlagVelocity,"nointerpolation","nocutoff");
                    else
                        v_ind = app.PWVpointList(i,1:3);
                    end
                    [x_seg,y_seg,z_seg]=ind2sub(size(app.Callingapp.vsegmentation),find(app.Callingapp.vsegmentation));
                    neighbor_idx=knnsearch([x_seg y_seg z_seg],v_ind,'K',1);
                    app.waveforms(i,:) = squeeze(app.v(x_seg(neighbor_idx),y_seg(neighbor_idx),z_seg(neighbor_idx),:))';
                    app.delays(i,:) = app.Callingapp.delays(z_seg(neighbor_idx),:);
                end
                plot_waveforms(app)
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if ~strcmp(value,'-')
                app.DropDown_3.Visible="on";
                app.FlipBranch1_3.Visible="on";
            end
            if ~app.ManuallyselectpointsCheckBox.Value
                branches=zeros(4,1);
                if ~strcmp(app.DropDown.Value,'-')
                    branches(1)=str2num(app.DropDown.Value);
                end
                if ~strcmp(app.DropDown_2.Value,'-')
                    branches(2)=str2num(app.DropDown_2.Value);
                end
                if ~strcmp(app.DropDown_3.Value,'-')
                    branches(3)=str2num(app.DropDown_3.Value);
                end
                if ~strcmp(app.DropDown_4.Value,'-')
                    branches(4)=str2num(app.DropDown_4.Value);
                end
                
                app.PWVpointList=[];
                app.waveforms=[];
                app.delays=[];
                [x_seg,y_seg,z_seg]=ind2sub(size(app.vsegmentation),find(app.vsegmentation));
                i=0;
                for run_branches=1:4
                    k=find(app.branchList(:,4)==branches(run_branches));
                    if ~isempty(k)
                        for run_elements=1:length(k)
                            [~,dist]=knnsearch([x_seg y_seg z_seg],app.branchList(k(run_elements),1:3),'K',6);
                            if norm(dist(:,6))<=9
                                i=i+1;
                                app.PWVpointList(i,:)=app.branchList(k(run_elements),:);
                            end
                        end
                    end
                end    
                for i=1:size(app.PWVpointList,1)
                    if app.Callingapp.LoadangioCheckBox.Value
                        [~,v_ind] = shift_position_affine(app.PWVpointList(i,1:3),app.Callingapp.angioPosition,app.Callingapp.angioOrientation,app.Callingapp.angiopixdim, ...
                            size(app.Callingapp.vsegmentation), app.Callingapp.vPosition,app.Callingapp.vOrientation,app.Callingapp.pixdim,app.Callingapp.directionFlagAngio,app.Callingapp.directionFlagVelocity,"nointerpolation","nocutoff");
                    else
                        v_ind = app.PWVpointList(i,1:3);
                    end
                    [x_seg,y_seg,z_seg]=ind2sub(size(app.Callingapp.vsegmentation),find(app.Callingapp.vsegmentation));
                    neighbor_idx=knnsearch([x_seg y_seg z_seg],v_ind,'K',1);
                    app.waveforms(i,:) = squeeze(app.v(x_seg(neighbor_idx),y_seg(neighbor_idx),z_seg(neighbor_idx),:))';
                    app.delays(i,:) = app.Callingapp.delays(z_seg(neighbor_idx),:);
                end
                plot_waveforms(app)
            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            if ~strcmp(value,'-')
                app.DropDown_4.Visible="on";
                app.FlipBranch1_4.Visible="on";
            end
            if ~app.ManuallyselectpointsCheckBox.Value
                branches=zeros(4,1);
                if ~strcmp(app.DropDown.Value,'-')
                    branches(1)=str2num(app.DropDown.Value);
                end
                if ~strcmp(app.DropDown_2.Value,'-')
                    branches(2)=str2num(app.DropDown_2.Value);
                end
                if ~strcmp(app.DropDown_3.Value,'-')
                    branches(3)=str2num(app.DropDown_3.Value);
                end
                if ~strcmp(app.DropDown_4.Value,'-')
                    branches(4)=str2num(app.DropDown_4.Value);
                end
                
                app.PWVpointList=[];
                app.waveforms=[];
                app.delays=[];
                [x_seg,y_seg,z_seg]=ind2sub(size(app.vsegmentation),find(app.vsegmentation));
                i=0;
                for run_branches=1:4
                    k=find(app.branchList(:,4)==branches(run_branches));
                    if ~isempty(k)
                        for run_elements=1:length(k)
                            [~,dist]=knnsearch([x_seg y_seg z_seg],app.branchList(k(run_elements),1:3),'K',6);
                            if norm(dist(:,6))<=9
                                i=i+1;
                                app.PWVpointList(i,:)=app.branchList(k(run_elements),:);
                            end
                        end
                    end
                end   
                for i=1:size(app.PWVpointList,1)
                    if app.Callingapp.LoadangioCheckBox.Value
                        [~,v_ind] = shift_position_affine(app.PWVpointList(i,1:3),app.Callingapp.angioPosition,app.Callingapp.angioOrientation,app.Callingapp.angiopixdim, ...
                            size(app.Callingapp.vsegmentation), app.Callingapp.vPosition,app.Callingapp.vOrientation,app.Callingapp.pixdim,app.Callingapp.directionFlagAngio,app.Callingapp.directionFlagVelocity,"nointerpolation","nocutoff");
                    else
                        v_ind = app.PWVpointList(i,1:3);
                    end
                    [x_seg,y_seg,z_seg]=ind2sub(size(app.Callingapp.vsegmentation),find(app.Callingapp.vsegmentation));
                    neighbor_idx=knnsearch([x_seg y_seg z_seg],v_ind,'K',1);
                    app.waveforms(i,:) = squeeze(app.v(x_seg(neighbor_idx),y_seg(neighbor_idx),z_seg(neighbor_idx),:))';
                    app.delays(i,:) = app.Callingapp.delays(z_seg(neighbor_idx),:);
                end
                plot_waveforms(app)
            end
        end

        % Button pushed function: Retry
        function RetryButtonPushed(app, event)
            app.PWVpointList=[];
            app.legend_list=[];
            app.waveforms = [];
            app.delays = [];
            %ud = findobj('Type','images.roi.Point');
            %delete(ud);
            plot_waveforms(app)
            reset3DSegmentationAndCenterline(app);
        end

        % Value changed function: XYButton
        function XYButtonValueChanged(app, event)
            value = app.XYButton.Value;
            if value==0
                app.XYButton.Value=1;
            else
                app.XZButton.Value=0;
                app.YZButton.Value=0;
            end
            reset3DSegmentationAndCenterline(app)
        end

        % Value changed function: XZButton
        function XZButtonValueChanged(app, event)
            value = app.XZButton.Value;
            if value==0
                app.XZButton.Value=1;
            else
                app.XYButton.Value=0;
                app.YZButton.Value=0;
            end
            reset3DSegmentationAndCenterline(app)
        end

        % Value changed function: YZButton
        function YZButtonValueChanged(app, event)
            value = app.YZButton.Value;
            if value==0
                app.YZButton.Value=1;
            else
                app.XYButton.Value=0;
                app.XZButton.Value=0;
            end
            reset3DSegmentationAndCenterline(app)
        end

        % Button pushed function: DeleteselectedpointButton
        function DeleteselectedpointButtonPushed(app, event)
            valueChar = app.SelectedpointsListBox.Value;
            if ~isempty(valueChar)
                valueNum = sscanf(valueChar, 'Branch %d, Point %d');
                targetBranch = valueNum(1);
                targetOccurrence = valueNum(2);      
                indices = app.PWVpointList(:,4:5) == [targetBranch targetOccurrence]; 
                idx = indices(:,1) & indices(:,2);
                app.PWVpointList(idx,:) =[];
                app.waveforms(idx,:) = [];
                app.legend_list = [];
                app.delays(idx,:) = [];

                plot_waveforms(app);
                reset3DSegmentationAndCenterline(app)
            end
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            if ~app.ManuallyselectpointsCheckBox.Value
                branches=zeros(4,1);
                if ~strcmp(app.DropDown.Value,'-')
                    branches(1)=str2num(app.DropDown.Value);
                end
                if ~strcmp(app.DropDown_2.Value,'-')
                    branches(2)=str2num(app.DropDown_2.Value);
                end
                if ~strcmp(app.DropDown_3.Value,'-')
                    branches(3)=str2num(app.DropDown_3.Value);
                end
                if ~strcmp(app.DropDown_4.Value,'-')
                    branches(4)=str2num(app.DropDown_4.Value);
                end
                app.PWVpointList=[];
                app.waveforms=[];
                app.delays=[];
                [x_seg,y_seg,z_seg]=ind2sub(size(app.vsegmentation),find(app.vsegmentation));
                i=0;
                for run_branches=1:4
                    k=find(app.branchList(:,4)==branches(run_branches));
                    if ~isempty(k)
                        for run_elements=1:length(k)
                            [~,dist]=knnsearch([x_seg y_seg z_seg],app.branchList(k(run_elements),1:3),'K',6);
                            if norm(dist(:,6))<=9
                                i=i+1;
                                app.PWVpointList(i,:)=app.branchList(k(run_elements),:);
                            end
                        end
                    end
                end   
                for i=1:size(app.PWVpointList,1)
                    if app.Callingapp.LoadangioCheckBox.Value
                        [~,v_ind] = shift_position_affine(app.PWVpointList(i,1:3),app.Callingapp.angioPosition,app.Callingapp.angioOrientation,app.Callingapp.angiopixdim, ...
                            size(app.Callingapp.vsegmentation), app.Callingapp.vPosition,app.Callingapp.vOrientation,app.Callingapp.pixdim,app.Callingapp.directionFlagAngio,app.Callingapp.directionFlagVelocity,"nointerpolation","nocutoff");
                    else
                        v_ind = app.PWVpointList(i,1:3);
                    end
                    [x_seg,y_seg,z_seg]=ind2sub(size(app.Callingapp.vsegmentation),find(app.Callingapp.vsegmentation));
                    neighbor_idx=knnsearch([x_seg y_seg z_seg],v_ind,'K',1);
                    app.waveforms(i,:) = squeeze(app.v(x_seg(neighbor_idx),y_seg(neighbor_idx),z_seg(neighbor_idx),:))';
                    app.delays(i,:) = app.Callingapp.delays(z_seg(neighbor_idx),:);
                end
                plot_waveforms(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectdatapointsandbranchesPanel = uipanel(app.UIFigure);
            app.SelectdatapointsandbranchesPanel.Title = 'Select datapoints and branches';
            app.Axes = uiaxes(app.SelectdatapointsandbranchesPanel);
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            zlabel(app.Axes, 'Z')
            app.Axes.XTick = [];
            app.Axes.YTick = [];
            app.OKButton = uibutton(app.SelectdatapointsandbranchesPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.DropDown = uidropdown(app.SelectdatapointsandbranchesPanel);
            app.DropDown.Items = {'-'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '-';
            app.DropDown_2 = uidropdown(app.SelectdatapointsandbranchesPanel);
            app.DropDown_2.Items = {'-'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Visible = 'off';
            app.DropDown_2.Value = '-';
            app.DropDown_3 = uidropdown(app.SelectdatapointsandbranchesPanel);
            app.DropDown_3.Items = {'-'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Visible = 'off';
            app.DropDown_3.Value = '-';
            app.DropDown_4 = uidropdown(app.SelectdatapointsandbranchesPanel);
            app.DropDown_4.Items = {'-'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Visible = 'off';
            app.DropDown_4.Value = '-';
            app.ManuallyselectpointsCheckBox = uicheckbox(app.SelectdatapointsandbranchesPanel);
            app.ManuallyselectpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManuallyselectpointsCheckBoxValueChanged, true);
            app.ManuallyselectpointsCheckBox.Text = 'Manually select points';
            app.ManuallyselectpointsCheckBox.Value = true;
            app.FlipBranch1 = uicheckbox(app.SelectdatapointsandbranchesPanel);
            app.FlipBranch1.Tooltip = {''};
            app.FlipBranch1.Text = '';
            app.FlipBranch1_2 = uicheckbox(app.SelectdatapointsandbranchesPanel);
            app.FlipBranch1_2.Visible = 'off';
            app.FlipBranch1_2.Tooltip = {''};
            app.FlipBranch1_2.Text = '';
            app.FlipBranch1_3 = uicheckbox(app.SelectdatapointsandbranchesPanel);
            app.FlipBranch1_3.Visible = 'off';
            app.FlipBranch1_3.Tooltip = {''};
            app.FlipBranch1_3.Text = '';
            app.FlipBranch1_4 = uicheckbox(app.SelectdatapointsandbranchesPanel);
            app.FlipBranch1_4.Visible = 'off';
            app.FlipBranch1_4.Tooltip = {''};
            app.FlipBranch1_4.Text = '';
            app.Button = uibutton(app.SelectdatapointsandbranchesPanel, 'push');
            app.Button.Icon = fullfile(pathToMLAPP, 'infoicon.jpg');
            app.Button.Enable = 'off';
            app.Button.Tooltip = {'If not checked, every available point is automatically chosen on the picked branches'};
            app.Button.Text = '';
            app.FlipLabel = uilabel(app.SelectdatapointsandbranchesPanel);
            app.FlipLabel.Text = 'Flip:';
            app.SelectusedBranchesincorrectorderLabel = uilabel(app.SelectdatapointsandbranchesPanel);
            app.SelectusedBranchesincorrectorderLabel.Text = 'Select used Branches in correct order:';
            app.ProximalbranchpointsarehighlightedflipifneccessaryLabel = uilabel(app.SelectdatapointsandbranchesPanel);
            app.ProximalbranchpointsarehighlightedflipifneccessaryLabel.Text = 'Proximal branch points are highlighted: flip if neccessary';
            app.Retry = uibutton(app.SelectdatapointsandbranchesPanel, 'push');
            app.Retry.ButtonPushedFcn = createCallbackFcn(app, @RetryButtonPushed, true);
            app.Retry.Text = 'Retry';
            app.ViewLabel = uilabel(app.SelectdatapointsandbranchesPanel);
            app.ViewLabel.Text = 'View:';
            app.YZButton = uibutton(app.SelectdatapointsandbranchesPanel, 'state');
            app.YZButton.ValueChangedFcn = createCallbackFcn(app, @YZButtonValueChanged, true);
            app.YZButton.Text = 'YZ';
            app.XZButton = uibutton(app.SelectdatapointsandbranchesPanel, 'state');
            app.XZButton.ValueChangedFcn = createCallbackFcn(app, @XZButtonValueChanged, true);
            app.XZButton.Text = 'XZ';
            app.XYButton = uibutton(app.SelectdatapointsandbranchesPanel, 'state');
            app.XYButton.ValueChangedFcn = createCallbackFcn(app, @XYButtonValueChanged, true);
            app.XYButton.Text = 'XY';
            app.WaveformsinselectedpointsPanel = uipanel(app.SelectdatapointsandbranchesPanel);
            app.WaveformsinselectedpointsPanel.Title = 'Waveforms in selected points';
            app.UIAxes = uiaxes(app.WaveformsinselectedpointsPanel);
            title(app.UIAxes, 'Selected Waveforms')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SelectedpointsListBoxLabel = uilabel(app.SelectdatapointsandbranchesPanel);
            app.SelectedpointsListBoxLabel.Text = 'Selected points';
            app.SelectedpointsListBox = uilistbox(app.SelectdatapointsandbranchesPanel);
            app.SelectedpointsListBox.Items = {};
            app.SelectedpointsListBox.Value = {};
            app.DeleteselectedpointButton = uibutton(app.SelectdatapointsandbranchesPanel, 'push');
            app.DeleteselectedpointButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedpointButtonPushed, true);
            app.DeleteselectedpointButton.Text = 'Delete selected point';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = select_datapoints_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end